classdef cicada < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CicadaUIFigure                  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenDatasetMenu                 matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        SaveDatasetMenu                 matlab.ui.container.Menu
        SaveDatasetAsMenu               matlab.ui.container.Menu
        ImportActigraphyMenu            matlab.ui.container.Menu
        ImportGeneActivMenu             matlab.ui.container.Menu
        ImportOtherDataMenu             matlab.ui.container.Menu
        ImportLightPinMenu              matlab.ui.container.Menu
        ImportEventsMenu                matlab.ui.container.Menu
        ImportSleepDiaryMenu            matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        ExportAnalysisMenu              matlab.ui.container.Menu
        ExportReportMenu                matlab.ui.container.Menu
        ExportCodeMenu                  matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        DatasetInfoMenu                 matlab.ui.container.Menu
        SelectDataMenu                  matlab.ui.container.Menu
        ChangeTimeZoneMenu              matlab.ui.container.Menu
        ChangeEpochLengthMenu           matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        ShowActivityCountsMenu          matlab.ui.container.Menu
        ShowEuclideanNormMenu           matlab.ui.container.Menu
        ShowAngleMenu                   matlab.ui.container.Menu
        PreprocessMenu                  matlab.ui.container.Menu
        GGIRAutomaticCalibrationMenu    matlab.ui.container.Menu
        GGIRNonWearDetectionMenu        matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        AnnotateEpochsMenu              matlab.ui.container.Menu
        AnnotateAccelerationGGIRMenu    matlab.ui.container.Menu
        AnnotateLightMenu               matlab.ui.container.Menu
        EventsMenu                      matlab.ui.container.Menu
        CreateDailyEventsMenu           matlab.ui.container.Menu
        CreateRelativeEventsMenu        matlab.ui.container.Menu
        GGIRSleepDetectionMenu          matlab.ui.container.Menu
        StatisticsMenu                  matlab.ui.container.Menu
        GenerateStatisticsMenu          matlab.ui.container.Menu
        WindowMenu                      matlab.ui.container.Menu
        RepositionpanelsMenu            matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        VisittheCicadaMenu              matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayoutLeftPanel             matlab.ui.container.GridLayout
        InformationPanel                matlab.ui.container.Panel
        GridLayoutInformationPanel      matlab.ui.container.GridLayout
        FilenamePanel                   matlab.ui.container.Panel
        GridLayoutFilenamePanel         matlab.ui.container.GridLayout
        FilenameLabel                   matlab.ui.control.Label
        StudyPanel                      matlab.ui.container.Panel
        GridLayoutStudyPanel            matlab.ui.container.GridLayout
        StudyLabel                      matlab.ui.control.Label
        ResearcherPanel                 matlab.ui.container.Panel
        GridLayoutResearcherPanel       matlab.ui.container.GridLayout
        ResearcherLabel                 matlab.ui.control.Label
        SubjectIDPanel                  matlab.ui.container.Panel
        GridLayoutSubjectIDPanel        matlab.ui.container.GridLayout
        SubjectIDLabel                  matlab.ui.control.Label
        GroupPanel                      matlab.ui.container.Panel
        GridLayoutGroupPanel            matlab.ui.container.GridLayout
        GroupLabel                      matlab.ui.control.Label
        ConditionPanel                  matlab.ui.container.Panel
        GridLayoutConditionPanel        matlab.ui.container.GridLayout
        ConditionLabel                  matlab.ui.control.Label
        SessionPanel                    matlab.ui.container.Panel
        GridLayoutSessionPanel          matlab.ui.container.GridLayout
        SessionLabel                    matlab.ui.control.Label
        DateOfBirthPanel                matlab.ui.container.Panel
        GridLayoutDateOfBirthPanel      matlab.ui.container.GridLayout
        DateOfBirthLabel                matlab.ui.control.Label
        SexPanel                        matlab.ui.container.Panel
        GridLayoutSexPanel              matlab.ui.container.GridLayout
        SexLabel                        matlab.ui.control.Label
        HeightPanel                     matlab.ui.container.Panel
        GridLayoutHeightPanel           matlab.ui.container.GridLayout
        HeightLabel                     matlab.ui.control.Label
        WeightPanel                     matlab.ui.container.Panel
        GridLayoutWeightPanel           matlab.ui.container.GridLayout
        WeightLabel                     matlab.ui.control.Label
        HandednessPanel                 matlab.ui.container.Panel
        GridLayoutHandednessPanel       matlab.ui.container.GridLayout
        HandednessLabel                 matlab.ui.control.Label
        DeviceLocationPanel             matlab.ui.container.Panel
        GridLayoutDeviceLocationPanel   matlab.ui.container.GridLayout
        DeviceLocationLabel             matlab.ui.control.Label
        StartDatePanel                  matlab.ui.container.Panel
        GridLayoutStartDatePanel        matlab.ui.container.GridLayout
        StartDateLabel                  matlab.ui.control.Label
        EndDatePanel                    matlab.ui.container.Panel
        GridLayoutEndDatePanel          matlab.ui.container.GridLayout
        EndDateLabel                    matlab.ui.control.Label
        RecordingLengthPanel            matlab.ui.container.Panel
        GridLayoutRecordingLengthPanel  matlab.ui.container.GridLayout
        RecordingLengthLabel            matlab.ui.control.Label
        SamplingRatePanel               matlab.ui.container.Panel
        GridLayoutSamplingRatePanel     matlab.ui.container.GridLayout
        SamplingRateLabel               matlab.ui.control.Label
        LegendPanel                     matlab.ui.container.Panel
        GridLayoutLegendPanel           matlab.ui.container.GridLayout
        AnnotationLabel                 matlab.ui.control.Label
        EventsLabel                     matlab.ui.control.Label
        InactivityButton                matlab.ui.control.Button
        SustainedInactivityLabel        matlab.ui.control.Label
        MinimalActivityButton           matlab.ui.control.Button
        LowActivityLabel                matlab.ui.control.Label
        LightActivityButton             matlab.ui.control.Button
        LightActivityLabel              matlab.ui.control.Label
        ModerateActivityButton          matlab.ui.control.Button
        ModerateActivityLabel           matlab.ui.control.Label
        VigorousActivityButton          matlab.ui.control.Button
        VigorousActivityLabel           matlab.ui.control.Label
        ExclusionButton                 matlab.ui.control.Button
        ExclusionLabel                  matlab.ui.control.Label
        ButtonPressButton               matlab.ui.control.Button
        ButtonPressLabel                matlab.ui.control.Label
        SleepWindowButton               matlab.ui.control.Button
        SleepWindowLabel                matlab.ui.control.Label
        SleepPeriodButton               matlab.ui.control.Button
        SleepPeriodLabel                matlab.ui.control.Label
        CustomEventButton               matlab.ui.control.Button
        CustomEventLabel                matlab.ui.control.Label
        UIAxesLogo                      matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayoutRightPanel            matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        DataAnalysisTab                 matlab.ui.container.Tab
        GridLayoutDataAnalysisTab       matlab.ui.container.GridLayout
        DataPanel                       matlab.ui.container.Panel
        DataContainer                   matlab.ui.container.Panel
        SettingsPanel                   matlab.ui.container.Panel
        GridLayoutSettingsPanel         matlab.ui.container.GridLayout
        CancelSettingsButton            matlab.ui.control.Button
        ApplySettingsButton             matlab.ui.control.Button
        ActogramSettingsPanel           matlab.ui.container.Panel
        GridLayoutActogramSettingsPanel  matlab.ui.container.GridLayout
        ActogramSettingsRowSpanLabel    matlab.ui.control.Label
        ActogramSettingsRowSpanInput    matlab.ui.control.Spinner
        ActogramSettingsRowSpanUnitLabel  matlab.ui.control.Label
        ActogramSettingsLengthLabel     matlab.ui.control.Label
        ActogramSettingsLengthInput     matlab.ui.control.DropDown
        ActogramSettingsLengthUnitLabel  matlab.ui.control.Label
        ActogramSettingsWidthLabel      matlab.ui.control.Label
        ActogramSettingsWidthInput      matlab.ui.control.DropDown
        ActogramSettingsClockLabel      matlab.ui.control.Label
        ActogramSettingsClockMinInput   matlab.ui.control.EditField
        ActogramSettingsClockMaxInput   matlab.ui.control.EditField
        ActogramSettingsClockUnitLabel  matlab.ui.control.Label
        ActogramSettingsRangeLabel      matlab.ui.control.Label
        ActogramSettingsRangeMinInput   matlab.ui.control.NumericEditField
        ActogramSettingsRangeMaxInput   matlab.ui.control.NumericEditField
        ActogramSettingsLogInput        matlab.ui.control.CheckBox
        AverageStatsTab                 matlab.ui.container.Tab
        GridLayoutAverageStatsTab       matlab.ui.container.GridLayout
        AvStats_ReAnalysePanel          matlab.ui.container.Panel
        AvStats_GridLayoutReAnalysePanel  matlab.ui.container.GridLayout
        AvStats_RegenStatsButton        matlab.ui.control.Button
        AvStats_FilterPanel             matlab.ui.container.Panel
        AvStats_FilterPanelButtonGroup  matlab.ui.container.ButtonGroup
        AvStats_FilterAllDays           matlab.ui.control.RadioButton
        AvStats_FilterWeekDays          matlab.ui.control.RadioButton
        AvStats_FilterWeekendDays       matlab.ui.control.RadioButton
        AvStats_Container               matlab.ui.container.Panel
        GridLayoutAvStats_Container     matlab.ui.container.GridLayout
        AvStats_InfoPanel               matlab.ui.container.Panel
        AvStats_GridLayoutInfoPanel     matlab.ui.container.GridLayout
        AvStats_NumberofDaysLabel       matlab.ui.control.Label
        AvStats_NumberofDaysValue       matlab.ui.control.Label
        AvStats_ActogramWindowLabel     matlab.ui.control.Label
        AvStats_ActogramWindowValue     matlab.ui.control.Label
        AvStats_TimeRejectedLabel       matlab.ui.control.Label
        AvStats_TimeRejectedValue       matlab.ui.control.Label
        AvStats_InterIntraDailyPanel    matlab.ui.container.Panel
        AvStats_GridLayoutInterIntraDailyPanel  matlab.ui.container.GridLayout
        AvStats_AccelerationPanel       matlab.ui.container.Panel
        AvStats_GridLayoutAccelerationPanel  matlab.ui.container.GridLayout
        AvStats_HoursModVigActLabel     matlab.ui.control.Label
        AvStats_HoursModVigActValue     matlab.ui.control.Label
        AvStats_AvEuclNormModVigActLabel  matlab.ui.control.Label
        AvStats_AvEuclNormModVigActValue  matlab.ui.control.Label
        AvStats_LightPanel              matlab.ui.container.Panel
        AvStats_GridLayoutLightPanel    matlab.ui.container.GridLayout
        AvStats_TemperaturePanel        matlab.ui.container.Panel
        AvStats_GridLayoutTemperaturePanel  matlab.ui.container.GridLayout
        AvStats_SleepPanel              matlab.ui.container.Panel
        AvStats_GridLayoutSleepPanel    matlab.ui.container.GridLayout
        AvStats_SlpCountLabel           matlab.ui.control.Label
        AvStats_SlpCountValue           matlab.ui.control.Label
        AvStats_SlpAcrossNoonLabel      matlab.ui.control.Label
        AvStats_SlpAcrossNoonValue      matlab.ui.control.Label
        AvStats_UIAxesSleepClock        matlab.ui.control.UIAxes
        AvStats_LightsOutLabel          matlab.ui.control.Label
        AvStats_SlpOnsetLatLabel        matlab.ui.control.Label
        AvStats_WakeAfterSlpOnsetLabel  matlab.ui.control.Label
        AvStats_FinAwakeLabel           matlab.ui.control.Label
        AvStats_LightsOnLabel           matlab.ui.control.Label
        AvStats_SlpWindowLabel          matlab.ui.control.Label
        AvStats_SlpPeriodLabel          matlab.ui.control.Label
        AvStats_SlpTimeLabel            matlab.ui.control.Label
        AvStats_SlpEffSlpPerLabel       matlab.ui.control.Label
        AvStats_SlpEffSlpTimeLabel      matlab.ui.control.Label
        AvStats_LegendActigraphyLabel   matlab.ui.control.Label
        AvStats_LegendDiaryLabel        matlab.ui.control.Label
        AvStats_LightsOutActValue       matlab.ui.control.Label
        AvStats_SlpOnsetLatActValue     matlab.ui.control.Label
        AvStats_WakeAfterSlpOnsetActValue  matlab.ui.control.Label
        AvStats_FinAwakeActValue        matlab.ui.control.Label
        AvStats_LightsOnActValue        matlab.ui.control.Label
        AvStats_SlpWindowActValue       matlab.ui.control.Label
        AvStats_SlpPeriodActValue       matlab.ui.control.Label
        AvStats_SlpTimeActValue         matlab.ui.control.Label
        AvStats_SlpEffSlpPeriodActValue  matlab.ui.control.Label
        AvStats_SlpEffSlpTimeActValue   matlab.ui.control.Label
        AvStats_LightsOutDiaryValue     matlab.ui.control.Label
        AvStats_SlpOnsetLatDiaryValue   matlab.ui.control.Label
        AvStats_WakeAfterSlpOnsetDiaryValue  matlab.ui.control.Label
        AvStats_FinAwakeDiaryValue      matlab.ui.control.Label
        AvStats_LightsOnDiaryValue      matlab.ui.control.Label
        AvStats_SlpWindowDiaryValue     matlab.ui.control.Label
        AvStats_SlpPeriodDiaryValue     matlab.ui.control.Label
        AvStats_SlpTimeDiaryValue       matlab.ui.control.Label
        AvStats_SlpEffSlpPeriodDiaryValue  matlab.ui.control.Label
        AvStats_SlpEffSlpTimeDiaryValue  matlab.ui.control.Label
        AvStats_MismatchLabel           matlab.ui.control.Label
        AvStats_MismatchSleepPeriodValue  matlab.ui.control.Label
        AvStats_MismatchSleepTimeValue  matlab.ui.control.Label
        DailyStatsTab                   matlab.ui.container.Tab
        GridLayoutDailyStatsTab         matlab.ui.container.GridLayout
        DailyStats_ReAnalysePanel       matlab.ui.container.Panel
        DailyStats_GridLayoutReAnalysePanel  matlab.ui.container.GridLayout
        DailyStats_RegenStatsButton     matlab.ui.control.Button
        DailyStats_Container            matlab.ui.container.Panel
        GridLayoutDailyStats_Container  matlab.ui.container.GridLayout
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % ---------------------------------------------------------
        ACT; % Structure to keep all data in
        Components; % Structure to keep all Components in
        ComponentList = {}; % Cell array to indicate which ones to construct and update
        Settings; % Structure to keep all Settings in
        Menu;
        Waitbar;
        Import; % For other GUIs to use and pass data
        Cursor;
        Interval;
        
        % ---------------------------------------------------------
        % Apps
        importSleepDiaryApp;
        editDatasetInfoApp;
        selectDataApp;
        changeTimeZoneApp;
        changeEpochLengthApp;
        annotateActivityGGIRApp;
        annotateLightApp;
        createEventApp;
        createDailyEventApp;
        createRelativeEventApp;
        editEventApp;
        selectColorApp;
    end
    
    methods (Access = public)
        
        function SizeChanged(app, source, event)
            % ---------------------------------------------------------
            return % a.k.a. do nothing
        end
        
        function EventListener(app, source, event)
            try
                % ---------------------------------------------------------
                % Copy the display settings
                if ...
                        ~isfield(app.ACT, 'urdisplay') && ...
                        ~strcmpi(source.UserData.input, 'na') && ...
                        ~strcmpi(source.UserData.input, 'cursor') && ...
                        ~strcmpi(source.UserData.input, 'editEvent') && ...
                        ~strcmpi(source.UserData.input, 'deleteEvents') && ...
                        ~strcmpi(source.UserData.input, 'sleepWindowsToManual') && ...
                        ~strcmpi(source.UserData.input, 'deleteSleepWindows') && ...
                        ~strcmpi(source.UserData.input, 'deleteAnnotation')
                    app.ACT.saved = false;
                    app.ACT.urdisplay = app.ACT.display;
                    app.ACT.analysis.ursettings = app.ACT.analysis.settings;
                    app.CancelSettingsButton.Enable = 'on';
                    app.ApplySettingsButton.Enable = 'on';
                    app.ComponentList = [app.ComponentList, {'Info'}];
                end
                % ---------------------------------------------------------
                % Extract datatype and check what input the source is
                datatype = source.UserData.type;
                switch source.UserData.input
                    % ---------------------------------------------------------
                    case 'rowspan'
                        % Set value to state
                        app.ACT.display.(datatype).rowspan = event.Value;
                        app.ComponentList = [app.ComponentList, {'Axes'}];
                        % ---------------------------------------------------------
                    case 'actogramLength'
                        % Set value to state
                        app.ACT.display.actogramLength = str2double(event.Value);
                        app.ComponentList = [app.ComponentList, {'DataContainer', 'Panel', 'Axes'}];
                        % ---------------------------------------------------------
                    case 'actogramWidth'
                        % Set value to state
                        app.ACT.display.actogramWidth = event.Value;
                        app.ComponentList = [app.ComponentList, {'DataContainer', 'Panel', 'Axes', 'Events', 'Acceleration', 'Annotation', 'Data', 'Cursor', 'Interval'}];
                        % ---------------------------------------------------------
                    case 'actogramStartClock'
                        % Indicate to recalculate number of days and GGIR's sleep detection algorithm and actigraphy sleep events if required
                        app.ApplySettingsButton.UserData.redo.ndays = true;
                        app.ApplySettingsButton.UserData.redo.sleep = true;
                        % Set value to state
                        app.ACT.display.actogramStartClock = event.Value;
                        app.ComponentList = [app.ComponentList, {'DataContainer', 'Panel', 'Axes', 'Events', 'Acceleration', 'Annotation', 'Data', 'Cursor', 'Interval'}];
                        % ---------------------------------------------------------
                    case 'actogramEndClock'
                        % Indicate to recalculate number of days and GGIR's sleep detection algorithm and actigraphy sleep events if required
                        app.ApplySettingsButton.UserData.redo.ndays = true;
                        app.ApplySettingsButton.UserData.redo.sleep = true;
                        % Set value to state
                        app.ACT.display.actogramEndClock = event.Value;
                        app.ComponentList = [app.ComponentList, {'DataContainer', 'Panel', 'Axes', 'Events', 'Acceleration', 'Annotation', 'Data', 'Cursor', 'Interval'}];
                        % ---------------------------------------------------------
                    case 'show'
                        % Set value to state in the appropriate structure
                        if isfield(source.UserData, 'field')
                            fname = source.UserData.field;
                            app.ACT.display.(datatype).field.(fname).show = double(event.Value);
                        else
                            app.ACT.display.(datatype).show = double(event.Value);
                            app.ComponentList = [app.ComponentList, {'Axes'}];
                        end
                        app.ComponentList = [app.ComponentList, {'Data'}];
                        % ---------------------------------------------------------
                    case 'range'
                        % Set value to state
                        idx = source.UserData.idx;
                        app.ACT.display.(datatype).range(idx) = event.Value;
                        % ---------------------------------------------------------
                        % Set copy over the new range to the metric's sub-structure
                        if strcmpi(datatype, 'acceleration')
                            metric = app.ACT.display.acceleration.view;
                            app.ACT.display.(datatype).(metric).range(idx) = event.Value;
                            app.ComponentList = [app.ComponentList, {'Axes', 'Annotation', 'Cursor', 'Interval'}];
                        else
                            app.ComponentList = [app.ComponentList, {'Axes'}];
                        end
                        % ---------------------------------------------------------
                    case 'log'
                        % Set value to state
                        app.ACT.display.(datatype).log = double(event.Value);
                        % ---------------------------------------------------------
                        % Set copy over the new range to the metric's sub-structure
                        if strcmpi(datatype, 'acceleration')
                            metric = app.ACT.display.acceleration.view;
                            app.ACT.display.(datatype).(metric).log = double(event.Value);
                        end
                        app.ComponentList = [app.ComponentList, {'Axes'}];
                        % ---------------------------------------------------------
                    case 'color'
                        % Prevent double clicking
                        source.Enable = 'off'; drawnow;
                        % Call the User Interface to select a new color
                        app.selectColorApp = selectColor(app, source.BackgroundColor);
                        % Wait for the response
                        uiwait(app.selectColorApp.SelectColorUIFigure);
                        if ~isfield(app.Import, 'Return')
                            app.Import = struct();
                            source.Enable = 'on';
                            return
                        elseif ~app.Import.Return
                            app.Import = struct();
                            source.Enable = 'on';
                            return
                        end
                        % ---------------------------------------------------------
                        % Set value to state
                        fname = source.UserData.field;
                        app.ACT.display.(datatype).field.(fname).clr = app.Import.Data;
                        source.BackgroundColor = app.ACT.display.(datatype).field.(fname).clr;
                        % ---------------------------------------------------------
                        % Clear the import structure
                        app.Import = struct();
                        % ---------------------------------------------------------
                        app.ComponentList = [app.ComponentList, {'Data'}];
                        % Reactivate the button
                        source.Enable = 'on';
                        % ---------------------------------------------------------
                    case 'order'
                        % Set the current and new index
                        currIdx = source.Parent.Layout.Row - 2;
                        newIdx  = currIdx + source.UserData.direction;
                        if newIdx < 1 || newIdx > length(app.ACT.display.order)
                            return
                        end
                        % ---------------------------------------------------------
                        % Create new index vector
                        idx = 1:length(app.ACT.display.order);
                        prevIdx = idx(newIdx);
                        idx(newIdx) = idx(currIdx);
                        idx(currIdx) = prevIdx;
                        % ---------------------------------------------------------
                        % Switch the order
                        app.ACT.display.order = app.ACT.display.order(idx);
                        app.GridLayoutSettingsPanel.RowHeight(3:length(idx)+2) = app.GridLayoutSettingsPanel.RowHeight(idx+2);
                        CmpA = findobj(app.GridLayoutSettingsPanel.Children, 'Tag', ['SettingsPanel_', app.ACT.display.order{newIdx}]);
                        CmpB = findobj(app.GridLayoutSettingsPanel.Children, 'Tag', ['SettingsPanel_', app.ACT.display.order{currIdx}]);
                        CmpA.Layout.Row = newIdx+2;
                        CmpB.Layout.Row = currIdx+2;
                        % ---------------------------------------------------------
                        app.ComponentList = [app.ComponentList, {'Axes', 'Data'}];
                        % ---------------------------------------------------------
                    case 'moveField'
                        % Create new index vector
                        fname = source.UserData.field;
                        fnames = fieldnames(app.ACT.display.(datatype).field);
                        currIdx = find(strcmpi(fnames, fname));
                        newIdx  = currIdx + source.UserData.direction;
                        idx = 1:length(fnames);
                        prevIdx = idx(newIdx);
                        idx(newIdx) = idx(currIdx);
                        idx(currIdx) = prevIdx;
                        % ---------------------------------------------------------
                        % Switch the order
                        app.ACT.display.(datatype).field = orderfields(app.ACT.display.(datatype).field, idx);
                        parent = findobj(app.GridLayoutSettingsPanel.Children, 'Tag', ['GridLayoutSettingsPanel_', datatype]);
                        fnames = fieldnames(app.ACT.display.(datatype).field);
                        for fi = 1:length(fnames)
                            parent.UserData.(['FieldLabel_', fnames{fi}]).Layout.Row = fi;
                            parent.UserData.(['FieldShowInput_', fnames{fi}]).Layout.Row = fi;
                            parent.UserData.(['FieldClrButton_', fnames{fi}]).Layout.Row = fi;
                            parent.UserData.(['FieldUpButton_', fnames{fi}]).Layout.Row = fi;
                            parent.UserData.(['FieldDownButton_', fnames{fi}]).Layout.Row = fi;
                            if fi == 1
                                parent.UserData.(['FieldUpButton_', fnames{fi}]).Enable = 'off';
                            else
                                parent.UserData.(['FieldUpButton_', fnames{fi}]).Enable = 'on';
                            end
                            if fi == length(fnames)
                                parent.UserData.(['FieldDownButton_', fnames{fi}]).Enable = 'off';
                            else
                                parent.UserData.(['FieldDownButton_', fnames{fi}]).Enable = 'on';
                            end
                        end
                        % ---------------------------------------------------------
                        app.ComponentList = [app.ComponentList, {'Data'}];
                        % ---------------------------------------------------------
                    case 'editEvent'
                        if strcmpi(app.ApplySettingsButton.Enable, 'on')
                            % Aks if the user to first apply or cancel any changed settings
                            sel = uiconfirm(app.CicadaUIFigure, 'Please apply or cancel any changed settings first.', 'Notification', ...
                                'Options',{'Ok'},...
                                'DefaultOption', 'Ok', ...
                                'Icon', 'warning');
                            return
                        end
                        % if the user double clicked, the source may have been deleted already
                        if ~isempty(app.editEventApp)
                            if isvalid(app.editEventApp)
                                return
                            end
                        end
                        source.LineWidth = 2;
                        source.EdgeColor = [0.0902 0.2196 0.1098];
                        % Call the User Interface to edit the event
                        app.editEventApp = editEvent(app, source.UserData.id);
                        % Wait for the response
                        uiwait(app.editEventApp.EditEventUIFigure);
                        source.LineWidth = 1;
                        source.EdgeColor = source.FaceColor;
                        if ~isfield(app.Import, 'Return')
                            app.Import = struct();
                            return
                        elseif ~app.Import.Return
                            app.Import = struct();
                            return
                        end
                        % ---------------------------------------------------------
                        % Check that the statistics functions have not run yet, otherwise we have to
                        % undo any statistics steps before continueing
                        if any(ismember(app.ACT.pipe, {'statistics'}))
                            cancelAction = app_askToUndoStatistics(app);
                            if cancelAction
                                return
                            end
                            lifecycle(app, true)
                        end
                        % ---------------------------------------------------------
                        % Delete or Change the event label, onset and duration
                        ids = source.UserData.id; % To keep track of which events need updating
                        idx = app.ACT.analysis.events.id == ids; % Find the event in the events table
                        % 'Select' refers to the part of the data that covers the original event
                        select = [app.ACT.analysis.events.onset(idx), app.ACT.analysis.events.onset(idx)+app.ACT.analysis.events.duration(idx)];
                        if ~any(idx) % Something must have gone horribly wrong
                            return
                        elseif app.Import.Data.delete
                            % ---------------------------------------------------------
                            % Aks if the user is sure
                            sel = uiconfirm(app.CicadaUIFigure, 'Are you sure to delete this event?', 'Delete event', ...
                                'Options',{'No, cancel', 'Yes, delete'},...
                                'DefaultOption', 'No, cancel', ...
                                'CancelOption', 'No, cancel', ...
                                'Icon', 'question');
                            % ---------------------------------------------------------
                            % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                            if strcmpi(sel, 'No, cancel')
                                return
                            end
                            % ---------------------------------------------------------
                            % Delete the event
                            app.ACT = cic_editEvents(app.ACT, 'delete', [], [], 'id', source.UserData.id);
                            % ---------------------------------------------------------
                            % Write history
                            app.ACT.history = char(app.ACT.history, '% ---------------------------------------------------------');
                            app.ACT.history = char(app.ACT.history, '% Delete one event using its ID');
                            app.ACT.history = char(app.ACT.history, sprintf('ACT = cic_editEvents(ACT, ''delete'', [], [], ''id'', %i);', source.UserData.id));
                        else
                            % ---------------------------------------------------------
                            % Edit the event
                            % -----
                            % Check if new event does not overlap with an existing event of the same type/label
                            [err, message] = checkEventOverlap(app, app.Import.Data);
                            if err
                                uiconfirm(app.CicadaUIFigure, message, 'Error', ...
                                    'Options',{'Ok'},...
                                    'DefaultOption', 'Ok', ...
                                    'Icon', 'error');
                                app.Cursor = struct();
                                app.Interval = struct();
                                app.ComponentList = [app.ComponentList, {'Cursor', 'Interval'}];
                                lifecycle(app, false)
                                return
                            end
                            % -----
                            % Call 'editEvents' to change the event
                            app.ACT = cic_editEvents(app.ACT, 'edit', app.Import.Data.onset, app.Import.Data.duration, ...
                                'id', source.UserData.id, ...
                                'Label', app.Import.Data.label, ...
                                'Type', app.Import.Data.type);
                            % If the new onset and duration are larger than the previous ones, update the previous 'select'
                            if select(1) > app.Import.Data.onset
                                select(1) = app.Import.Data.onset;
                            end
                            if select(2) < app.Import.Data.onset+app.Import.Data.duration
                                select(2) = app.Import.Data.onset+app.Import.Data.duration;
                            end
                            % ---------------------------------------------------------
                            % Write history
                            app.ACT.history = char(app.ACT.history, '% ---------------------------------------------------------');
                            app.ACT.history = char(app.ACT.history, '% Edit one event using its ID');
                            app.ACT.history = char(app.ACT.history, sprintf('onset = datenum(''%s'', ''dd/mm/yyyy HH:MM''); %% new onset of event', datestr(app.Import.Data.onset, 'dd/mm/yyyy HH:MM')));
                            app.ACT.history = char(app.ACT.history, sprintf('duration = %.4f/24; %% new duration of event in days (i.e. hours/24)', app.Import.Data.duration*24));
                            app.ACT.history = char(app.ACT.history, 'ACT = cic_editEvents(ACT, ''edit'', onset, duration, ...');
                            app.ACT.history = char(app.ACT.history, sprintf('    ''id'', %i, ...', source.UserData.id));
                            app.ACT.history = char(app.ACT.history, sprintf('    ''Label'', ''%s'', ...', app.Import.Data.label));
                            app.ACT.history = char(app.ACT.history, sprintf('    ''Type'', ''%s'');', app.Import.Data.type));
                        end
                        % Check if any sleepPeriod and WASO events need updating
                        [app.ACT, id] = cic_actigraphySleepEvents(app.ACT, select);
                        ids = [ids; id];
                        % ---------------------------------------------------------
                        % Clear the import structure
                        app.Import = struct();
                        % ---------------------------------------------------------
                        ComponentTags = strsplit(sprintf('Event_day-0_id-%i,', ids), ',');
                        app.ComponentList = [app.ComponentList, ComponentTags(1:end-1), {'Info', 'SettingsPanel'}];
                        lifecycle(app, false);
                        % ---------------------------------------------------------
                    case 'deleteEvents'
                        if strcmpi(app.ApplySettingsButton.Enable, 'on')
                            % Aks if the user to first apply or cancel any changed settings
                            sel = uiconfirm(app.CicadaUIFigure, 'Please apply or cancel any changed settings first.', 'Notification', ...
                                'Options',{'Ok'},...
                                'DefaultOption', 'Ok', ...
                                'Icon', 'warning');
                            return
                        end
                        % Prevent double clicking
                        source.Enable = 'off'; drawnow;
                        % The dropdown input value determines the sleep window type
                        Component = findobj(app.GridLayoutSettingsPanel.Children, 'Tag', 'DeleteEventsInput');
                        % ---------------------------------------------------------
                        % Aks if the user is sure
                        sel = uiconfirm(app.CicadaUIFigure, ['Are you sure to delete all events with label ''', Component.Value, '''?'], 'Delete all events', ...
                            'Options',{'No, cancel', 'Yes, delete'},...
                            'DefaultOption', 'No, cancel', ...
                            'CancelOption', 'No, cancel', ...
                            'Icon', 'question');
                        % ---------------------------------------------------------
                        % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                        if strcmpi(sel, 'No, cancel')
                            source.Enable = 'on';
                            return
                        end
                        % ---------------------------------------------------------
                        % Check that the statistics functions have not run yet, otherwise we have to
                        % undo any statistics steps before continueing
                        if any(ismember(app.ACT.pipe, {'statistics'}))
                            cancelAction = app_askToUndoStatistics(app);
                            if cancelAction
                                source.Enable = 'on';
                                return
                            end
                            lifecycle(app, true)
                        end
                        % ---------------------------------------------------------
                        % User did not press cancel, so delete all events of with this label
                        [app.ACT, idsEv] = cic_editEvents(app.ACT, 'delete', [], [], 'Label', Component.Value);
                        % ---------------------------------------------------------
                        % Write history
                        app.ACT.history = char(app.ACT.history, '% ---------------------------------------------------------');
                        app.ACT.history = char(app.ACT.history, sprintf('%% Delete all events of label ''%s''', Component.Value));
                        app.ACT.history = char(app.ACT.history, sprintf('ACT = cic_editEvents(ACT, ''delete'', [], [], ''Label'', ''%s'');', Component.Value));
                        % ---------------------------------------------------------
                        ComponentTags = strsplit(sprintf('Event_day-0_id-%i,', idsEv), ',');
                        app.ComponentList = [app.ComponentList, ComponentTags(1:end-1), {'Info', 'SettingsPanel'}];
                        lifecycle(app, true);
                        % Reactivate the button
                        source.Enable = 'on';
                        % ---------------------------------------------------------
                    case 'sleepWindowType'
                        % Indicate to recalculate number of days and GGIR's sleep detection algorithm and actigraphy sleep events if required
                        app.ApplySettingsButton.UserData.redo.ndays = false;
                        app.ApplySettingsButton.UserData.redo.sleep = true;
                        % Set value to state
                        app.ACT.analysis.settings.sleepWindowType = event.Source.Value;
                        app.ComponentList = [app.ComponentList, {'Events', 'SettingsPanel'}];
                        % ---------------------------------------------------------
                    case 'sleepWindowsToManual'
                        if strcmpi(app.ApplySettingsButton.Enable, 'on')
                            % Aks if the user to first apply or cancel any changed settings
                            sel = uiconfirm(app.CicadaUIFigure, 'Please apply or cancel any changed settings first.', 'Notification', ...
                                'Options',{'Ok'},...
                                'DefaultOption', 'Ok', ...
                                'Icon', 'warning');
                            return
                        end
                        % Prevent double clicking
                        source.Enable = 'off'; drawnow;
                        % ---------------------------------------------------------
                        % The dropdown input value determines the sleep window type
                        Component = findobj(app.GridLayoutSettingsPanel.Children, 'Tag', 'SleepWindowTypeInput');
                        % If the value is 'manual' already, there is is nothing to do, so return
                        if strcmpi(Component.Value, 'manual')
                            source.Enable = 'on';
                            return
                        end
                        % ---------------------------------------------------------
                        % Copy current sleepwindows to type 'manual'
                        events = selectEventsUsingTime(app.ACT.analysis.events, app.ACT.xmin, app.ACT.xmax, 'Label', 'sleepWindow', 'Type', Component.Value);
                        % ---------------------------------------------------------
                        % Check if the events overlap with a current manual sleep window
                        [err, message] = checkEventOverlapWithManualSleepWindow(app, events);
                        if err
                            source.Enable = 'on';
                            uiconfirm(app.CicadaUIFigure, message, 'Error', ...
                                'Options',{'Ok'},...
                                'DefaultOption', 'Ok', ...
                                'Icon', 'error');
                            return
                        end
                        % All is well, copy over the events
                        app.ACT = cic_editEvents(app.ACT, 'add', events.onset, events.duration, 'Label', 'sleepWindow', 'Type', 'manual');
                        app.ACT.analysis.settings.sleepWindowType = 'manual';
                        slpWinEvents = selectEventsUsingTime(app.ACT.analysis.events, app.ACT.xmin, app.ACT.xmax, 'Label', 'sleepWindow', 'Type', 'manual');
                        [app.ACT, idsSp] = cic_actigraphySleepEvents(app.ACT);
                        % ---------------------------------------------------------
                        ComponentTags = strsplit(sprintf('Event_day-0_id-%i,', [events.id; idsSp; slpWinEvents.id]), ',');
                        app.ComponentList = [app.ComponentList, ComponentTags(1:end-1), {'Info', 'SettingsPanel'}];
                        lifecycle(app, true);
                        % Reactivate the button
                        source.Enable = 'on';
                        % ---------------------------------------------------------
                    case 'deleteSleepWindows'
                        if strcmpi(app.ApplySettingsButton.Enable, 'on')
                            % Aks if the user to first apply or cancel any changed settings
                            sel = uiconfirm(app.CicadaUIFigure, 'Please apply or cancel any changed settings first.', 'Notification', ...
                                'Options',{'Ok'},...
                                'DefaultOption', 'Ok', ...
                                'Icon', 'warning');
                            return
                        end
                        % Prevent double clicking
                        source.Enable = 'off'; drawnow;
                        % The dropdown input value determines the sleep window type
                        Component = findobj(app.GridLayoutSettingsPanel.Children, 'Tag', 'SleepWindowTypeInput');
                        % ---------------------------------------------------------
                        % Aks if the user is sure
                        sel = uiconfirm(app.CicadaUIFigure, ['Are you sure to delete all sleep events of type ''', Component.Value, '''?'], 'Delete all events', ...
                            'Options',{'No, cancel', 'Yes, delete'},...
                            'DefaultOption', 'No, cancel', ...
                            'CancelOption', 'No, cancel', ...
                            'Icon', 'question');
                        % ---------------------------------------------------------
                        % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                        if strcmpi(sel, 'No, cancel')
                            source.Enable = 'on';
                            return
                        end
                        % ---------------------------------------------------------
                        % Check that the statistics functions have not run yet, otherwise we have to
                        % undo any statistics steps before continueing
                        if any(ismember(app.ACT.pipe, {'statistics'}))
                            cancelAction = app_askToUndoStatistics(app);
                            if cancelAction
                                source.Enable = 'on';
                                return
                            end
                            lifecycle(app, true)
                        end
                        % ---------------------------------------------------------
                        % User did not press cancel, so delete all events of this sleep window type
                        [app.ACT, idsSw] = cic_editEvents(app.ACT, 'delete', [], [], 'Label', 'sleepWindow', 'Type', Component.Value);
                        % ---------------------------------------------------------
                        % Write history
                        app.ACT.history = char(app.ACT.history, '% ---------------------------------------------------------');
                        app.ACT.history = char(app.ACT.history, sprintf('%% Delete all sleep windows of type ''%s''', Component.Value));
                        app.ACT.history = char(app.ACT.history, sprintf('ACT = cic_editEvents(ACT, ''delete'', [], [], ''Label'', ''%s'');', Component.Value));
                        % ---------------------------------------------------------
                        % if the user deleted a Sleep Diary, also delete its sleep periods and WASO events
                        if strcmpi(Component.Value, 'sleepDiary')
                            app.ACT = cic_editEvents(app.ACT, 'delete', [], [], 'Label', 'sleepPeriod', 'Type', 'sleepDiary');
                            app.ACT = cic_editEvents(app.ACT, 'delete', [], [], 'Label', 'waso', 'Type', 'sleepDiary');
                            % ---------------------------------------------------------
                            % Write history
                            app.ACT.history = char(app.ACT.history, '% -----');
                            app.ACT.history = char(app.ACT.history, '% You deleted a Sleep Diary, so also delete its sleep periods and WASO events');
                            app.ACT.history = char(app.ACT.history, 'ACT = cic_editEvents(ACT, ''delete'', [], [], ''Label'', ''sleepPeriod'', ''Type'', ''sleepDiary'');');
                            app.ACT.history = char(app.ACT.history, 'ACT = cic_editEvents(ACT, ''delete'', [], [], ''Label'', ''waso'', ''Type'', ''sleepDiary'');');
                        end
                        % ---------------------------------------------------------
                        % Reset the sleep window type and re-calculate the sleep events
                        app.ACT.analysis.settings.sleepWindowType = 'manual'; % refer back to the default sleep window type
                        % ---------------------------------------------------------
                        % Write history
                        app.ACT.history = char(app.ACT.history, '% -----');
                        app.ACT.history = char(app.ACT.history, '% Refer back to the default sleep window type');
                        app.ACT.history = char(app.ACT.history, 'ACT.analysis.settings.sleepWindowType = ''manual'';');
                        % ---------------------------------------------------------
                        % Calculate the sleep events
                        [app.ACT, idsSp] = cic_actigraphySleepEvents(app.ACT);
                        % Extract the sleep window events that need updating
                        slpWinEvents = selectEventsUsingTime(app.ACT.analysis.events, app.ACT.xmin, app.ACT.xmax, 'Label', 'sleepWindow', 'Type', 'manual');
                        ComponentTags = strsplit(sprintf('Event_day-0_id-%i,', [idsSw; idsSp; slpWinEvents.id]), ',');
                        app.ComponentList = [app.ComponentList, ComponentTags(1:end-1), {'Info', 'SettingsPanel'}];
                        lifecycle(app, true);
                        % Reactivate the button
                        source.Enable = 'on';
                        % ---------------------------------------------------------
                    case 'deleteAnnotation'
                        if strcmpi(app.ApplySettingsButton.Enable, 'on')
                            % Aks if the user to first apply or cancel any changed settings
                            sel = uiconfirm(app.CicadaUIFigure, 'Please apply or cancel any changed settings first.', 'Notification', ...
                                'Options',{'Ok'},...
                                'DefaultOption', 'Ok', ...
                                'Icon', 'warning');
                            return
                        end
                        % Prevent double clicking
                        source.Enable = 'off'; drawnow;
                        % Aks if the user is sure
                        sel = uiconfirm(app.CicadaUIFigure, 'Are you sure to delete the annotation?', 'Delete annotation', ...
                            'Options',{'No, cancel', 'Yes, delete'},...
                            'DefaultOption', 'No, cancel', ...
                            'CancelOption', 'No, cancel', ...
                            'Icon', 'question');
                        % ---------------------------------------------------------
                        % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                        if strcmpi(sel, 'No, cancel')
                            source.Enable = 'on';
                            return
                        end
                        % ---------------------------------------------------------
                        % Check that the statistics functions have not run yet, otherwise we have to
                        % undo any statistics steps before continueing
                        if any(ismember(app.ACT.pipe, {'statistics'}))
                            cancelAction = app_askToUndoStatistics(app);
                            if cancelAction
                                source.Enable = 'on';
                                return
                            end
                            lifecycle(app, true)
                        end
                        % ---------------------------------------------------------
                        % The dropdown input value determines the sleep window type
                        Component = findobj(app.GridLayoutSettingsPanel.Children, 'Tag', 'AnnotationTypeInput');
                        % Reactivate the button
                        source.Enable = 'on';
                        % ---------------------------------------------------------
                        % Undo annotation
                        [app.ACT, idsSp] = cic_undoAnnotation(app.ACT, Component.Value);
                        % ---------------------------------------------------------
                        ComponentTags = strsplit(sprintf('Event_day-0_id-%i,', idsSp), ',');
                        app.ComponentList = [app.ComponentList, ComponentTags(1:end-1), {'Info', 'Annotation', 'SettingsPanel'}];
                        lifecycle(app, true);
                        % ---------------------------------------------------------
                    case 'cursor'
                        if strcmpi(app.ApplySettingsButton.Enable, 'on')
                            % Aks if the user to first apply or cancel any changed settings
                            sel = uiconfirm(app.CicadaUIFigure, 'Please apply or cancel any changed settings first.', 'Notification', ...
                                'Options',{'Ok'},...
                                'DefaultOption', 'Ok', ...
                                'Icon', 'warning');
                            return
                        end
                        app.Cursor.Parent = source.Parent.Parent;
                        app.Cursor.Position = round(source.CurrentPoint(1)*24*60)/(24*60);
                        app.Cursor.Position = max([app.Cursor.Position, app.ACT.xmin]);
                        app.Cursor.Position = min([app.Cursor.Position, app.ACT.xmax]);
                        app.Cursor.CurrentPoint = source.CurrentPoint;
                        app.ComponentList = [app.ComponentList, {'Cursor'}];
                        lifecycle(app, false);
                        % ---------------------------------------------------------
                end
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end
        
        % ---------------------------------------------------------
        % Function to reset the GUI
        function initialize(app)
            try
                % ---------------------------------------------------------
                % Load the Cicada settings
                if exist([fileparts(which('cicada')), filesep, 'settings', filesep, 'CicadaSettings.json'], 'file') == 0
                    % -----
                    % Notify the User that the settings file is not found
                    sel = uiconfirm(app.CicadaUIFigure, 'Cicada cannot find its settings file. Please use the browse button to select the Cicada Settings .JSON file', 'Oops... The Cicada is missing some parts.', ...
                        'Options',{'Browse'},...
                        'DefaultOption', 'Browse', ...
                        'Icon', 'question');
                    % -----
                    % Ask the user to select the settings file
                    [selfile, selpath] = uigetfile('*.json', 'Select the Cicada settings .JSON file');
                    if selfile == 0
                        % If the user did not select a file, delete the app
                        delete(app);
                        return;
                    end
                    % -----
                    % Load the settings file the User Specified
                    app.Settings = jsondecode(fileread(fullfile(selpath, selfile)));
                    % -----
                    % Save the Cicada settings JSON file to its default location
                    struct2json(app.Settings, [fileparts(which('cicada')), filesep, 'settings', filesep, 'CicadaSettings.json']);
                end
                % Load the Cicada settings file
                app.Settings = jsondecode(fileread([fileparts(which('cicada')), filesep, 'settings', filesep, 'CicadaSettings.json']));
                % -----
                % Check if the sleep diary import settings .JSON file can be found, otherwise empty
                if isempty(which(app.Settings.importSettings.sleepDiary))
                    app.Settings.importSettings.sleepDiary = '';
                end
                % ---------------------------------------------------------
                % Create a new empty ACT state
                app.ACT = newDataset();
                
                % ---------------------------------------------------------
                % Store the default display values if they don't exist already
                app.ACT.display = softCopyStructure(app.ACT.display, app.Settings.display);
                
                % ---------------------------------------------------------
                % Revert all menu items back to default
                app.Menu.CloseMenu.Enable = 'off';
                app.Menu.SaveDatasetMenu.Enable = 'off';
                app.Menu.SaveDatasetAsMenu.Enable = 'off';
                app.Menu.ImportEventsMenu.Enable = 'off';
                app.Menu.ImportOtherDataMenu.Enable = 'off';
                app.Menu.ExportMenu.Enable = 'off';
                app.Menu.ExportAnalysisMenu.Enable = 'off';
                app.Menu.ExportReportMenu.Enable = 'off';
                app.Menu.EditMenu.Enable = 'off';
                app.Menu.ViewMenu.Enable = 'off';
                app.Menu.ShowActivityCountsMenu.Enable = 'on';
                app.Menu.ShowActivityCountsMenu.Checked = 'off';
                app.Menu.ShowEuclideanNormMenu.Enable = 'off';
                app.Menu.ShowEuclideanNormMenu.Checked = 'on';
                app.Menu.ShowAngleMenu.Enable = 'on';
                app.Menu.ShowAngleMenu.Checked = 'off';
                app.Menu.PreprocessMenu.Enable = 'off';
                app.Menu.AnalysisMenu.Enable = 'off';
                app.Menu.AnnotateLightMenu.Enable = 'off';
                app.Menu.StatisticsMenu.Enable = 'off';
                app.Menu.WindowMenu.Enable = 'off';
                
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                
                % ---------------------------------------------------------
                % Intialize the cursor and interval
                app.Cursor = struct();
                app.Interval = struct();
                
                % ---------------------------------------------------------
                % Delete all Settings panels so they are redrawn
                Cmps = findobj(app.GridLayoutSettingsPanel.Children, '-regexp', 'Tag', 'GridLayoutMove_*');
                for ci = 1:length(Cmps)
                    unmountComponent(app, Cmps(ci))
                end
                Cmps = findobj(app.GridLayoutSettingsPanel.Children, '-regexp', 'Tag', '^SettingsPanel_*');
                for ci = 1:length(Cmps)
                    unmountComponent(app, Cmps(ci))
                end
                unmountComponent(app, findobj(app.GridLayoutSettingsPanel.Children, 'Tag', 'SleepWindowTypePanel'));
                
                % ---------------------------------------------------------
                % When resetting the GUI these components should update
                app.ComponentList = [app.ComponentList, {'Info', 'Menu', 'SettingsPanel', 'DataContainer', 'Panel', 'Axes', 'Events', 'Acceleration', 'Annotation', 'Data', 'Stats', 'Cursor', 'Interval'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end
        
        % ---------------------------------------------------------
        % Function to map the data in 'app.ACT' to the properties of all components
        function mapStateToProps(app)
            % ---------------------------------------------------------
            % Only keep unique values in the component list
            app.ComponentList = unique(app.ComponentList);
            
            % ---------------------------------------------------------
            % Construct Cursor and Interval
            if ismember('Cursor', app.ComponentList) && isfield(app.Cursor, 'Position')
                app_constructCursor(app);
            end
            if ismember('Interval', app.ComponentList) && isfield(app.Interval, 'Position')
                app_constructInterval(app);
            end
            
            % ---------------------------------------------------------
            % Construct all static components
            app_constructStaticComponents(app);
            
            % ---------------------------------------------------------
            % Construct the stats tabs
            if any(ismember({'Stats', 'AvStats'}, app.ComponentList))
                app_constructStatsAverage(app);
            end
            if any(ismember({'Stats', 'DailyStats'}, app.ComponentList))
                app_constructStatsDaily(app);
            end
            if ismember({'Stats'}, app.ComponentList)
                % ---------------------------------------------------------
                % If there is a sleep analysis, construct the tab
                if isfield(app.ACT.stats, 'sleep')
                    if isfield(app.ACT.stats.sleep, 'actigraphy')
                        app_constructStatsSleep(app);
                    end
                end
                % Construct custom stats tabs
                labels = unique(app.ACT.analysis.events.label(strcmpi(app.ACT.analysis.events.type, 'customEvent')));
                if ~isempty(labels)
                    cellfun(@(lbl) app_constructStatsCustom(app, lbl), labels, 'UniformOutput', false);
                end
            end
            
            % ---------------------------------------------------------
            % If the 'order' field does not exist, this means no data is loaded and no futher components need to be mapped
            if ~isfield(app.ACT.display, 'order')
                return
            end
            
            % ---------------------------------------------------------
            % Construct and mount the dynamic settings panel components
            if ismember('SettingsPanel', app.ComponentList)
                app_constructSettingsPanel(app);
            end
            
            % ---------------------------------------------------------
            % If you want to update only one event component, the tag of that component is passed to the ComponentList
            if any(strRegexpCheck(app.ComponentList, '^Event_day-[0-9]+_id-[0-9]+'))
                app_constructSingleEvents(app);
            end
            
            % ---------------------------------------------------------
            % Construct all data panel components
            if any(ismember({'Panel', 'Axes', 'Events', 'Acceleration', 'Annotation', 'Data'}, app.ComponentList))
                app_constructDataPanel(app);
            end
        end
        
        % ---------------------------------------------------------
        % Function to test whether a specific component should mount or not
        function [Result, Component] = shouldComponentMount(app, parent, tag)
            Component = findobj(parent.Children, 'Tag', tag);
            Result = isempty(Component);
        end
        
        % ---------------------------------------------------------
        % Function to mount a specific component in the GUI
        function Component = mountComponent(app, mountFnc, parent, props, varargin)
            if nargin > 4
                Component = eval(sprintf('%s(app, parent, props, varargin{:})', mountFnc));
            else
                Component = eval(sprintf('%s(app, parent, props)', mountFnc));
            end
        end
        
        % ---------------------------------------------------------
        % Function to construct a specific component in app.Components
        function Component = constructComponent(app, tag, parent, props)
            if isobject(tag)
                Component = tag;
            else
                Component = findobj(parent.Children, 'Tag', tag);
            end
            app.Components = [app.Components; {Component, props}];
        end
        
        % ---------------------------------------------------------
        % Function to test whether a specific component should update or not
        function result = shouldComponentUpdate(app, Component, nextProps)
            % Extract the current values of the component's properties
            currProps = cellfun(@(p) Component.(p), nextProps(:,1), 'UniformOutput', false);
            % Compare the nextProps values to that of currProps
            result = any(cellfun(@(a, b) ~isequaln(a, b), currProps, nextProps(:,2)));
        end
        
        % ---------------------------------------------------------
        % Function to update a specific component in the GUI
        function updateComponent(app, Component, props)
            cellfun(@(p, v) set(Component, p, v), props(:,1), props(:,2), 'UniformOutput', false)
        end
        
        % ---------------------------------------------------------
        % Function to delete a specific component from the GUI
        function unmountComponent(app, Component)
            delete(Component)
        end
        
        % ---------------------------------------------------------
        % Function to delete any dynamic component
        function unmountComponents(app)
            % ---------------------------------------------------------
            % Check if any data panels need to be deleted
            if ismember('DataContainer', app.ComponentList)
                Cmps = app.DataContainer.Children;
                for ci = 1:length(Cmps)
                    if isempty(app.ACT.ndays)
                        unmountComponent(app, Cmps(ci))
                    elseif Cmps(ci).UserData.Day > app.ACT.ndays
                        unmountComponent(app, Cmps(ci))
                    end
                end
            end
            % ---------------------------------------------------------
            % Check if any event objects need to be deleted
            if ismember('Events', app.ComponentList) || any(strRegexpCheck(app.ComponentList, '^Event_day-[0-9]+_id-[0-9]+'))
                Cmps = findobj(app.DataContainer.Children, '-regexp', 'Tag', 'Event*');
                for ci = 1:length(Cmps)
                    if ~ismember(Cmps(ci).UserData.id, app.ACT.analysis.events.id)
                        unmountComponent(app, Cmps(ci))
                    end
                end
            end
            % ---------------------------------------------------------
            % Check if any annotation objects need to be deleted
            if ismember('Annotation', app.ComponentList)
                datatypes = fieldnames(app.ACT.metric);
                datatypes(isfield(app.ACT.analysis.annotate, datatypes)) = [];
                exp = ['Annotation(', strjoin(titleCase(datatypes), '|'), ')_*'];
                unmountComponent(app, findobj(app.DataContainer.Children, '-regexp', 'Tag', exp))
            end
            % ---------------------------------------------------------
            % Check if any Gridlayout-Move and their buttons need to be deleted
            if ismember('SettingsPanel', app.ComponentList)
                Cmps = findobj(app.GridLayoutSettingsPanel.Children, '-regexp', 'Tag', 'GridLayoutMove_*');
                for ci = 1:length(Cmps)
                    if ~isfield(app.ACT.display, 'order')
                        unmountComponent(app, Cmps(ci))
                    elseif ~ismember(Cmps(ci).UserData.datatype, app.ACT.display.order)
                        unmountComponent(app, Cmps(ci))
                    end
                end
                % ---------------------------------------------------------
                % Check if any settings panels need to be deleted
                Cmps = findobj(app.GridLayoutSettingsPanel.Children, '-regexp', 'Tag', '^SettingsPanel_*');
                for ci = 1:length(Cmps)
                    if ~isfield(app.ACT.display, 'order')
                        unmountComponent(app, Cmps(ci))
                    elseif ~ismember(lower(Cmps(ci).Title), lower(app.ACT.display.order))
                        unmountComponent(app, Cmps(ci))
                    end
                end
                % ---------------------------------------------------------
                % Check if the annotation type panel needs to be deleted
                if isempty(fieldnames(app.ACT.analysis.annotate))
                    unmountComponent(app, findobj(app.CicadaUIFigure, 'Tag', 'AnnotationTypePanel'));
                end
                % ---------------------------------------------------------
                % Check if the sleep window type panel needs to be deleted
                if ~isfield(app.ACT.analysis.settings, 'sleepWindowType')
                    unmountComponent(app, findobj(app.CicadaUIFigure, 'Tag', 'SleepWindowTypePanel'));
                end
                % ---------------------------------------------------------
                % Check if the delete event panel needs to be deleted
                if isempty(app.ACT.analysis.events.type)
                    unmountComponent(app, findobj(app.CicadaUIFigure, 'Tag', 'DeleteEventsPanel'));
                end
            end
            % ---------------------------------------------------------
            % Check if the stats components need to be deleted
            if ismember('Stats', app.ComponentList)
                if ~isfield(app.ACT.stats, 'average')
                    unmountComponent(app, findobj(app.TabGroup, '-regexp', 'Tag', '^Tab_Custom*'));
                end
                if ~isfield(app.ACT.stats, 'daily')
                    unmountComponent(app, app.GridLayoutDailyStats_Container.Children);
                end
                if ~isfield(app.ACT.stats, 'sleep')
                    unmountComponent(app, findobj(app.TabGroup, 'Tag', 'Tab_CicSleep'));
                elseif isfield(app.ACT.stats, 'sleep') && ~isfield(app.ACT.stats.sleep, 'actigraphy')
                    unmountComponent(app, findobj(app.TabGroup, 'Tag', 'Tab_CicSleep'));
                end
            end
            % ---------------------------------------------------------
            % Check if the cursor and interval need to be deleted
            if ismember('Interval', app.ComponentList)
                if ~isfield(app.Interval, 'Position')
                    Cmps = findobj(app.DataContainer.Children, '-regexp', 'Tag', '^Interval*');
                    for ci = 1:length(Cmps)
                        unmountComponent(app, Cmps(ci))
                    end
                end
            end
            if ismember('Cursor', app.ComponentList)
                if ~isfield(app.Cursor, 'Position')
                    Cmps = findobj(app.DataContainer.Children, '-regexp', 'Tag', '^Cursor*');
                    for ci = 1:length(Cmps)
                        unmountComponent(app, Cmps(ci))
                    end
                end
            end
        end
        
        % ---------------------------------------------------------
        % Function call all required functions in the lifecycle in response to an event
        function lifecycle(app, ShouldShowWaitbar)
            % ---------------------------------------------------------
            % Show waitbar if required
            if ShouldShowWaitbar
                showWaitbar('Mapping data to component properties', -1, 'off');
            end
            % ---------------------------------------------------------
            % Map the current state the the properties of all components and update them if needed
            mapStateToProps(app);
            
            % ---------------------------------------------------------
            % Show waitbar if required
            if ShouldShowWaitbar
                showWaitbar('Updating components', -1, 'off');
            end
            
            % ---------------------------------------------------------
            % Check if each constructed component should update, and if so update it
            for ci = 1:size(app.Components, 1)
                if shouldComponentUpdate(app, app.Components{ci, 1}, app.Components{ci, 2})
                    if ShouldShowWaitbar
                        app.Waitbar.Value = ci/size(app.Components, 1);
                    end
                    updateComponent(app, app.Components{ci, 1}, app.Components{ci, 2})
                end
            end
            
            % ---------------------------------------------------------
            % Check if any dynamic component should be deleted
            unmountComponents(app)
            
            % ---------------------------------------------------------
            % Initialize an empty component list for the next round
            app.Components = {};
            app.ComponentList = {};
            
            % ---------------------------------------------------------
            % Close waitbar if required
            if ShouldShowWaitbar; closeWaitbar(); end
        end
    end
    
    methods (Access = private)
        
        function results = func13(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ---------------------------------------------------------
            % Check if Cicada is already running
            if numel(findall(groot, 'Name', 'Cicada')) > 1
                fprintf('Cicada is already running, please close current window before starting a new session.\n')
                sel = uiconfirm(app.CicadaUIFigure, 'Cicada is already running, please close current window before starting a new session.', 'Cannot have two Cicada''s buzzing at the same time', ...
                    'Options',{'Quit'},...
                    'DefaultOption', 'Quit', ...
                    'Icon', 'info');
                delete(app)
                return
            end
            
            % ---------------------------------------------------------
            % If all the support functions are not found, try to add Cicada to the path and otherwise ask the user to specify
            % the Cicada installation location
            if exist('cic_version') == 0
                cicDir = fileparts(which('cicada'));
                % If we know where Cicada is, we can add the package dir to the path
                if ~isempty(cicDir)
                    addpath(genpath(cicDir))
                else
                    sel = uiconfirm(app.CicadaUIFigure, 'Cicada is not added to the Matlab path. Please use the browse button to specify the path where ''Cicada.mlapp'' is located', 'Oops... The Cicada is lost.', ...
                        'Options',{'Browse'},...
                        'DefaultOption', 'Browse', ...
                        'Icon', 'question');
                    cicDir = uigetdir('.', 'Specify the path where ''Cicada.mlapp'' is located');
                    if cicDir == 0 % User pressed cancel, so quit
                        delete(app);
                        return
                    end
                    addpath(genpath(cicDir))
                end
            end
            
            % ---------------------------------------------------------
            % Center the GUI on screen
            screensize = get(0, 'ScreenSize');
            app.CicadaUIFigure.Position(1) = (screensize(3) - app.CicadaUIFigure.Position(3))/2;
            app.CicadaUIFigure.Position(2) = (screensize(4) - app.CicadaUIFigure.Position(4))/2;
            
            % ---------------------------------------------------------
            % Change the SizeChangedFcn of the UIFigure
            app.CicadaUIFigure.SizeChangedFcn = @app.SizeChanged;
            
            % ---------------------------------------------------------
            % Load the Cicada Logo
            app.UIAxesLogo.UserData.ImObj = imagesc(app.UIAxesLogo, 'CData', cicadaLogo());
            app.UIAxesLogo.YDir = 'reverse';
            app.UIAxesLogo.Toolbar = [];
            app.UIAxesLogo.Interactions = [];
            
            % ---------------------------------------------------------
            % Add Tags so it can be found by constructComponent
            app.GridLayoutSettingsPanel.Tag = 'GridLayoutSettingsPanel';
            app.ActogramSettingsPanel.Tag = 'ActogramSettingsPanel';
            
            % ---------------------------------------------------------
            % Add Tags to the view menu
            app.ShowActivityCountsMenu.Tag = 'counts';
            app.ShowEuclideanNormMenu.Tag = 'euclNorm';
            app.ShowAngleMenu.Tag = 'angle';
            
            % ---------------------------------------------------------
            % Add UserData to the Settings inputs
            app.ActogramSettingsLengthInput.UserData.type    = 'acceleration';
            app.ActogramSettingsLengthInput.UserData.input   = 'actogramLength';
            app.ActogramSettingsWidthInput.UserData.type     = 'acceleration';
            app.ActogramSettingsWidthInput.UserData.input    = 'actogramWidth';
            app.ActogramSettingsClockMinInput.UserData.type  = 'acceleration';
            app.ActogramSettingsClockMinInput.UserData.input = 'actogramStartClock';
            app.ActogramSettingsClockMaxInput.UserData.type  = 'acceleration';
            app.ActogramSettingsClockMaxInput.UserData.input = 'actogramEndClock';
            app.ActogramSettingsRowSpanInput.UserData.type   = 'acceleration';
            app.ActogramSettingsRowSpanInput.UserData.input  = 'rowspan';
            app.ActogramSettingsRangeMinInput.UserData.type  = 'acceleration';
            app.ActogramSettingsRangeMinInput.UserData.input = 'range';
            app.ActogramSettingsRangeMinInput.UserData.idx   = 1;
            app.ActogramSettingsRangeMaxInput.UserData.type  = 'acceleration';
            app.ActogramSettingsRangeMaxInput.UserData.input = 'range';
            app.ActogramSettingsRangeMaxInput.UserData.idx   = 2;
            app.ActogramSettingsLogInput.UserData.type       = 'acceleration';
            app.ActogramSettingsLogInput.UserData.input      = 'log';
            
            initialize(app);
            
        end

        % Menu selected function: OpenDatasetMenu
        function OpenDatasetMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Ask to save the Dataset
                result = app_askToSaveDataset(app, event);
                if ~result; return; end
                % ---------------------------------------------------------
                % User selects a file to open
                [fInName, fInPath] = uigetfile('*.mat', 'Select a dataset');
                drawnow;
                figure(app.CicadaUIFigure);
                if fInPath == 0; return; end % User pressed cancel, so return
                
                % Navigate to the data folder
                cd(fInPath);
                
                % Store the current data structure to revert back if needed.
                prevAct = app.ACT;
                
                % ---------------------------------------------------------
                % Initialize app
                initialize(app);
                
                % ---------------------------------------------------------
                % Load the file
                showWaitbar('Loading dataset', -1, 'off');
                try
                    app.ACT = cic_loadmat([fInPath, fInName]);
                catch ME % Loading the file has failed for some reason
                    app.ACT = prevAct;
                    clear prevAct;
                    app_showErrMsg(app, ME)
                    return
                end
                
                % Check if the loaded Dataset is valid, if not, show error and return
                [app.ACT, isValid, msg] = cic_checkDataset(app.ACT);
                if ~isValid
                    app.ACT = prevAct;
                    clear ACT prevAct;
                    closeWaitbar();
                    uiconfirm(app.CicadaUIFigure, sprintf(msg(3:end)), 'Error. Well, that is embarrassing...', ...
                        'Options',{'Ok'},...
                        'DefaultOption', 'Ok', ...
                        'Icon', 'error');
                    return
                end
                clear prevAct;
                
                % ---------------------------------------------------------
                % Store the default display values if they don't exist already
                app.ACT.display = softCopyStructure(app.ACT.display, app.Settings.display);
                app.ACT.display = getDefaultDisplaySettings(app.ACT.data, app.ACT.display);
                
                % ---------------------------------------------------------
                % If epoch length has not been set yet, do that now
                if isempty(app.ACT.epoch)
                    app.ACT.epoch = app.Settings.analysis.epochLength;
                end
                
                % ---------------------------------------------------------
                % If metrics have not been calculated yet, do that now
                if isempty(fieldnames(app.ACT.metric))
                    app.ACT = cic_calcEpochedMetrics(app.ACT, app.ACT.epoch); % epoch length in seconds
                end
                
                % ---------------------------------------------------------
                % How many days are in this recording? (only calculate if 'ACT.ndays' is empty)
                if isempty(app.ACT.ndays)
                    app.ACT = cic_getDays(app.ACT, app.ACT.display.actogramStartClock, app.ACT.display.actogramEndClock);
                end
                
                % ---------------------------------------------------------
                % Enable the menu items
                menuItems = fieldnames(app.Menu);
                for mi = 1:length(menuItems)
                    app.Menu.(menuItems{mi}).Enable = 'on';
                end
                if ~isfield(app.ACT.stats, 'average')
                    app.Menu.ExportAnalysisMenu.Enable = 'off';
                    app.Menu.ExportReportMenu.Enable = 'off';
                else
                    app.Menu.ExportAnalysisMenu.Enable = 'on';
                    app.Menu.ExportReportMenu.Enable = 'on';
                end
                if ~isfield(app.ACT.metric, 'light')
                    app.Menu.AnnotateLightMenu.Enable = 'off';
                else
                    app.Menu.AnnotateLightMenu.Enable = 'on';
                end
                % ---------------------------------------------------------
                % Initiate the default view
                metric = app.ACT.display.acceleration.view;
                app.ACT.display.acceleration.view = metric;
                app.ACT.display.acceleration.log = app.ACT.display.acceleration.(metric).log;
                app.ACT.display.acceleration.range = app.ACT.display.acceleration.(metric).range;
                app.ActogramSettingsRowSpanInput.Value = app.ACT.display.acceleration.rowspan;
                app.ActogramSettingsLengthInput.Value = num2str(app.ACT.display.actogramLength);
                app.ActogramSettingsWidthInput.Value = app.ACT.display.actogramWidth;
                app.ActogramSettingsRangeMinInput.Value = app.ACT.display.acceleration.range(1);
                app.ActogramSettingsRangeMaxInput.Value = app.ACT.display.acceleration.range(2);
                app.ActogramSettingsLogInput.Value = app.ACT.display.acceleration.log;
                app.Cursor = struct();
                app.Interval = struct();
                
                switch metric
                    case 'counts'
                        app.Menu.ShowActivityCountsMenu.Enable = 'off';
                        app.Menu.ShowActivityCountsMenu.Checked = 'on';
                        app.Menu.ShowEuclideanNormMenu.Enable = 'on';
                        app.Menu.ShowEuclideanNormMenu.Checked = 'off';
                        app.Menu.ShowAngleMenu.Enable = 'on';
                        app.Menu.ShowAngleMenu.Checked = 'off';
                    case 'euclNorm'
                        app.Menu.ShowActivityCountsMenu.Enable = 'on';
                        app.Menu.ShowActivityCountsMenu.Checked = 'off';
                        app.Menu.ShowEuclideanNormMenu.Enable = 'off';
                        app.Menu.ShowEuclideanNormMenu.Checked = 'on';
                        app.Menu.ShowAngleMenu.Enable = 'on';
                        app.Menu.ShowAngleMenu.Checked = 'off';
                    case 'angle'
                        app.Menu.ShowActivityCountsMenu.Enable = 'on';
                        app.Menu.ShowActivityCountsMenu.Checked = 'off';
                        app.Menu.ShowEuclideanNormMenu.Enable = 'on';
                        app.Menu.ShowEuclideanNormMenu.Checked = 'off';
                        app.Menu.ShowAngleMenu.Enable = 'off';
                        app.Menu.ShowAngleMenu.Checked = 'on';
                end
                
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'Menu', 'SettingsPanel', 'DataContainer', 'Panel', 'Axes', 'Events', 'Acceleration', 'Annotation', 'Data', 'Stats', 'Cursor', 'Interval'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Ask to save the Dataset
                result = app_askToSaveDataset(app, event);
                if ~result; return; end
                % ---------------------------------------------------------
                % Initialize a new empty state
                initialize(app)
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'Menu', 'SettingsPanel', 'DataContainer', 'Stats'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: SaveDatasetMenu
        function SaveDatasetMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % If the output path has not been set yet, ask for the location
                if isempty(app.ACT.filepath)
                    [fOutName, fOutPath] = uiputfile(fullfile('.', [app.ACT.filename, '.mat']), 'Save dataset to...');
                    if fOutPath == 0; return; end % User pressed cancel, so return
                    
                    % Navigate to the data folder
                    cd(fOutPath);
                    
                    % Store the filename and path
                    [fpath, fname] = fileparts([fOutPath, fOutName]);
                    app.ACT.filename = fname;
                    app.ACT.filepath = fpath;
                end
                
                % ---------------------------------------------------------
                % Save the file
                showWaitbar('Saving dataset', -1, 'off');
                try
                    app.ACT = cic_savemat(app.ACT, fullfile(app.ACT.filepath, [app.ACT.filename, '.mat']));
                catch ME % Loading the file has failed for some reason
                    app.ACT.saved = false;
                    closeWaitbar();
                    uiconfirm(app.CicadaUIFigure, ME.message, 'Error', ...
                        'Options',{'Ok'},...
                        'DefaultOption', 'Ok', ...
                        'Icon', 'error');
                    return
                end
                
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: SaveDatasetAsMenu
        function SaveDatasetAsMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Ask for the location
                [fOutName, fOutPath] = uiputfile(fullfile('.', [app.ACT.filename, '.mat']), 'Save dataset to...');
                if fOutPath == 0; return; end % User pressed cancel, so return
                
                % Navigate to the data folder
                cd(fOutPath);
                
                % Store the filename and path
                [fpath, fname] = fileparts([fOutPath, fOutName]);
                app.ACT.filename = fname;
                app.ACT.filepath = fpath;
                
                % ---------------------------------------------------------
                % Save the file
                showWaitbar('Saving dataset', -1, 'off');
                try
                    app.ACT = cic_savemat(app.ACT, fullfile(app.ACT.filepath, [app.ACT.filename, '.mat']));
                catch ME % Loading the file has failed for some reason
                    app.ACT.saved = false;
                    closeWaitbar();
                    uiconfirm(app.CicadaUIFigure, ME.message, 'Error', ...
                        'Options',{'Ok'},...
                        'DefaultOption', 'Ok', ...
                        'Icon', 'error');
                    return
                end
                
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: ImportGeneActivMenu
        function ImportGeneActivMenuSelected(app, event)
            try 
                % ---------------------------------------------------------
                % Ask to save the Dataset
                result = app_askToSaveDataset(app, event);
                if ~result; return; end
                % ---------------------------------------------------------
                % User selects an input file to load as a .bin file
                [fInName, fInPath] = uigetfile('*.bin', 'Select data file');
                if fInPath == 0; return; end % User pressed cancel, so return
                % Navigate to the data folder
                cd(fInPath);
                % Store the current data structure to revert back if needed.
                prevAct = app.ACT;
                % ---------------------------------------------------------
                % Load the .bin file
                app.ACT = cic_importGeneActivBin([fInPath, fInName]);
                
                % If there is no data, user pressed cancel or something went wrong
                if ~isfield(app.ACT.data, 'acceleration')
                    abort = true;
                elseif ~isfield(app.ACT.data.acceleration, 'x')
                    abort = true;
                elseif isempty(app.ACT.data.acceleration.x)
                    abort = true;
                else
                    abort = false;
                end
                if abort
                    app.ACT = prevAct;
                    clear prevAct
                    closeWaitbar();
                    return
                end
                clear prevAct
                % ---------------------------------------------------------
                % Process imported data
                showWaitbar('Processing imported data', -1, 'off');
                % Store the default display values if they don't exist already
                app.ACT.display = softCopyStructure(app.ACT.display, app.Settings.display);
                app.ACT.display = getDefaultDisplaySettings(app.ACT.data, app.ACT.display);
                % ---------------------------------------------------------
                % Extract all metrics from the raw data in epochs of default length
                app.ACT = cic_calcEpochedMetrics(app.ACT, app.Settings.analysis.epochLength);
                % ---------------------------------------------------------
                % How many days are in this recording?
                app.ACT = cic_getDays(app.ACT, app.ACT.display.actogramStartClock, app.ACT.display.actogramEndClock);
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Enable the menu items
                menuItems = fieldnames(app.Menu);
                for mi = 1:length(menuItems)
                    app.Menu.(menuItems{mi}).Enable = 'on';
                end
                if ~isfield(app.ACT.metric, 'light')
                    app.Menu.AnnotateLightMenu.Enable = 'off';
                else
                    app.Menu.AnnotateLightMenu.Enable = 'on';
                end
                app.Menu.ExportAnalysisMenu.Enable = 'off';
                app.Menu.ExportReportMenu.Enable = 'off';
                % ---------------------------------------------------------
                % Initiate the default view
                metric = app.ACT.display.acceleration.view;
                app.ACT.display.acceleration.view = metric;
                app.ACT.display.acceleration.log = app.ACT.display.acceleration.(metric).log;
                app.ACT.display.acceleration.range = app.ACT.display.acceleration.(metric).range;
                app.ActogramSettingsRowSpanInput.Value = app.ACT.display.acceleration.rowspan;
                app.ActogramSettingsLengthInput.Value = num2str(app.ACT.display.actogramLength);
                app.ActogramSettingsWidthInput.Value = app.ACT.display.actogramWidth;
                app.ActogramSettingsRangeMinInput.Value = app.ACT.display.acceleration.range(1);
                app.ActogramSettingsRangeMaxInput.Value = app.ACT.display.acceleration.range(2);
                app.ActogramSettingsLogInput.Value = app.ACT.display.acceleration.log;
                app.Cursor = struct();
                app.Interval = struct();
                switch metric
                    case 'counts'
                        app.Menu.ShowActivityCountsMenu.Enable = 'off';
                        app.Menu.ShowActivityCountsMenu.Checked = 'on';
                        app.Menu.ShowEuclideanNormMenu.Enable = 'on';
                        app.Menu.ShowEuclideanNormMenu.Checked = 'off';
                        app.Menu.ShowAngleMenu.Enable = 'on';
                        app.Menu.ShowAngleMenu.Checked = 'off';
                    case 'euclNorm'
                        app.Menu.ShowActivityCountsMenu.Enable = 'on';
                        app.Menu.ShowActivityCountsMenu.Checked = 'off';
                        app.Menu.ShowEuclideanNormMenu.Enable = 'off';
                        app.Menu.ShowEuclideanNormMenu.Checked = 'on';
                        app.Menu.ShowAngleMenu.Enable = 'on';
                        app.Menu.ShowAngleMenu.Checked = 'off';
                    case 'angle'
                        app.Menu.ShowActivityCountsMenu.Enable = 'on';
                        app.Menu.ShowActivityCountsMenu.Checked = 'off';
                        app.Menu.ShowEuclideanNormMenu.Enable = 'on';
                        app.Menu.ShowEuclideanNormMenu.Checked = 'off';
                        app.Menu.ShowAngleMenu.Enable = 'off';
                        app.Menu.ShowAngleMenu.Checked = 'on';
                end
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'Menu', 'SettingsPanel', 'DataContainer', 'Panel', 'Axes', 'Events', 'Acceleration', 'Annotation', 'Data', 'Stats', 'Cursor', 'Interval'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: ImportLightPinMenu
        function ImportLightPinMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Check if WIMR Light Pin data already exists and if so ask to overwrite or not
                if isfield(app.ACT.data.light, 'MelanopicLux')
                    sel = uiconfirm(app.CicadaUIFigure, {'Light Pin data is already imported and will be overwritten if you import another datafile.', 'Do you want to continue?'}, 'Overwrite current data?', ...
                        'Options',{'No, cancel', 'Yes, overwrite'},...
                        'DefaultOption', 'No, cancel', ...
                        'CancelOption', 'No, cancel', ...
                        'Icon', 'question');
                    % ---------------------------------------------------------
                    % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                    if strcmpi(sel, 'No, cancel')
                        return
                    end
                end
                % ---------------------------------------------------------
                % User selects an input file to load as a .bin file
                [fInName, fInPath] = uigetfile('*.csv', 'Select data file');
                if fInPath == 0; return; end % User pressed cancel, so return
                % ---------------------------------------------------------
                % Check that the statistics functions have not run yet, otherwise we have to
                % undo any statistics steps before continueing
                if any(ismember(app.ACT.pipe, {'statistics'}))
                    cancelAction = app_askToUndoStatistics(app);
                    if cancelAction
                        return
                    end
                    lifecycle(app, true)
                end
                % ---------------------------------------------------------
                % Import the .csv file
                showWaitbar('Reading .csv file', -1, 'off');
                [app.ACT, err, warn, msg] = cic_importWIMRLightPin(app.ACT, [fInPath, fInName]);
                if err
                    uiconfirm(app.CicadaUIFigure, msg, 'Error', ...
                        'Options',{'Ok'},...
                        'DefaultOption', 'Ok', ...
                        'Icon', 'error');
                    return
                end
                if warn
                    uiconfirm(app.CicadaUIFigure, msg, 'Warning', ...
                        'Options',{'Ok'},...
                        'DefaultOption', 'Ok', ...
                        'Icon', 'warning');
                end
                % ---------------------------------------------------------
                % Process imported data
                showWaitbar('Processing imported data', -1, 'off');
                % Store the default display values if they don't exist already
                app.ACT.display = updateDisplaySettings(app.ACT.data, app.ACT.display);
                % ---------------------------------------------------------
                % Extract all metrics from the raw data in epochs of default length
                app.ACT = cic_calcEpochedMetrics(app.ACT, app.ACT.epoch);
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Enable the menu items
                app.Menu.AnnotateLightMenu.Enable = 'on';
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'Menu', 'SettingsPanel', 'DataContainer', 'Panel', 'Axes', 'Events', 'Acceleration', 'Annotation', 'Data', 'Stats', 'Cursor', 'Interval'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: ImportSleepDiaryMenu
        function ImportSleepDiaryMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Call the User Interface to load the diary data
                app.importSleepDiaryApp = importSleepDiary(app);
                % Wait for the response
                if ~isvalid(app.importSleepDiaryApp)
                    app.Import = struct();
                    return
                end
                uiwait(app.importSleepDiaryApp.ImportSleepDiaryUIFigure);
                if ~isfield(app.Import, 'Return')
                    app.Import = struct();
                    return
                elseif ~app.Import.Return
                    app.Import = struct();
                    return
                end
                % ---------------------------------------------------------
                % Check if sleep diary already exists and if so ask to overwrite or not
                if any(strcmpi(app.ACT.analysis.events.type, 'sleepDiary'))
                    sel = uiconfirm(app.CicadaUIFigure, {'A sleep diary is already imported and will be overwritten if you import another one.', 'Do you want to continue?'}, 'Overwrite current data?', ...
                        'Options',{'No, cancel', 'Yes, overwrite'},...
                        'DefaultOption', 'No, cancel', ...
                        'CancelOption', 'No, cancel', ...
                        'Icon', 'question');
                    % ---------------------------------------------------------
                    % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                    if strcmpi(sel, 'No, cancel')
                        return
                    end
                end
                % ---------------------------------------------------------
                % Check that the statistics functions have not run yet, otherwise we have to
                % undo any statistics steps before continueing
                if any(ismember(app.ACT.pipe, {'statistics'}))
                    cancelAction = app_askToUndoStatistics(app);
                    if cancelAction
                        return
                    end
                    lifecycle(app, true)
                end
                % ---------------------------------------------------------
                % Import the sleep diary events
                showWaitbar('Calculating events from sleep diary', -1, 'off');
                app.ACT = cic_diarySleepEvents(app.ACT);
                app.ACT = cic_actigraphySleepEvents(app.ACT);
                % Clear the import structure
                app.Import = struct();
                % ---------------------------------------------------------
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'SettingsPanel', 'Events'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: ExportAnalysisMenu
        function ExportStatsMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Ask for the location
                [fOutName, fOutPath] = uiputfile(fullfile('.', [app.ACT.filename, '.csv']), 'Export statistics to...');
                if fOutPath == 0; return; end % User pressed cancel, so return
                % Extract the path and filename without extension, so we can add it hardcoded
                [fOutPath, fOutName] = fileparts(fullfile(fOutPath, fOutName));
                % ---------------------------------------------------------
                % Write the statistics to file
                app.ACT = cic_exportStatistics(app.ACT, [fOutPath, filesep, fOutName]);
                % ---------------------------------------------------------
                % Notify the User it's done
                uiconfirm(app.CicadaUIFigure, {'Statistics succefully written to files'}, 'Completed', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'success');
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: ExportReportMenu
        function ExportReportMenuSelected(app, event)
            uiconfirm(app.CicadaUIFigure, {'Sorry, this part of Cicada has not been developed yet.'}, 'Notification', ...
                'Options',{'Ok'},...
                'DefaultOption', 'Ok', ...
                'Icon', 'info');
        end

        % Menu selected function: ExportCodeMenu
        function ExportCodeMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Ask for the location
                [fOutName, fOutPath] = uiputfile(fullfile('.', [app.ACT.filename, '.m']), 'Save matlab script to...');
                if fOutPath == 0; return; end % User pressed cancel, so return
                % Extract the path and filename without extension, so we can add it hardcoded
                [fOutPath, fOutName] = fileparts(fullfile(fOutPath, fOutName));
                % ---------------------------------------------------------
                % Write the history to file
                app.ACT = cic_writeHistory(app.ACT, [fOutPath, filesep, fOutName, '.m']);
                % ---------------------------------------------------------
                % Notify the User it's done
                uiconfirm(app.CicadaUIFigure, {'Code succefully written to file:', [fOutName, '.m']}, 'Completed', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'success');
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Ask to save the Dataset
                result = app_askToSaveDataset(app, event);
                if ~result; return; end
                delete(app)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: DatasetInfoMenu
        function DatasetInfoMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Call the User Interface to change the information data
                app.editDatasetInfoApp = editDatasetInfo(app);
                % Wait for the response
                uiwait(app.editDatasetInfoApp.EditDatasetInfoUIFigure);
                if ~isfield(app.Import, 'Return')
                    app.Import = struct();
                    return
                elseif ~app.Import.Return
                    app.Import = struct();
                    return
                end
                % ---------------------------------------------------------
                % Update the information structure
                app.ACT = cic_editInformation(app.ACT, app.Import.Data);
                % Clear the import structure
                app.Import = struct();
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info'}];
                lifecycle(app, false)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: SelectDataMenu
        function SelectDataMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Call the User Interface to select a subset of data
                app.selectDataApp = selectData(app);
                % Wait for the response
                uiwait(app.selectDataApp.SelectDataUIFigure);
                if ~isfield(app.Import, 'Return')
                    app.Import = struct();
                    return
                elseif ~app.Import.Return
                    app.Import = struct();
                    return
                end
                % ---------------------------------------------------------
                % Check that the analysis functions have not run yet, otherwise we have to
                % undo any analysis steps before continueing
                if any(ismember(app.ACT.pipe, {'analysis', 'statistics'}))
                    cancelAction = app_askToUndoAnalysis(app);
                    if cancelAction
                        return
                    end
                    lifecycle(app, true)
                end
                % ---------------------------------------------------------
                % Select the data
                showWaitbar('Selecting data', -1, 'off');
                % Select data based on new start and end date
                disp(datestr(app.Import.Data(1)))
                app.ACT = cic_selectDatasetUsingTime(app.ACT, app.Import.Data(1), app.Import.Data(2));
                % ---------------------------------------------------------
                % Get the number of days in this recording
                app.ACT = cic_getDays(app.ACT, datestr(app.ACT.startdate, 'HH:MM'), datestr(app.ACT.enddate, 'HH:MM'));
                % Clear the import structure
                app.Import = struct();
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'DataContainer', 'Panel', 'Axes', 'Events', 'Acceleration', 'Annotation', 'Data', 'Cursor', 'Interval'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: ChangeTimeZoneMenu
        function ChangeTimeZoneMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Call the User Interface to change the time zone
                app.changeTimeZoneApp = changeTimeZone(app);
                % Wait for the response
                uiwait(app.changeTimeZoneApp.ChangeTimeZoneUIFigure);
                if ~isfield(app.Import, 'Return')
                    app.Import = struct();
                    return
                elseif ~app.Import.Return
                    app.Import = struct();
                    return
                end
                % ---------------------------------------------------------
                % Check that the statistics functions have not run yet, otherwise we have to
                % undo any statistics steps before continueing
                if any(ismember(app.ACT.pipe, {'statistics'}))
                    cancelAction = app_askToUndoStatistics(app);
                    if cancelAction
                        return
                    end
                    lifecycle(app, true)
                end
                % ---------------------------------------------------------
                % Change the time zone
                showWaitbar('Changing time zone', -1, 'off');
                app.ACT = cic_changeTimeZone(app.ACT, app.Import.Data.timezone);
                % ---------------------------------------------------------
                % Get the number of days in this recording
                app.ACT = cic_getDays(app.ACT, datestr(app.ACT.startdate, 'HH:MM'), datestr(app.ACT.enddate, 'HH:MM'));
                % Clear the import structure
                app.Import = struct();
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'DataContainer', 'Panel', 'Axes', 'Events', 'Acceleration', 'Annotation', 'Data', 'Cursor', 'Interval'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: ChangeEpochLengthMenu
        function ChangeEpochLengthMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Call the User Interface to change the epoch length
                app.changeEpochLengthApp = changeEpochLength(app);
                % Wait for the response
                uiwait(app.changeEpochLengthApp.ChangeEpochLengthUIFigure);
                if ~isfield(app.Import, 'Return')
                    app.Import = struct();
                    return
                elseif ~app.Import.Return
                    app.Import = struct();
                    return
                end
                % If the user selected the same epoch as it already was, do nothing and return
                if app.ACT.epoch == app.Import.Data.epoch; return; end
                % ---------------------------------------------------------
                % Check that the analysis has not run yet, otherwise we have to
                % undo any analysis steps before continueing
                if any(ismember(app.ACT.pipe, {'analysis', 'statistics'}))
                    cancelAction = app_askToUndoAnalysis(app);
                    if cancelAction
                        return
                    end
                end
                % ---------------------------------------------------------
                % Change the epoch length
                showWaitbar('Changing epoch length', -1, 'off');
                app.ACT = cic_calcEpochedMetrics(app.ACT, app.Import.Data.epoch);
                % Clear the import structure
                app.Import = struct();
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'Acceleration', 'Data'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: ShowActivityCountsMenu, 
        % ShowAngleMenu, ShowEuclideanNormMenu
        function ViewMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Update the view
                metric = event.Source.Tag;
                app.ACT.display.acceleration.view = metric;
                app.ACT.display.acceleration.log = app.ACT.display.acceleration.(metric).log;
                app.ACT.display.acceleration.range = app.ACT.display.acceleration.(metric).range;
                app.ActogramSettingsLogInput.Value = app.ACT.display.acceleration.log;
                app.ActogramSettingsRangeMinInput.Value = app.ACT.display.acceleration.range(1);
                app.ActogramSettingsRangeMaxInput.Value = app.ACT.display.acceleration.range(2);
                % ---------------------------------------------------------
                % Update menu items
                switch metric
                    case 'counts'
                        app.Menu.ShowActivityCountsMenu.Enable = 'off';
                        app.Menu.ShowActivityCountsMenu.Checked = 'on';
                        app.Menu.ShowEuclideanNormMenu.Enable = 'on';
                        app.Menu.ShowEuclideanNormMenu.Checked = 'off';
                        app.Menu.ShowAngleMenu.Enable = 'on';
                        app.Menu.ShowAngleMenu.Checked = 'off';
                    case 'euclNorm'
                        app.Menu.ShowActivityCountsMenu.Enable = 'on';
                        app.Menu.ShowActivityCountsMenu.Checked = 'off';
                        app.Menu.ShowEuclideanNormMenu.Enable = 'off';
                        app.Menu.ShowEuclideanNormMenu.Checked = 'on';
                        app.Menu.ShowAngleMenu.Enable = 'on';
                        app.Menu.ShowAngleMenu.Checked = 'off';
                    case 'angle'
                        app.Menu.ShowActivityCountsMenu.Enable = 'on';
                        app.Menu.ShowActivityCountsMenu.Checked = 'off';
                        app.Menu.ShowEuclideanNormMenu.Enable = 'on';
                        app.Menu.ShowEuclideanNormMenu.Checked = 'off';
                        app.Menu.ShowAngleMenu.Enable = 'off';
                        app.Menu.ShowAngleMenu.Checked = 'on';
                end
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Menu', 'Axes', 'Acceleration', 'Annotation'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: GGIRAutomaticCalibrationMenu
        function GGIRAutomaticCalibrationMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Check that the analysis has not run yet, otherwise we have to
                % undo any analysis steps before continueing
                if any(ismember(app.ACT.pipe, {'analysis', 'statistics'}))
                    cancelAction = app_askToUndoAnalysis(app);
                    if cancelAction
                        return
                    end
                end
                % ---------------------------------------------------------
                % Calibrate the data using Zhou Fang's algrotihm
                showWaitbar('Calibrating data', -1, 'off');
                app.ACT = cic_ggirAutomaticCalibration(app.ACT);
                % Extract all metrics from the raw data in epochs of length 'ACT.epoch'
                showWaitbar('Calculating metrics', -1, 'off');
                app.ACT = cic_calcEpochedMetrics(app.ACT, app.ACT.epoch);
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'Acceleration'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: GGIRNonWearDetectionMenu
        function GGIRNonWearDetectionMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Check that the analysis has not run yet, otherwise we have to
                % undo any analysis steps before continueing
                if any(ismember(app.ACT.pipe, {'analysis', 'statistics'}))
                    cancelAction = app_askToUndoAnalysis(app);
                    if cancelAction
                        return
                    end
                end
                % ---------------------------------------------------------
                % Run GGIR's automatic non-wear detection algorithm
                showWaitbar('Running GGIR''s non-wear detection algorithm', -1, 'off');
                [app.ACT, err] = cic_ggirDetectNonWear(app.ACT);
                if ~isempty(err)
                    uiconfirm(app.CicadaUIFigure, err, 'Error', ...
                        'Options',{'Ok'},...
                        'DefaultOption', 'Ok', ...
                        'Icon', 'error');
                    return
                end
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'Events', 'SettingsPanel'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: AnnotateAccelerationGGIRMenu
        function AnnotateAccelerationGGIRMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Call the User Interface to set the parameters for annotation
                app.annotateActivityGGIRApp = annotateActivityGGIR(app);
                % Wait for the response
                uiwait(app.annotateActivityGGIRApp.annotateActivityGGIRUIFigure);
                if ~isfield(app.Import, 'Return')
                    app.Import = struct();
                    return
                elseif ~app.Import.Return
                    app.Import = struct();
                    return
                end
                % ---------------------------------------------------------
                % Check that the statistics functions have not run yet, otherwise we have to
                % undo any statistics steps before continueing
                if any(ismember(app.ACT.pipe, {'statistics'}))
                    cancelAction = app_askToUndoStatistics(app);
                    if cancelAction
                        return
                    end
                    lifecycle(app, true)
                end
                % ---------------------------------------------------------
                % Annotate the data
                showWaitbar('Annotating activity data', -1, 'off');
                app.ACT = cic_annotateActivityGGIR(app.ACT, app.Import.Data);
                app.ACT = cic_actigraphySleepEvents(app.ACT);
                % Clear the import structure
                app.Import = struct();
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'Events', 'SettingsPanel', 'Annotation'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: AnnotateLightMenu
        function AnnotateLightMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Call the User Interface to set the parameters for annotation
                app.annotateLightApp = annotateLight(app);
                % Wait for the response
                uiwait(app.annotateLightApp.annotateLightUIFigure);
                if ~isfield(app.Import, 'Return')
                    app.Import = struct();
                    return
                elseif ~app.Import.Return
                    app.Import = struct();
                    return
                end
                % ---------------------------------------------------------
                % Check that the statistics functions have not run yet, otherwise we have to
                % undo any statistics steps before continueing
                if any(ismember(app.ACT.pipe, {'statistics'}))
                    cancelAction = app_askToUndoStatistics(app);
                    if cancelAction
                        return
                    end
                    lifecycle(app, true)
                end
                % ---------------------------------------------------------
                % Annotate the data
                showWaitbar('Annotating light data', -1, 'off');
                app.ACT = cic_annotateLight(app.ACT, app.Import.Data);
                % Clear the import structure
                app.Import = struct();
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'SettingsPanel', 'Annotation'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: CreateDailyEventsMenu
        function CreateDailyEventsMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Call the User Interface to create new events
                app.createDailyEventApp = createDailyEvent(app);
                % Wait for the response
                uiwait(app.createDailyEventApp.CreateDailyEventUIFigure);
                if ~isfield(app.Import, 'Return')
                    app.Import = struct();
                    return
                elseif ~app.Import.Return
                    app.Import = struct();
                    return
                end
                % ---------------------------------------------------------
                % If the event label already exist, confirm that the user wants to add these new events to that set
                if ismember(app.Import.Data.label, app.ACT.analysis.events.label)
                    sel = uiconfirm(app.CicadaUIFigure, {sprintf('You''re about to add events labeled ''%s'', but some events with this label already exist.', app.Import.Data.label), 'Do you want to add the new events to the existing set?'}, 'Add new events to existing set?', ...
                        'Options', {'No, cancel', 'Yes, add'},...
                        'DefaultOption', 'No, cancel', ...
                        'CancelOption', 'No, cancel', ...
                        'Icon', 'question');
                    % ---------------------------------------------------------
                    % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                    if strcmpi(sel, 'No, cancel')
                        app.Import = struct();
                        return
                    end
                end
                % ---------------------------------------------------------
                % Check that the statistics functions have not run yet, otherwise we have to
                % undo any statistics steps before continueing
                if any(ismember(app.ACT.pipe, {'statistics'}))
                    cancelAction = app_askToUndoStatistics(app);
                    if cancelAction
                        return
                    end
                    lifecycle(app, true)
                end
                % ---------------------------------------------------------
                % Create new event
                showWaitbar('Creating event', -1, 'off');
                app.ACT = cic_createDailyEvent(app.ACT, app.Import.Data.onset, app.Import.Data.duration, app.Import.Data.label, app.Import.Data.days);
                % Clear the import structure
                app.Import = struct();
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'Events', 'SettingsPanel'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: CreateRelativeEventsMenu
        function CreateRelativeEventsMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Call the User Interface to create new events
                app.createRelativeEventApp = createRelativeEvent(app);
                % Wait for the response
                uiwait(app.createRelativeEventApp.CreateRelativeEventUIFigure);
                if ~isfield(app.Import, 'Return')
                    app.Import = struct();
                    return
                elseif ~app.Import.Return
                    app.Import = struct();
                    return
                end
                % ---------------------------------------------------------
                % If the event label already exist, confirm that the user wants to add these new events to that set
                if ismember(app.Import.Data.label, app.ACT.analysis.events.label)
                    sel = uiconfirm(app.CicadaUIFigure, {sprintf('You''re about to add events labeled ''%s'', but some events with this label already exist.', app.Import.Data.label), 'Do you want to add the new events to the existing set?'}, 'Add new events to existing set?', ...
                        'Options', {'No, cancel', 'Yes, add'},...
                        'DefaultOption', 'No, cancel', ...
                        'CancelOption', 'No, cancel', ...
                        'Icon', 'question');
                    % ---------------------------------------------------------
                    % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                    if strcmpi(sel, 'No, cancel')
                        app.Import = struct();
                        return
                    end
                end
                % ---------------------------------------------------------
                % Check that the statistics functions have not run yet, otherwise we have to
                % undo any statistics steps before continueing
                if any(ismember(app.ACT.pipe, {'statistics'}))
                    cancelAction = app_askToUndoStatistics(app);
                    if cancelAction
                        return
                    end
                    lifecycle(app, true)
                end
                % ---------------------------------------------------------
                % Create new event
                showWaitbar('Creating event', -1, 'off');
                app.ACT = cic_createRelativeEvent(app.ACT, ...
                    app.Import.Data.ref, ...
                    app.Import.Data.refLabel, ...
                    app.Import.Data.refType, ...
                    app.Import.Data.delay, ...
                    app.Import.Data.duration, ...
                    app.Import.Data.newLabel);
                % Clear the import structure
                app.Import = struct();
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'Events', 'SettingsPanel'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Callback function: AvStats_RegenStatsButton, 
        % DailyStats_RegenStatsButton, GenerateStatisticsMenu
        function RegenStatsButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Prevent double clicking
                event.Source.Enable = 'off'; drawnow;
                % Delete the daily stats panel in case any new stats have been generated
                delete(findobj(app.GridLayoutDailyStats_Container.Children, 'Tag', 'DailyStats_Panel'))
                % ---------------------------------------------------------
                % Re-analyse the data
                showWaitbar('Generating statistics', -1, 'off');
                event.Source.Enable = 'on'; drawnow;
                app.ACT = cic_statistics(app.ACT);
                % Run the analysis on custom events
                labels = unique(app.ACT.analysis.events.label(strcmpi(app.ACT.analysis.events.type, 'customEvent')));
                if ~isempty(labels)
                    for li = 1:length(labels)
                        app.ACT = cic_statistics(app.ACT, 'customEvent', labels{li});
                    end
                end
                % Set the menu items
                app.Menu.ExportAnalysisMenu.Enable = 'on';
                app.Menu.ExportReportMenu.Enable = 'on';
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'Menu', 'Stats'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: GGIRSleepDetectionMenu
        function GGIRSleepDetectionMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Check if GGIR sleep windows already exists and if so ask to overwrite or not
                if any(strcmpi(app.ACT.analysis.events.label, 'sleepWindow') & strcmpi(app.ACT.analysis.events.type, 'GGIR'))
                    sel = uiconfirm(app.CicadaUIFigure, {'The GGIR sleep detection algorithm is already run and will be overwritten if you continue.', 'Do you want to continue?'}, 'Overwrite current GGIR sleep detection results?', ...
                        'Options', {'No, cancel', 'Yes, overwrite'},...
                        'DefaultOption', 'No, cancel', ...
                        'CancelOption', 'No, cancel', ...
                        'Icon', 'question');
                    % ---------------------------------------------------------
                    % If the user pressed 'Cancel' or 'Esc', return to stop the current process
                    if strcmpi(sel, 'No, cancel')
                        return
                    end
                end
                % ---------------------------------------------------------
                % Check that the statistics functions have not run yet, otherwise we have to
                % undo any statistics steps before continueing
                if any(ismember(app.ACT.pipe, {'statistics'}))
                    cancelAction = app_askToUndoStatistics(app);
                    if cancelAction
                        return
                    end
                    lifecycle(app, true)
                end
                % ---------------------------------------------------------
                % Run the sleep detection algorithm
                showWaitbar('Running GGIR''s sleep window detection algorithm', -1, 'off');
                app.ACT = cic_ggirSleepPeriodDetection(app.ACT);
                app.ACT = cic_actigraphySleepEvents(app.ACT);
                % Show default tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info', 'SettingsPanel', 'Events'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: RepositionpanelsMenu
        function RepositionpanelsMenuSelected(app, event)
            try
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'DataContainer', 'Panel', 'Axes', 'Acceleration'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about(app);
        end

        % Menu selected function: VisittheCicadaMenu
        function VisittheCicadaMenuSelected(app, event)
            web('https://cicada-actigraphy-suite.readthedocs.io', '-browser')
        end

        % Value changed function: ActogramSettingsClockMaxInput, 
        % ActogramSettingsClockMinInput, 
        % ActogramSettingsLengthInput, ActogramSettingsLogInput, 
        % ActogramSettingsRangeMaxInput, 
        % ActogramSettingsRangeMinInput, 
        % ActogramSettingsRowSpanInput, ActogramSettingsWidthInput
        function ChangePreferences(app, event)
            % ---------------------------------------------------------
            % Call the app's event listener to update the state
            EventListener(app, event.Source, event)
        end

        % Button pushed function: CancelSettingsButton
        function CancelSettingsButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Disable the buttons
                app.ApplySettingsButton.Enable = 'off';
                app.CancelSettingsButton.Enable = 'off';
                % ---------------------------------------------------------
                % Reset the values of all the settings inputs
                if isfield(app.ACT, 'urdisplay')
                    app.ACT.display = app.ACT.urdisplay;
                    app.ACT = rmfield(app.ACT, 'urdisplay');
                end
                if isfield(app.ACT.analysis, 'ursettings')
                    app.ACT.analysis.settings = app.ACT.analysis.ursettings;
                    app.ACT.analysis = rmfield(app.ACT.analysis, 'ursettings');
                end
                app.ActogramSettingsRowSpanInput.Value  = app.ACT.display.acceleration.rowspan;
                app.ActogramSettingsLengthInput.Value   = num2str(app.ACT.display.actogramLength);
                app.ActogramSettingsWidthInput.Value    = app.ACT.display.actogramWidth;
                app.ActogramSettingsClockMinInput.Value = app.ACT.display.actogramStartClock;
                app.ActogramSettingsClockMaxInput.Value = app.ACT.display.actogramEndClock;
                app.ActogramSettingsRangeMinInput.Value = app.ACT.display.acceleration.range(1);
                app.ActogramSettingsRangeMaxInput.Value = app.ACT.display.acceleration.range(2);
                app.ActogramSettingsLogInput.Value      = app.ACT.display.acceleration.log;
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'SettingsPanel'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Button pushed function: ApplySettingsButton
        function ApplySettingsButtonPushed(app, event)
            try
                % ---------------------------------------------------------
                % Disable the buttons
                app.ApplySettingsButton.Enable = 'off';
                app.CancelSettingsButton.Enable = 'off';
                % ---------------------------------------------------------
                % Re-do some additional steps if required
                if isfield(app.ApplySettingsButton.UserData, 'redo')
                    % -----
                    % Check that the statistics functions have not run yet, otherwise we have to
                    % undo any statistics steps before continueing
                    if isfield(app.ACT.stats, 'average') && any(ismember(app.ACT.pipe, {'statistics'}))
                        cancelAction = app_askToUndoStatistics(app);
                        if cancelAction
                            CancelSettingsButtonPushed(app, event)
                            return
                        end
                    end
                    % -----
                    % Recalculate the number of days
                    if app.ApplySettingsButton.UserData.redo.ndays
                        app.ACT = cic_getDays(app.ACT, app.ACT.display.actogramStartClock, app.ACT.display.actogramEndClock);
                    end
                    % -----
                    % Recalulcate the actigraphy sleep events
                    if app.ApplySettingsButton.UserData.redo.sleep
                        % ---------------------------------------------------------
                        % Write history
                        app.ACT.history = char(app.ACT.history, '% ---------------------------------------------------------');
                        app.ACT.history = char(app.ACT.history, '% Change the sleep window type');
                        app.ACT.history = char(app.ACT.history, sprintf('ACT.analysis.settings.sleepWindowType = ''%s'';', app.ACT.analysis.settings.sleepWindowType));
                        % Recalculate sleep events
                        app.ACT = cic_actigraphySleepEvents(app.ACT);
                    end
                    app.ApplySettingsButton.UserData = [];
                end
                % ---------------------------------------------------------
                % Remove the copies of the settings stuctures
                if isfield(app.ACT, 'urdisplay')
                    app.ACT = rmfield(app.ACT, 'urdisplay');
                    app.ACT.analysis = rmfield(app.ACT.analysis, 'ursettings');
                end
                % ---------------------------------------------------------
                % Set saved to false
                app.ACT.saved = false;
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Info'}];
                lifecycle(app, true)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Window key press function: CicadaUIFigure
        function CicadaUIFigureKeyPress(app, event)
            try
                % ---------------------------------------------------------
                % The user can use the left and right arrows to move the cursor
                % If there is no cursor, return
                if ~isfield(app.Cursor, 'Position')
                    % The only thing the user may do is delete the interval
                    switch event.Key
                        case {'escape', 'backspace'}
                            app.Cursor = struct();
                            app.Interval = struct();
                    end
                    % ---------------------------------------------------------
                    % Call the app lifecycle
                    app.ComponentList = [app.ComponentList, {'Cursor', 'Interval'}];
                    lifecycle(app, false)
                    return
                end
                % Check which key was pressed
                switch event.Key
                    case 'leftarrow'
                        if (app.Cursor.Position - 1/24/60) < app.ACT.xmin
                            return
                        end
                        app.Cursor.Position = app.Cursor.Position - 1/24/60;
                    case 'rightarrow'
                        if (app.Cursor.Position + 1/24/60) > app.ACT.xmax
                            return
                        end
                        app.Cursor.Position = app.Cursor.Position + 1/24/60;
                    case {'escape', 'backspace'}
                        app.Cursor = struct();
                        app.Interval = struct();
                    case {'enter', 'return'}
                        if ~isfield(app.Interval, 'Position')
                            app.Interval.Position = app.Cursor.Position;
                            app.Interval.Parents = app.Cursor.Parent;
                        else
                            app.Interval.Position = sort([app.Interval.Position(1), app.Cursor.Position]);
                            app.Interval.Parents = [app.Interval.Parents; app.Cursor.Parent];
                        end
                        app.Cursor = struct();
                    otherwise
                        return
                end
                % ---------------------------------------------------------
                % If the interval has two values call the GUI for the user to
                % select what event label and type to attribute this interval to
                if isfield(app.Interval, 'Position')
                    if length(app.Interval.Position) == 2
                        % ---------------------------------------------------------
                        % Delete the cursor, sort and update the interval
                        app.ComponentList = [app.ComponentList, {'Cursor', 'Interval'}];
                        lifecycle(app, false)
                        % ---------------------------------------------------------
                        % Call the User Interface to create new event
                        app.createEventApp = createEvent(app);
                        % Wait for the response
                        uiwait(app.createEventApp.CreateEventUIFigure);
                        if ~isfield(app.Import, 'Return')
                            app.Import = struct();
                            app.Cursor = struct();
                            app.Interval = struct();
                            app.ComponentList = [app.ComponentList, {'Cursor', 'Interval'}];
                            lifecycle(app, false)
                            return
                        elseif ~app.Import.Return
                            app.Import = struct();
                            app.Cursor = struct();
                            app.Interval = struct();
                            app.ComponentList = [app.ComponentList, {'Cursor', 'Interval'}];
                            lifecycle(app, false)
                            return
                        end
                        % ---------------------------------------------------------
                        % Create new event in table form
                        event = table();
                        event.id = -1;
                        event.label = app.Import.Data.label;
                        event.onset = app.Import.Data.onset;
                        event.duration = app.Import.Data.duration;
                        event.type = app.Import.Data.type;
                        % ---------------------------------------------------------
                        % Check if new event does not overlap with an existing event of the same type/label
                        [err, message] = checkEventOverlap(app, event);
                        if err
                            uiconfirm(app.CicadaUIFigure, message, 'Error', ...
                                'Options',{'Ok'},...
                                'DefaultOption', 'Ok', ...
                                'Icon', 'error');
                            app.Cursor = struct();
                            app.Interval = struct();
                            app.ComponentList = [app.ComponentList, {'Cursor', 'Interval'}];
                            lifecycle(app, false)
                            return
                        end
                        % ---------------------------------------------------------
                        % Check that the statistics functions have not run yet, otherwise we have to
                        % undo any statistics steps before continueing
                        if any(ismember(app.ACT.pipe, {'statistics'}))
                            cancelAction = app_askToUndoStatistics(app);
                            if cancelAction
                                app.Cursor = struct();
                                app.Interval = struct();
                                app.ComponentList = [app.ComponentList, {'Cursor', 'Interval'}];
                                lifecycle(app, false)
                                return
                            end
                            lifecycle(app, true)
                        end
                        % ---------------------------------------------------------
                        % Write history
                        app.ACT.history = char(app.ACT.history, '% ---------------------------------------------------------');
                        app.ACT.history = char(app.ACT.history, '% Add a new event');
                        % ---------------------------------------------------------
                        % Add the new event
                        ids = [];
                        switch app.Import.Data.label
                            case 'sleepWindow'
                                % If the sleep window type is not manual at this moment, copy these events and relabel them to 'manual'
                                if ~strcmpi(app.ACT.analysis.settings.sleepWindowType, 'manual')
                                    events = selectEventsUsingTime(app.ACT.analysis.events, app.ACT.xmin, app.ACT.xmax, 'Label', 'sleepWindow', 'Type', app.ACT.analysis.settings.sleepWindowType);
                                    [app.ACT, id] = cic_editEvents(app.ACT, 'add', events.onset, events.duration, 'Label', 'sleepWindow', 'Type', 'manual');
                                    ids = [ids; id];
                                    % ---------------------------------------------------------
                                    % Write history
                                    app.ACT.history = char(app.ACT.history, '% -----');
                                    app.ACT.history = char(app.ACT.history, '% Adding a new manual sleep window while the sleep window type is currently not manual, so copy these events and relabel them as ''manual''.');
                                    app.ACT.history = char(app.ACT.history, sprintf('events = selectEventsUsingTime(ACT.analysis.events, ACT.xmin, ACT.xmax, ''Label'', ''sleepWindow'', ''Type'', ''%s'');', app.ACT.analysis.settings.sleepWindowType));
                                    app.ACT.history = char(app.ACT.history, 'ACT = cic_editEvents(ACT, ''add'', events.onset, events.duration, ''Label'', ''sleepWindow'', ''Type'', ''manual'');');
                                    app.ACT.history = char(app.ACT.history, '% -----');
                                    app.ACT.history = char(app.ACT.history, '% Set the sleep window type to manual');
                                    app.ACT.history = char(app.ACT.history, 'ACT.analysis.settings.sleepWindowType = ''manual'';');
                                    % Set sleep window type to manual
                                    app.ACT.analysis.settings.sleepWindowType = 'manual';
                                end
                                [app.ACT, id] = cic_editEvents(app.ACT, 'add', event.onset, event.duration, 'Label', 'sleepWindow', 'Type', 'manual');
                                ids = [ids; id];
                                % ---------------------------------------------------------
                                % Write history
                                app.ACT.history = char(app.ACT.history, '% -----');
                                app.ACT.history = char(app.ACT.history, '% Add the sleep window event');
                                app.ACT.history = char(app.ACT.history, sprintf('onset = datenum(''%s'', ''dd/mm/yyyy HH:MM''); %% onset of new event', datestr(event.onset, 'dd/mm/yyyy HH:MM')));
                                app.ACT.history = char(app.ACT.history, sprintf('duration = %.4f/24; %% duration of new event in days (i.e. hours/24)', event.duration*24));
                                app.ACT.history = char(app.ACT.history, 'ACT = cic_editEvents(ACT, ''add'', onset, duration, ''Label'', ''sleepWindow'', ''Type'', ''manual'');');
                                % ---------------------------------------------------------
                                % Get the actigraphy sleep events
                                [app.ACT, id] = cic_actigraphySleepEvents(app.ACT, [event.onset, event.onset+event.duration]);
                                ids = [ids; id];
                            otherwise
                                [app.ACT, id] = cic_editEvents(app.ACT, 'add', event.onset, event.duration, 'Label', event.label, 'Type', event.type);
                                ids = [ids; id];
                                % ---------------------------------------------------------
                                % Write history
                                app.ACT.history = char(app.ACT.history, sprintf('onset = datenum(''%s'', ''dd/mm/yyyy HH:MM''); %% onset of new event', datestr(event.onset, 'dd/mm/yyyy HH:MM')));
                                app.ACT.history = char(app.ACT.history, sprintf('duration = %.4f/24; %% duration of new event in days (i.e. hours/24)', event.duration*24));
                                app.ACT.history = char(app.ACT.history, sprintf('ACT = cic_editEvents(ACT, ''add'', onset, duration, ''Label'', ''%s'', ''Type'', ''%s'');', event.label, event.type));
                        end
                        % Clear the import structure
                        app.Import = struct();
                        app.Cursor = struct();
                        app.Interval = struct();
                        % Construct component list
                        ComponentTags = strsplit(sprintf('Event_day-0_id-%i,', ids), ',');
                        app.ComponentList = [app.ComponentList, ComponentTags(1:end-1), {'Info', 'SettingsPanel'}];
                    end
                end
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'Cursor', 'Interval'}];
                lifecycle(app, false)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Selection changed function: AvStats_FilterPanelButtonGroup
        function AvStats_FilterPanelButtonGroupSelectionChanged(app, event)
            try
                % ---------------------------------------------------------
                % Call the app lifecycle
                app.ComponentList = [app.ComponentList, {'AvStats'}];
                lifecycle(app, false)
                % ---------------------------------------------------------
            catch ME % something went wrong
                app_showErrMsg(app, ME)
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CicadaUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {728, 728};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CicadaUIFigure = uifigure('Visible', 'off');
            app.CicadaUIFigure.Name = 'Cicada';
            app.CicadaUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.CicadaUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @CicadaUIFigureKeyPress, true);
            app.FileMenu = uimenu(app.CicadaUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenDatasetMenu = uimenu(app.FileMenu);
            app.OpenDatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDatasetMenuSelected, true);
            app.OpenDatasetMenu.Separator = 'on';
            app.OpenDatasetMenu.Accelerator = 'O';
            app.OpenDatasetMenu.Text = 'Open Dataset';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Enable = 'off';
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Accelerator = 'W';
            app.CloseMenu.Text = 'Close';
            app.SaveDatasetMenu = uimenu(app.FileMenu);
            app.SaveDatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDatasetMenuSelected, true);
            app.SaveDatasetMenu.Enable = 'off';
            app.SaveDatasetMenu.Accelerator = 'S';
            app.SaveDatasetMenu.Text = 'Save Dataset';
            app.SaveDatasetAsMenu = uimenu(app.FileMenu);
            app.SaveDatasetAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDatasetAsMenuSelected, true);
            app.SaveDatasetAsMenu.Enable = 'off';
            app.SaveDatasetAsMenu.Text = 'Save Dataset As';
            app.ImportActigraphyMenu = uimenu(app.FileMenu);
            app.ImportActigraphyMenu.Separator = 'on';
            app.ImportActigraphyMenu.Text = 'Import Actigraphy';
            app.ImportGeneActivMenu = uimenu(app.ImportActigraphyMenu);
            app.ImportGeneActivMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportGeneActivMenuSelected, true);
            app.ImportGeneActivMenu.Text = 'Import GeneActiv (.bin)';
            app.ImportOtherDataMenu = uimenu(app.FileMenu);
            app.ImportOtherDataMenu.Enable = 'off';
            app.ImportOtherDataMenu.Text = 'Import Other Data';
            app.ImportLightPinMenu = uimenu(app.ImportOtherDataMenu);
            app.ImportLightPinMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportLightPinMenuSelected, true);
            app.ImportLightPinMenu.Text = 'Import WIMR Light Pin (.csv)';
            app.ImportEventsMenu = uimenu(app.FileMenu);
            app.ImportEventsMenu.Enable = 'off';
            app.ImportEventsMenu.Text = 'Import Events';
            app.ImportSleepDiaryMenu = uimenu(app.ImportEventsMenu);
            app.ImportSleepDiaryMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportSleepDiaryMenuSelected, true);
            app.ImportSleepDiaryMenu.Text = 'Import Sleep Diary';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Enable = 'off';
            app.ExportMenu.Separator = 'on';
            app.ExportMenu.Text = 'Export';
            app.ExportAnalysisMenu = uimenu(app.ExportMenu);
            app.ExportAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportStatsMenuSelected, true);
            app.ExportAnalysisMenu.Text = 'Statistics';
            app.ExportReportMenu = uimenu(app.ExportMenu);
            app.ExportReportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportReportMenuSelected, true);
            app.ExportReportMenu.Text = 'Report';
            app.ExportCodeMenu = uimenu(app.ExportMenu);
            app.ExportCodeMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportCodeMenuSelected, true);
            app.ExportCodeMenu.Text = 'Matlab Code';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Separator = 'on';
            app.QuitMenu.Text = 'Quit';
            app.EditMenu = uimenu(app.CicadaUIFigure);
            app.EditMenu.Enable = 'off';
            app.EditMenu.Text = 'Edit';
            app.DatasetInfoMenu = uimenu(app.EditMenu);
            app.DatasetInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @DatasetInfoMenuSelected, true);
            app.DatasetInfoMenu.Separator = 'on';
            app.DatasetInfoMenu.Text = 'Dataset Info';
            app.SelectDataMenu = uimenu(app.EditMenu);
            app.SelectDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectDataMenuSelected, true);
            app.SelectDataMenu.Separator = 'on';
            app.SelectDataMenu.Text = 'Select Data';
            app.ChangeTimeZoneMenu = uimenu(app.EditMenu);
            app.ChangeTimeZoneMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTimeZoneMenuSelected, true);
            app.ChangeTimeZoneMenu.Text = 'Change Time Zone';
            app.ChangeEpochLengthMenu = uimenu(app.EditMenu);
            app.ChangeEpochLengthMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeEpochLengthMenuSelected, true);
            app.ChangeEpochLengthMenu.Text = 'Change Epoch Length';
            app.ViewMenu = uimenu(app.CicadaUIFigure);
            app.ViewMenu.Enable = 'off';
            app.ViewMenu.Text = 'View';
            app.ShowActivityCountsMenu = uimenu(app.ViewMenu);
            app.ShowActivityCountsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ShowActivityCountsMenu.Text = 'Show Activity Counts';
            app.ShowEuclideanNormMenu = uimenu(app.ViewMenu);
            app.ShowEuclideanNormMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ShowEuclideanNormMenu.Enable = 'off';
            app.ShowEuclideanNormMenu.Checked = 'on';
            app.ShowEuclideanNormMenu.Text = 'Show Euclidean Norm';
            app.ShowAngleMenu = uimenu(app.ViewMenu);
            app.ShowAngleMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewMenuSelected, true);
            app.ShowAngleMenu.Text = 'Show Angle';
            app.PreprocessMenu = uimenu(app.CicadaUIFigure);
            app.PreprocessMenu.Enable = 'off';
            app.PreprocessMenu.Text = 'Preprocess';
            app.GGIRAutomaticCalibrationMenu = uimenu(app.PreprocessMenu);
            app.GGIRAutomaticCalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @GGIRAutomaticCalibrationMenuSelected, true);
            app.GGIRAutomaticCalibrationMenu.Text = 'GGIR Automatic Calibration';
            app.GGIRNonWearDetectionMenu = uimenu(app.PreprocessMenu);
            app.GGIRNonWearDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @GGIRNonWearDetectionMenuSelected, true);
            app.GGIRNonWearDetectionMenu.Text = 'GGIR Non-Wear Detection';
            app.AnalysisMenu = uimenu(app.CicadaUIFigure);
            app.AnalysisMenu.Enable = 'off';
            app.AnalysisMenu.Text = 'Analysis';
            app.AnnotateEpochsMenu = uimenu(app.AnalysisMenu);
            app.AnnotateEpochsMenu.Text = 'Annotate Epochs';
            app.AnnotateAccelerationGGIRMenu = uimenu(app.AnnotateEpochsMenu);
            app.AnnotateAccelerationGGIRMenu.MenuSelectedFcn = createCallbackFcn(app, @AnnotateAccelerationGGIRMenuSelected, true);
            app.AnnotateAccelerationGGIRMenu.Separator = 'on';
            app.AnnotateAccelerationGGIRMenu.Text = 'Annotate Acceleration (GGIR)';
            app.AnnotateLightMenu = uimenu(app.AnnotateEpochsMenu);
            app.AnnotateLightMenu.MenuSelectedFcn = createCallbackFcn(app, @AnnotateLightMenuSelected, true);
            app.AnnotateLightMenu.Text = 'Annotate Light';
            app.EventsMenu = uimenu(app.AnalysisMenu);
            app.EventsMenu.Text = 'Events';
            app.CreateDailyEventsMenu = uimenu(app.EventsMenu);
            app.CreateDailyEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateDailyEventsMenuSelected, true);
            app.CreateDailyEventsMenu.Text = 'Create Daily Events';
            app.CreateRelativeEventsMenu = uimenu(app.EventsMenu);
            app.CreateRelativeEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateRelativeEventsMenuSelected, true);
            app.CreateRelativeEventsMenu.Text = 'Create Relative Events';
            app.GGIRSleepDetectionMenu = uimenu(app.EventsMenu);
            app.GGIRSleepDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @GGIRSleepDetectionMenuSelected, true);
            app.GGIRSleepDetectionMenu.Separator = 'on';
            app.GGIRSleepDetectionMenu.Text = 'GGIR Sleep Detection';
            app.StatisticsMenu = uimenu(app.CicadaUIFigure);
            app.StatisticsMenu.Enable = 'off';
            app.StatisticsMenu.Text = 'Statistics';
            app.GenerateStatisticsMenu = uimenu(app.StatisticsMenu);
            app.GenerateStatisticsMenu.MenuSelectedFcn = createCallbackFcn(app, @RegenStatsButtonPushed, true);
            app.GenerateStatisticsMenu.Text = 'Generate Statistics';
            app.WindowMenu = uimenu(app.CicadaUIFigure);
            app.WindowMenu.Enable = 'off';
            app.WindowMenu.Text = 'Window';
            app.RepositionpanelsMenu = uimenu(app.WindowMenu);
            app.RepositionpanelsMenu.MenuSelectedFcn = createCallbackFcn(app, @RepositionpanelsMenuSelected, true);
            app.RepositionpanelsMenu.Text = 'Reposition panels';
            app.HelpMenu = uimenu(app.CicadaUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.VisittheCicadaMenu = uimenu(app.HelpMenu);
            app.VisittheCicadaMenu.MenuSelectedFcn = createCallbackFcn(app, @VisittheCicadaMenuSelected, true);
            app.VisittheCicadaMenu.Separator = 'on';
            app.VisittheCicadaMenu.Text = 'Visit the Cicada';
            app.GridLayout = uigridlayout(app.CicadaUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutLeftPanel = uigridlayout(app.LeftPanel);
            app.InformationPanel = uipanel(app.GridLayoutLeftPanel);
            app.GridLayoutInformationPanel = uigridlayout(app.InformationPanel);
            app.FilenamePanel = uipanel(app.GridLayoutInformationPanel);
            app.FilenamePanel.Title = 'Filename';
            app.GridLayoutFilenamePanel = uigridlayout(app.FilenamePanel);
            app.FilenameLabel = uilabel(app.GridLayoutFilenamePanel);
            app.FilenameLabel.Text = '';
            app.StudyPanel = uipanel(app.GridLayoutInformationPanel);
            app.StudyPanel.Title = 'Study';
            app.GridLayoutStudyPanel = uigridlayout(app.StudyPanel);
            app.StudyLabel = uilabel(app.GridLayoutStudyPanel);
            app.StudyLabel.Text = '';
            app.ResearcherPanel = uipanel(app.GridLayoutInformationPanel);
            app.ResearcherPanel.Title = 'Researcher';
            app.GridLayoutResearcherPanel = uigridlayout(app.ResearcherPanel);
            app.ResearcherLabel = uilabel(app.GridLayoutResearcherPanel);
            app.ResearcherLabel.Text = '';
            app.SubjectIDPanel = uipanel(app.GridLayoutInformationPanel);
            app.SubjectIDPanel.Title = 'Subject ID';
            app.GridLayoutSubjectIDPanel = uigridlayout(app.SubjectIDPanel);
            app.SubjectIDLabel = uilabel(app.GridLayoutSubjectIDPanel);
            app.SubjectIDLabel.Text = '';
            app.GroupPanel = uipanel(app.GridLayoutInformationPanel);
            app.GroupPanel.Title = 'Group';
            app.GridLayoutGroupPanel = uigridlayout(app.GroupPanel);
            app.GroupLabel = uilabel(app.GridLayoutGroupPanel);
            app.GroupLabel.Text = '';
            app.ConditionPanel = uipanel(app.GridLayoutInformationPanel);
            app.ConditionPanel.Title = 'Condition';
            app.GridLayoutConditionPanel = uigridlayout(app.ConditionPanel);
            app.ConditionLabel = uilabel(app.GridLayoutConditionPanel);
            app.ConditionLabel.Text = '';
            app.SessionPanel = uipanel(app.GridLayoutInformationPanel);
            app.SessionPanel.Title = 'Session';
            app.GridLayoutSessionPanel = uigridlayout(app.SessionPanel);
            app.SessionLabel = uilabel(app.GridLayoutSessionPanel);
            app.SessionLabel.Text = '';
            app.DateOfBirthPanel = uipanel(app.GridLayoutInformationPanel);
            app.DateOfBirthPanel.Title = 'Date Of Birth';
            app.GridLayoutDateOfBirthPanel = uigridlayout(app.DateOfBirthPanel);
            app.DateOfBirthLabel = uilabel(app.GridLayoutDateOfBirthPanel);
            app.DateOfBirthLabel.Text = '';
            app.SexPanel = uipanel(app.GridLayoutInformationPanel);
            app.SexPanel.Title = 'Sex';
            app.GridLayoutSexPanel = uigridlayout(app.SexPanel);
            app.SexLabel = uilabel(app.GridLayoutSexPanel);
            app.SexLabel.Text = '';
            app.HeightPanel = uipanel(app.GridLayoutInformationPanel);
            app.HeightPanel.Title = 'Height';
            app.GridLayoutHeightPanel = uigridlayout(app.HeightPanel);
            app.HeightLabel = uilabel(app.GridLayoutHeightPanel);
            app.HeightLabel.Text = '';
            app.WeightPanel = uipanel(app.GridLayoutInformationPanel);
            app.WeightPanel.Title = 'Weight';
            app.GridLayoutWeightPanel = uigridlayout(app.WeightPanel);
            app.WeightLabel = uilabel(app.GridLayoutWeightPanel);
            app.WeightLabel.Text = '';
            app.HandednessPanel = uipanel(app.GridLayoutInformationPanel);
            app.HandednessPanel.Title = 'Handedness';
            app.GridLayoutHandednessPanel = uigridlayout(app.HandednessPanel);
            app.HandednessLabel = uilabel(app.GridLayoutHandednessPanel);
            app.HandednessLabel.Text = '';
            app.DeviceLocationPanel = uipanel(app.GridLayoutInformationPanel);
            app.DeviceLocationPanel.Title = 'Device Location';
            app.GridLayoutDeviceLocationPanel = uigridlayout(app.DeviceLocationPanel);
            app.DeviceLocationLabel = uilabel(app.GridLayoutDeviceLocationPanel);
            app.DeviceLocationLabel.Text = '';
            app.StartDatePanel = uipanel(app.GridLayoutInformationPanel);
            app.StartDatePanel.Title = 'Start Date';
            app.GridLayoutStartDatePanel = uigridlayout(app.StartDatePanel);
            app.StartDateLabel = uilabel(app.GridLayoutStartDatePanel);
            app.StartDateLabel.Text = '';
            app.EndDatePanel = uipanel(app.GridLayoutInformationPanel);
            app.EndDatePanel.Title = 'End Date';
            app.GridLayoutEndDatePanel = uigridlayout(app.EndDatePanel);
            app.EndDateLabel = uilabel(app.GridLayoutEndDatePanel);
            app.EndDateLabel.Text = '';
            app.RecordingLengthPanel = uipanel(app.GridLayoutInformationPanel);
            app.RecordingLengthPanel.Title = 'Recording Length';
            app.GridLayoutRecordingLengthPanel = uigridlayout(app.RecordingLengthPanel);
            app.RecordingLengthLabel = uilabel(app.GridLayoutRecordingLengthPanel);
            app.RecordingLengthLabel.Text = '';
            app.SamplingRatePanel = uipanel(app.GridLayoutInformationPanel);
            app.SamplingRatePanel.Title = 'Sampling Rate';
            app.GridLayoutSamplingRatePanel = uigridlayout(app.SamplingRatePanel);
            app.SamplingRateLabel = uilabel(app.GridLayoutSamplingRatePanel);
            app.SamplingRateLabel.Text = '';
            app.LegendPanel = uipanel(app.GridLayoutInformationPanel);
            app.LegendPanel.Title = 'Legend';
            app.GridLayoutLegendPanel = uigridlayout(app.LegendPanel);
            app.AnnotationLabel = uilabel(app.GridLayoutLegendPanel);
            app.AnnotationLabel.Text = 'Annotation';
            app.EventsLabel = uilabel(app.GridLayoutLegendPanel);
            app.EventsLabel.Text = 'Events';
            app.InactivityButton = uibutton(app.GridLayoutLegendPanel, 'push');
            app.InactivityButton.Text = '';
            app.SustainedInactivityLabel = uilabel(app.GridLayoutLegendPanel);
            app.SustainedInactivityLabel.Text = 'Sustained Inactivity';
            app.MinimalActivityButton = uibutton(app.GridLayoutLegendPanel, 'push');
            app.MinimalActivityButton.Text = '';
            app.LowActivityLabel = uilabel(app.GridLayoutLegendPanel);
            app.LowActivityLabel.Text = 'Low Activity';
            app.LightActivityButton = uibutton(app.GridLayoutLegendPanel, 'push');
            app.LightActivityButton.Text = '';
            app.LightActivityLabel = uilabel(app.GridLayoutLegendPanel);
            app.LightActivityLabel.Text = 'Light Activity';
            app.ModerateActivityButton = uibutton(app.GridLayoutLegendPanel, 'push');
            app.ModerateActivityButton.Text = '';
            app.ModerateActivityLabel = uilabel(app.GridLayoutLegendPanel);
            app.ModerateActivityLabel.Text = 'Moderate Activity';
            app.VigorousActivityButton = uibutton(app.GridLayoutLegendPanel, 'push');
            app.VigorousActivityButton.Text = '';
            app.VigorousActivityLabel = uilabel(app.GridLayoutLegendPanel);
            app.VigorousActivityLabel.Text = 'Vigorous Activity';
            app.ExclusionButton = uibutton(app.GridLayoutLegendPanel, 'push');
            app.ExclusionButton.Text = '';
            app.ExclusionLabel = uilabel(app.GridLayoutLegendPanel);
            app.ExclusionLabel.Text = 'Exclusion';
            app.ButtonPressButton = uibutton(app.GridLayoutLegendPanel, 'push');
            app.ButtonPressButton.Text = '';
            app.ButtonPressLabel = uilabel(app.GridLayoutLegendPanel);
            app.ButtonPressLabel.Text = 'Button Press';
            app.SleepWindowButton = uibutton(app.GridLayoutLegendPanel, 'push');
            app.SleepWindowButton.Text = '';
            app.SleepWindowLabel = uilabel(app.GridLayoutLegendPanel);
            app.SleepWindowLabel.Text = 'Sleep Window';
            app.SleepPeriodButton = uibutton(app.GridLayoutLegendPanel, 'push');
            app.SleepPeriodButton.Text = '';
            app.SleepPeriodLabel = uilabel(app.GridLayoutLegendPanel);
            app.SleepPeriodLabel.Text = 'Sleep Period';
            app.CustomEventButton = uibutton(app.GridLayoutLegendPanel, 'push');
            app.CustomEventButton.Text = '';
            app.CustomEventLabel = uilabel(app.GridLayoutLegendPanel);
            app.CustomEventLabel.Text = 'Custom Event';
            app.UIAxesLogo = uiaxes(app.GridLayoutLeftPanel);
            title(app.UIAxesLogo, '')
            xlabel(app.UIAxesLogo, '')
            ylabel(app.UIAxesLogo, '')
            app.UIAxesLogo.XTick = [];
            app.UIAxesLogo.YTick = [];
            app.UIAxesLogo.ZTick = [];
            app.UIAxesLogo.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayoutRightPanel = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayoutRightPanel);
            app.DataAnalysisTab = uitab(app.TabGroup);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.GridLayoutDataAnalysisTab = uigridlayout(app.DataAnalysisTab);
            app.DataPanel = uipanel(app.GridLayoutDataAnalysisTab);
            app.DataContainer = uipanel(app.DataPanel);
            app.SettingsPanel = uipanel(app.GridLayoutDataAnalysisTab);
            app.GridLayoutSettingsPanel = uigridlayout(app.SettingsPanel);
            app.CancelSettingsButton = uibutton(app.GridLayoutSettingsPanel, 'push');
            app.CancelSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @CancelSettingsButtonPushed, true);
            app.CancelSettingsButton.Enable = 'off';
            app.CancelSettingsButton.Text = 'Cancel';
            app.ApplySettingsButton = uibutton(app.GridLayoutSettingsPanel, 'push');
            app.ApplySettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySettingsButtonPushed, true);
            app.ApplySettingsButton.Enable = 'off';
            app.ApplySettingsButton.Text = 'Apply';
            app.ActogramSettingsPanel = uipanel(app.GridLayoutSettingsPanel);
            app.ActogramSettingsPanel.Title = 'Actogram';
            app.ActogramSettingsPanel.Visible = 'off';
            app.GridLayoutActogramSettingsPanel = uigridlayout(app.ActogramSettingsPanel);
            app.ActogramSettingsRowSpanLabel = uilabel(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsRowSpanLabel.Text = 'Height';
            app.ActogramSettingsRowSpanInput = uispinner(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsRowSpanInput.Limits = [1 5];
            app.ActogramSettingsRowSpanInput.ValueDisplayFormat = '%i';
            app.ActogramSettingsRowSpanInput.ValueChangedFcn = createCallbackFcn(app, @ChangePreferences, true);
            app.ActogramSettingsRowSpanInput.Value = 3;
            app.ActogramSettingsRowSpanUnitLabel = uilabel(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsRowSpanUnitLabel.Text = 'x';
            app.ActogramSettingsLengthLabel = uilabel(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsLengthLabel.Text = 'Length';
            app.ActogramSettingsLengthInput = uidropdown(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsLengthInput.Items = {'5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.ActogramSettingsLengthInput.ValueChangedFcn = createCallbackFcn(app, @ChangePreferences, true);
            app.ActogramSettingsLengthInput.Value = '5';
            app.ActogramSettingsLengthUnitLabel = uilabel(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsLengthUnitLabel.Text = 'days';
            app.ActogramSettingsWidthLabel = uilabel(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsWidthLabel.Text = 'Plot';
            app.ActogramSettingsWidthInput = uidropdown(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsWidthInput.Items = {'single', 'double'};
            app.ActogramSettingsWidthInput.ValueChangedFcn = createCallbackFcn(app, @ChangePreferences, true);
            app.ActogramSettingsWidthInput.Value = 'single';
            app.ActogramSettingsClockLabel = uilabel(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsClockLabel.Text = 'Clock';
            app.ActogramSettingsClockMinInput = uieditfield(app.GridLayoutActogramSettingsPanel, 'text');
            app.ActogramSettingsClockMinInput.ValueChangedFcn = createCallbackFcn(app, @ChangePreferences, true);
            app.ActogramSettingsClockMaxInput = uieditfield(app.GridLayoutActogramSettingsPanel, 'text');
            app.ActogramSettingsClockMaxInput.ValueChangedFcn = createCallbackFcn(app, @ChangePreferences, true);
            app.ActogramSettingsClockUnitLabel = uilabel(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsClockUnitLabel.Text = 'HH:MM';
            app.ActogramSettingsRangeLabel = uilabel(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsRangeLabel.Text = 'Range';
            app.ActogramSettingsRangeMinInput = uieditfield(app.GridLayoutActogramSettingsPanel, 'numeric');
            app.ActogramSettingsRangeMinInput.ValueDisplayFormat = '%.1f';
            app.ActogramSettingsRangeMinInput.ValueChangedFcn = createCallbackFcn(app, @ChangePreferences, true);
            app.ActogramSettingsRangeMinInput.Value = 1;
            app.ActogramSettingsRangeMaxInput = uieditfield(app.GridLayoutActogramSettingsPanel, 'numeric');
            app.ActogramSettingsRangeMaxInput.ValueDisplayFormat = '%.1f';
            app.ActogramSettingsRangeMaxInput.ValueChangedFcn = createCallbackFcn(app, @ChangePreferences, true);
            app.ActogramSettingsRangeMaxInput.Value = 1;
            app.ActogramSettingsLogInput = uicheckbox(app.GridLayoutActogramSettingsPanel);
            app.ActogramSettingsLogInput.ValueChangedFcn = createCallbackFcn(app, @ChangePreferences, true);
            app.ActogramSettingsLogInput.Text = 'log';
            app.AverageStatsTab = uitab(app.TabGroup);
            app.AverageStatsTab.Title = 'Average Stats';
            app.GridLayoutAverageStatsTab = uigridlayout(app.AverageStatsTab);
            app.AvStats_ReAnalysePanel = uipanel(app.GridLayoutAverageStatsTab);
            app.AvStats_ReAnalysePanel.Title = ' ';
            app.AvStats_GridLayoutReAnalysePanel = uigridlayout(app.AvStats_ReAnalysePanel);
            app.AvStats_RegenStatsButton = uibutton(app.AvStats_GridLayoutReAnalysePanel, 'push');
            app.AvStats_RegenStatsButton.ButtonPushedFcn = createCallbackFcn(app, @RegenStatsButtonPushed, true);
            app.AvStats_RegenStatsButton.Enable = 'off';
            app.AvStats_RegenStatsButton.Text = 'Statistics Up-to-Date';
            app.AvStats_FilterPanel = uipanel(app.GridLayoutAverageStatsTab);
            app.AvStats_FilterPanel.Title = 'Filter';
            app.AvStats_FilterPanel.Visible = 'off';
            app.AvStats_FilterPanelButtonGroup = uibuttongroup(app.AvStats_FilterPanel);
            app.AvStats_FilterPanelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AvStats_FilterPanelButtonGroupSelectionChanged, true);
            app.AvStats_FilterAllDays = uiradiobutton(app.AvStats_FilterPanelButtonGroup);
            app.AvStats_FilterAllDays.Text = 'all days';
            app.AvStats_FilterAllDays.Value = true;
            app.AvStats_FilterWeekDays = uiradiobutton(app.AvStats_FilterPanelButtonGroup);
            app.AvStats_FilterWeekDays.Text = 'weekdays only';
            app.AvStats_FilterWeekendDays = uiradiobutton(app.AvStats_FilterPanelButtonGroup);
            app.AvStats_FilterWeekendDays.Text = 'weekends only';
            app.AvStats_Container = uipanel(app.GridLayoutAverageStatsTab);
            app.GridLayoutAvStats_Container = uigridlayout(app.AvStats_Container);
            app.AvStats_InfoPanel = uipanel(app.GridLayoutAvStats_Container);
            app.AvStats_GridLayoutInfoPanel = uigridlayout(app.AvStats_InfoPanel);
            app.AvStats_NumberofDaysLabel = uilabel(app.AvStats_GridLayoutInfoPanel);
            app.AvStats_NumberofDaysLabel.Text = 'Number of Days';
            app.AvStats_NumberofDaysValue = uilabel(app.AvStats_GridLayoutInfoPanel);
            app.AvStats_NumberofDaysValue.Text = '-';
            app.AvStats_ActogramWindowLabel = uilabel(app.AvStats_GridLayoutInfoPanel);
            app.AvStats_ActogramWindowLabel.Text = 'Actogram Window';
            app.AvStats_ActogramWindowValue = uilabel(app.AvStats_GridLayoutInfoPanel);
            app.AvStats_ActogramWindowValue.Text = '-';
            app.AvStats_TimeRejectedLabel = uilabel(app.AvStats_GridLayoutInfoPanel);
            app.AvStats_TimeRejectedLabel.Text = 'Time Rejected';
            app.AvStats_TimeRejectedValue = uilabel(app.AvStats_GridLayoutInfoPanel);
            app.AvStats_TimeRejectedValue.Text = '-';
            app.AvStats_InterIntraDailyPanel = uipanel(app.GridLayoutAvStats_Container);
            app.AvStats_GridLayoutInterIntraDailyPanel = uigridlayout(app.AvStats_InterIntraDailyPanel);
            app.AvStats_AccelerationPanel = uipanel(app.GridLayoutAvStats_Container);
            app.AvStats_AccelerationPanel.Title = 'Average Day Acceleration - 5h Moving Window';
            app.AvStats_GridLayoutAccelerationPanel = uigridlayout(app.AvStats_AccelerationPanel);
            app.AvStats_HoursModVigActLabel = uilabel(app.AvStats_GridLayoutAccelerationPanel);
            app.AvStats_HoursModVigActLabel.Text = {'Time in Moderate to'; 'Vigorous Activity'};
            app.AvStats_HoursModVigActValue = uilabel(app.AvStats_GridLayoutAccelerationPanel);
            app.AvStats_HoursModVigActValue.Text = '-';
            app.AvStats_AvEuclNormModVigActLabel = uilabel(app.AvStats_GridLayoutAccelerationPanel);
            app.AvStats_AvEuclNormModVigActLabel.Text = {'Mean Euclidean Norm'; 'in Moderate to Vigorous Activity'};
            app.AvStats_AvEuclNormModVigActValue = uilabel(app.AvStats_GridLayoutAccelerationPanel);
            app.AvStats_AvEuclNormModVigActValue.Text = '-';
            app.AvStats_LightPanel = uipanel(app.GridLayoutAvStats_Container);
            app.AvStats_LightPanel.Title = 'Average Day Light - 30m Moving Window';
            app.AvStats_GridLayoutLightPanel = uigridlayout(app.AvStats_LightPanel);
            app.AvStats_TemperaturePanel = uipanel(app.GridLayoutAvStats_Container);
            app.AvStats_TemperaturePanel.Title = 'Average Day Temp - 30m Moving Window';
            app.AvStats_GridLayoutTemperaturePanel = uigridlayout(app.AvStats_TemperaturePanel);
            app.AvStats_SleepPanel = uipanel(app.GridLayoutAvStats_Container);
            app.AvStats_SleepPanel.Title = 'Average Sleep';
            app.AvStats_GridLayoutSleepPanel = uigridlayout(app.AvStats_SleepPanel);
            app.AvStats_SlpCountLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpCountLabel.Text = {'Number of '; 'Sleep Windows'};
            app.AvStats_SlpCountValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpCountValue.Text = '-';
            app.AvStats_SlpAcrossNoonLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpAcrossNoonLabel.Text = {'Number of Sleep'; 'Windows across Noon'};
            app.AvStats_SlpAcrossNoonValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpAcrossNoonValue.Text = '-';
            app.AvStats_UIAxesSleepClock = uiaxes(app.AvStats_GridLayoutSleepPanel);
            title(app.AvStats_UIAxesSleepClock, '')
            xlabel(app.AvStats_UIAxesSleepClock, '')
            ylabel(app.AvStats_UIAxesSleepClock, '')
            app.AvStats_UIAxesSleepClock.YLim = [0 40];
            app.AvStats_UIAxesSleepClock.ZLim = [0 4];
            app.AvStats_UIAxesSleepClock.XTick = [];
            app.AvStats_UIAxesSleepClock.YTick = [];
            app.AvStats_LightsOutLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_LightsOutLabel.Text = 'Lights Out';
            app.AvStats_SlpOnsetLatLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpOnsetLatLabel.Text = 'SOL';
            app.AvStats_WakeAfterSlpOnsetLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_WakeAfterSlpOnsetLabel.Text = 'WASO';
            app.AvStats_FinAwakeLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_FinAwakeLabel.Text = 'FA';
            app.AvStats_LightsOnLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_LightsOnLabel.Text = 'Lights On';
            app.AvStats_SlpWindowLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpWindowLabel.Text = 'Slp Win';
            app.AvStats_SlpPeriodLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpPeriodLabel.Text = 'Slp Per';
            app.AvStats_SlpTimeLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpTimeLabel.Text = 'Slp Time';
            app.AvStats_SlpEffSlpPerLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpEffSlpPerLabel.Text = 'SE slpPer';
            app.AvStats_SlpEffSlpTimeLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpEffSlpTimeLabel.Text = 'SE slpTime';
            app.AvStats_LegendActigraphyLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_LegendActigraphyLabel.Text = 'Actigraphy';
            app.AvStats_LegendDiaryLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_LegendDiaryLabel.Text = 'Diary';
            app.AvStats_LightsOutActValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_LightsOutActValue.Text = '-';
            app.AvStats_SlpOnsetLatActValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpOnsetLatActValue.Text = '-';
            app.AvStats_WakeAfterSlpOnsetActValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_WakeAfterSlpOnsetActValue.Text = '-';
            app.AvStats_FinAwakeActValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_FinAwakeActValue.Text = '-';
            app.AvStats_LightsOnActValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_LightsOnActValue.Text = '-';
            app.AvStats_SlpWindowActValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpWindowActValue.Text = '-';
            app.AvStats_SlpPeriodActValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpPeriodActValue.Text = '-';
            app.AvStats_SlpTimeActValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpTimeActValue.Text = '-';
            app.AvStats_SlpEffSlpPeriodActValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpEffSlpPeriodActValue.Text = '-';
            app.AvStats_SlpEffSlpTimeActValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpEffSlpTimeActValue.Text = '-';
            app.AvStats_LightsOutDiaryValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_LightsOutDiaryValue.Text = '-';
            app.AvStats_SlpOnsetLatDiaryValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpOnsetLatDiaryValue.Text = '-';
            app.AvStats_WakeAfterSlpOnsetDiaryValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_WakeAfterSlpOnsetDiaryValue.Text = '-';
            app.AvStats_FinAwakeDiaryValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_FinAwakeDiaryValue.Text = '-';
            app.AvStats_LightsOnDiaryValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_LightsOnDiaryValue.Text = '-';
            app.AvStats_SlpWindowDiaryValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpWindowDiaryValue.Text = '-';
            app.AvStats_SlpPeriodDiaryValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpPeriodDiaryValue.Text = '-';
            app.AvStats_SlpTimeDiaryValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpTimeDiaryValue.Text = '-';
            app.AvStats_SlpEffSlpPeriodDiaryValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpEffSlpPeriodDiaryValue.Text = '-';
            app.AvStats_SlpEffSlpTimeDiaryValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_SlpEffSlpTimeDiaryValue.Text = '-';
            app.AvStats_MismatchLabel = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_MismatchLabel.Text = 'Mismatch';
            app.AvStats_MismatchSleepPeriodValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_MismatchSleepPeriodValue.Text = '-';
            app.AvStats_MismatchSleepTimeValue = uilabel(app.AvStats_GridLayoutSleepPanel);
            app.AvStats_MismatchSleepTimeValue.Text = '-';
            app.DailyStatsTab = uitab(app.TabGroup);
            app.DailyStatsTab.Title = 'Daily Stats';
            app.GridLayoutDailyStatsTab = uigridlayout(app.DailyStatsTab);
            app.DailyStats_ReAnalysePanel = uipanel(app.GridLayoutDailyStatsTab);
            app.DailyStats_ReAnalysePanel.Title = ' ';
            app.DailyStats_GridLayoutReAnalysePanel = uigridlayout(app.DailyStats_ReAnalysePanel);
            app.DailyStats_RegenStatsButton = uibutton(app.DailyStats_GridLayoutReAnalysePanel, 'push');
            app.DailyStats_RegenStatsButton.ButtonPushedFcn = createCallbackFcn(app, @RegenStatsButtonPushed, true);
            app.DailyStats_RegenStatsButton.Enable = 'off';
            app.DailyStats_RegenStatsButton.Text = 'Statistics Up-to-Date';
            app.DailyStats_Container = uipanel(app.GridLayoutDailyStatsTab);
            app.GridLayoutDailyStats_Container = uigridlayout(app.DailyStats_Container);
            % Show the figure after all components are created
            app.CicadaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cicada

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CicadaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CicadaUIFigure)
        end
    end
end