classdef about < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AboutUIFigure         matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        AboutPanel            matlab.ui.container.Panel
        GridLayoutAboutPanel  matlab.ui.container.GridLayout
        UIAxesLogo            matlab.ui.control.UIAxes
        VersionLabel          matlab.ui.control.Label
        CopyrightLabel        matlab.ui.control.Label
        LicenseArt1Label      matlab.ui.control.Label
        LicenseArt2Label      matlab.ui.control.Label
        LicenseArt2Label_2    matlab.ui.control.Label
        LicenseArt2Label_3    matlab.ui.control.Label
        LicenseArt2Label_4    matlab.ui.control.Label
        LicenseArt2Label_5    matlab.ui.control.Label
        LicenseArt2Label_6    matlab.ui.control.Label
    end

    
    properties (Access = public)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            % Store main app object
            app.MainApp = MainApp;
            % ---------------------------------------------------------
            % Position the new app in the center of the Cicada app
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.AboutUIFigure.Position(3);
            w = app.AboutUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.AboutUIFigure.Position = [x y h w];
            app.AboutUIFigure.Resize = 'off';
            drawnow;
            % ---------------------------------------------------------
            % Load the Cicada Logo
            app.UIAxesLogo.UserData.ImObj = imagesc(app.UIAxesLogo, 'CData', cicadaLogo());
            app.UIAxesLogo.YDir = 'reverse';
            app.UIAxesLogo.Toolbar = [];
            app.UIAxesLogo.Interactions = [];
            % ---------------------------------------------------------
            % Load the Software Version
            app.VersionLabel.Text = ['Version ', cic_version()];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AboutUIFigure = uifigure('Visible', 'off');
            app.AboutUIFigure.Name = 'About';
            app.GridLayout = uigridlayout(app.AboutUIFigure);
            app.AboutPanel = uipanel(app.GridLayout);
            app.AboutPanel.Title = 'About';
            app.GridLayoutAboutPanel = uigridlayout(app.AboutPanel);
            app.UIAxesLogo = uiaxes(app.GridLayoutAboutPanel);
            title(app.UIAxesLogo, '')
            xlabel(app.UIAxesLogo, '')
            ylabel(app.UIAxesLogo, '')
            app.UIAxesLogo.XTick = [];
            app.UIAxesLogo.YTick = [];
            app.UIAxesLogo.ZTick = [];
            app.UIAxesLogo.Visible = 'off';
            app.VersionLabel = uilabel(app.GridLayoutAboutPanel);
            app.VersionLabel.Text = '';
            app.CopyrightLabel = uilabel(app.GridLayoutAboutPanel);
            app.CopyrightLabel.Text = 'Copyright (c), Rick Wassing, rickwassing@gmail.com';
            app.LicenseArt1Label = uilabel(app.GridLayoutAboutPanel);
            app.LicenseArt1Label.Text = {'Attribution-ShareAlike 4.0 International'; '(CC BY-SA 4.0)'};
            app.LicenseArt2Label = uilabel(app.GridLayoutAboutPanel);
            app.LicenseArt2Label.Text = {'This is a human-readable summary of'; '(and not a substitute for) the license.'; ''; 'You are free to:'; 'Share — copy and redistribute the material in any medium or format'; 'Adapt — remix, transform, and build upon the material'; 'for any purpose, even commercially.'; ''};
            app.LicenseArt2Label_2 = uilabel(app.GridLayoutAboutPanel);
            app.LicenseArt2Label_2.Text = 'You are free to:';
            app.LicenseArt2Label_3 = uilabel(app.GridLayoutAboutPanel);
            app.LicenseArt2Label_3.Text = {'Share — copy and redistribute the material in any medium or format'; 'Adapt — remix, transform, and build upon the material for any '; '               purpose, even commercially.'; ''};
            app.LicenseArt2Label_4 = uilabel(app.GridLayoutAboutPanel);
            app.LicenseArt2Label_4.Text = {'The licensor cannot revoke these freedoms'; 'as long as you follow the license terms.'};
            app.LicenseArt2Label_5 = uilabel(app.GridLayoutAboutPanel);
            app.LicenseArt2Label_5.Text = 'Under the following terms:';
            app.LicenseArt2Label_6 = uilabel(app.GridLayoutAboutPanel);
            app.LicenseArt2Label_6.Text = {'Attribution — You must give appropriate credit, provide a link to'; '     the license, and indicate if changes were made. You may do so in'; '     any reasonable manner, but not in any way that suggests the'; '     licensor endorses you or your use.'; 'ShareAlike — If you remix, transform, or build upon the material, '; '     you must distribute your contributions under the same license as'; '     the original.'; 'No additional restrictions — You may not apply legal terms or'; '     technological measures that legally restrict others from doing'; '     anything the license permits.'};
            % Show the figure after all components are created
            app.AboutUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = about(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AboutUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AboutUIFigure)
        end
    end
end