classdef annotateActivityGGIR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        annotateActivityGGIRUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        CancelButton                  matlab.ui.control.Button
        GoButton                      matlab.ui.control.Button
        ThresholdInactivityPanel      matlab.ui.container.Panel
        GridLayoutThresholdInactivityPanel  matlab.ui.container.GridLayout
        InactTimeLabel                matlab.ui.control.Label
        InactAngleLabel               matlab.ui.control.Label
        InactTimeInput                matlab.ui.control.NumericEditField
        InactAngleInput               matlab.ui.control.NumericEditField
        InactTimeUnitLabel            matlab.ui.control.Label
        InactAngleUnitLabel           matlab.ui.control.Label
        ThresholdActivityPanel        matlab.ui.container.Panel
        GridLayoutThresholdActivityPanel  matlab.ui.container.GridLayout
        ActTimeLabel                  matlab.ui.control.Label
        ActLightLabel                 matlab.ui.control.Label
        ActModerateLabel              matlab.ui.control.Label
        ActVigorousLabel              matlab.ui.control.Label
        ActTimeInput                  matlab.ui.control.NumericEditField
        ActLightInput                 matlab.ui.control.NumericEditField
        ActModerateInput              matlab.ui.control.NumericEditField
        ActVigorousInput              matlab.ui.control.NumericEditField
        ActTimeUnitLabel              matlab.ui.control.Label
        ActLightUnitLabel             matlab.ui.control.Label
        ActModerateUnitLabel          matlab.ui.control.Label
        ActVigorousUnitLabel          matlab.ui.control.Label
        BoutsPanel                    matlab.ui.container.Panel
        GridLayoutBoutsPanel          matlab.ui.container.GridLayout
        BoutCriterionLabel            matlab.ui.control.Label
        ClosedBoutLabel               matlab.ui.control.Label
        BoutMetricLabel               matlab.ui.control.Label
        BoutCriterionInput            matlab.ui.control.NumericEditField
        ClosedBoutInput               matlab.ui.control.CheckBox
        BoutMetricInput               matlab.ui.control.DropDown
        BoutCriterionUnitLabel        matlab.ui.control.Label
        Metric1Description            matlab.ui.control.Label
        Metric2Description            matlab.ui.control.Label
        Metric3Description            matlab.ui.control.Label
        Metric4Description            matlab.ui.control.Label
        Metric6Description            matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            % ---------------------------------------------------------
            % Position the new app in the center of the Cicada app
            app_centerGUI(app.MainApp, app, app.annotateActivityGGIRUIFigure)
            % ---------------------------------------------------------
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            
            app.MainApp.Import.Data.thrInact_time = 5;
            app.MainApp.Import.Data.thrInact_angle = 5;
            app.MainApp.Import.Data.thrAct_time = 10;
            app.MainApp.Import.Data.thrAct_lig = 0.03;
            app.MainApp.Import.Data.thrAct_mod = 0.1;
            app.MainApp.Import.Data.thrAct_vig = 0.4;
            app.MainApp.Import.Data.boutCrit = 80;
            app.MainApp.Import.Data.boutClosed = false;
            app.MainApp.Import.Data.boutMetric = 6;
            
            app.InactTimeInput.Tag = 'thrInact_time';
            app.InactAngleInput.Tag = 'thrInact_angle';
            app.ActTimeInput.Tag = 'thrAct_time';
            app.ActLightInput.Tag = 'thrAct_lig';
            app.ActModerateInput.Tag = 'thrAct_mod';
            app.ActVigorousInput.Tag = 'thrAct_vig';
            app.BoutCriterionInput.Tag = 'boutCrit';
            app.ClosedBoutInput.Tag = 'boutClosed';
            app.BoutMetricInput.Tag = 'boutMetric';

            app.Metric1Description.Layout.Row = [4, 7];
            app.Metric2Description.Layout.Row = [4, 7];
            app.Metric3Description.Layout.Row = [4, 7];
            app.Metric4Description.Layout.Row = [4, 7];
            app.Metric6Description.Layout.Row = [4, 7];
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: ActLightInput, ActModerateInput, 
        % ActTimeInput, ActVigorousInput, BoutCriterionInput, 
        % BoutMetricInput, ClosedBoutInput, InactAngleInput, 
        % InactTimeInput
        function InputValueChanged(app, event)
            value = event.Source.Value;
            tag = event.Source.Tag;
            boutClosedLabels = {'no', 'yes'};
            switch tag
                case 'boutClosed'
                    event.Source.Text = boutClosedLabels{value+1};
                    value = value == 1;
                case 'boutMetric'
                    app.Metric1Description.Visible = 'off';
                    app.Metric2Description.Visible = 'off';
                    app.Metric3Description.Visible = 'off';
                    app.Metric4Description.Visible = 'off';
                    app.Metric6Description.Visible = 'off';
                    app.(['Metric', value 'Description']).Visible = 'on';
                    value = str2double(value);
            end
            app.MainApp.Import.Data.(tag) = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.annotateActivityGGIRUIFigure = uifigure('Visible', 'off');
            app.annotateActivityGGIRUIFigure.Name = 'Annotate Acceleration (GGIR)';
            app.GridLayout = uigridlayout(app.annotateActivityGGIRUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GoButton = uibutton(app.GridLayout, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.ThresholdInactivityPanel = uipanel(app.GridLayout);
            app.ThresholdInactivityPanel.Title = 'Threshold Inactivity';
            app.GridLayoutThresholdInactivityPanel = uigridlayout(app.ThresholdInactivityPanel);
            app.InactTimeLabel = uilabel(app.GridLayoutThresholdInactivityPanel);
            app.InactTimeLabel.Text = 'Time';
            app.InactAngleLabel = uilabel(app.GridLayoutThresholdInactivityPanel);
            app.InactAngleLabel.Text = 'Angle';
            app.InactTimeInput = uieditfield(app.GridLayoutThresholdInactivityPanel, 'numeric');
            app.InactTimeInput.Limits = [1 Inf];
            app.InactTimeInput.ValueDisplayFormat = '%i';
            app.InactTimeInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InactTimeInput.Value = 5;
            app.InactAngleInput = uieditfield(app.GridLayoutThresholdInactivityPanel, 'numeric');
            app.InactAngleInput.Limits = [1 Inf];
            app.InactAngleInput.ValueDisplayFormat = '%i';
            app.InactAngleInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InactAngleInput.Value = 5;
            app.InactTimeUnitLabel = uilabel(app.GridLayoutThresholdInactivityPanel);
            app.InactTimeUnitLabel.Text = 'minutes';
            app.InactAngleUnitLabel = uilabel(app.GridLayoutThresholdInactivityPanel);
            app.InactAngleUnitLabel.Text = 'degrees';
            app.ThresholdActivityPanel = uipanel(app.GridLayout);
            app.ThresholdActivityPanel.Title = 'Threshold Activity';
            app.GridLayoutThresholdActivityPanel = uigridlayout(app.ThresholdActivityPanel);
            app.ActTimeLabel = uilabel(app.GridLayoutThresholdActivityPanel);
            app.ActTimeLabel.Text = 'Time';
            app.ActLightLabel = uilabel(app.GridLayoutThresholdActivityPanel);
            app.ActLightLabel.Text = 'Light';
            app.ActModerateLabel = uilabel(app.GridLayoutThresholdActivityPanel);
            app.ActModerateLabel.Text = 'Moderate';
            app.ActVigorousLabel = uilabel(app.GridLayoutThresholdActivityPanel);
            app.ActVigorousLabel.Text = 'Vigorous';
            app.ActTimeInput = uieditfield(app.GridLayoutThresholdActivityPanel, 'numeric');
            app.ActTimeInput.Limits = [1 Inf];
            app.ActTimeInput.ValueDisplayFormat = '%i';
            app.ActTimeInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ActTimeInput.Value = 10;
            app.ActLightInput = uieditfield(app.GridLayoutThresholdActivityPanel, 'numeric');
            app.ActLightInput.Limits = [0 Inf];
            app.ActLightInput.ValueDisplayFormat = '%0.2f';
            app.ActLightInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ActLightInput.Value = 0.03;
            app.ActModerateInput = uieditfield(app.GridLayoutThresholdActivityPanel, 'numeric');
            app.ActModerateInput.Limits = [0 Inf];
            app.ActModerateInput.ValueDisplayFormat = '%0.2f';
            app.ActModerateInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ActModerateInput.Value = 0.1;
            app.ActVigorousInput = uieditfield(app.GridLayoutThresholdActivityPanel, 'numeric');
            app.ActVigorousInput.Limits = [0 Inf];
            app.ActVigorousInput.ValueDisplayFormat = '%0.2f';
            app.ActVigorousInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ActVigorousInput.Value = 0.4;
            app.ActTimeUnitLabel = uilabel(app.GridLayoutThresholdActivityPanel);
            app.ActTimeUnitLabel.Text = 'minutes';
            app.ActLightUnitLabel = uilabel(app.GridLayoutThresholdActivityPanel);
            app.ActLightUnitLabel.Text = 'g';
            app.ActModerateUnitLabel = uilabel(app.GridLayoutThresholdActivityPanel);
            app.ActModerateUnitLabel.Text = 'g';
            app.ActVigorousUnitLabel = uilabel(app.GridLayoutThresholdActivityPanel);
            app.ActVigorousUnitLabel.Text = 'g';
            app.BoutsPanel = uipanel(app.GridLayout);
            app.BoutsPanel.Title = 'Bouts';
            app.GridLayoutBoutsPanel = uigridlayout(app.BoutsPanel);
            app.BoutCriterionLabel = uilabel(app.GridLayoutBoutsPanel);
            app.BoutCriterionLabel.Text = 'Bout Criterion';
            app.ClosedBoutLabel = uilabel(app.GridLayoutBoutsPanel);
            app.ClosedBoutLabel.Text = 'Closed Bout';
            app.BoutMetricLabel = uilabel(app.GridLayoutBoutsPanel);
            app.BoutMetricLabel.Text = 'Bout Metric';
            app.BoutCriterionInput = uieditfield(app.GridLayoutBoutsPanel, 'numeric');
            app.BoutCriterionInput.ValueDisplayFormat = '%i';
            app.BoutCriterionInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.BoutCriterionInput.Value = 80;
            app.ClosedBoutInput = uicheckbox(app.GridLayoutBoutsPanel);
            app.ClosedBoutInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ClosedBoutInput.Text = 'no';
            app.BoutMetricInput = uidropdown(app.GridLayoutBoutsPanel);
            app.BoutMetricInput.Items = {'1', '2', '3', '4', '6'};
            app.BoutMetricInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.BoutMetricInput.Value = '6';
            app.BoutCriterionUnitLabel = uilabel(app.GridLayoutBoutsPanel);
            app.BoutCriterionUnitLabel.Text = '%';
            app.Metric1Description = uilabel(app.GridLayoutBoutsPanel);
            app.Metric1Description.Visible = 'off';
            app.Metric1Description.Text = {'Metric 1'; 'Find segments of at least `10` minutes '; 'that contain at least `80` % epochs above'; 'the threshold, and start and end with an'; 'epoch above the threshold. Tick `Closed '; 'Bout` to label the entire segment, or'; 'untick to only label epochs above the'; 'threshold (Sabia 2014; da Silva 2014).'};
            app.Metric2Description = uilabel(app.GridLayoutBoutsPanel);
            app.Metric2Description.Visible = 'off';
            app.Metric2Description.Text = {'Metric 2'; 'Find any `10` minute segment that contains'; 'at least `80` % epochs above the threshold.'};
            app.Metric3Description = uilabel(app.GridLayoutBoutsPanel);
            app.Metric3Description.Visible = 'off';
            app.Metric3Description.Text = {'Metric 3'; 'Find segments of at least `10` minutes in'; 'which more than `80` % epochs are above'; 'the threshold and do not contain breaks of '; '1 minute or longer.'};
            app.Metric4Description = uilabel(app.GridLayoutBoutsPanel);
            app.Metric4Description.Visible = 'off';
            app.Metric4Description.Text = {'Metric 4'; 'Find segments of at least `10` minutes in'; 'which more than `80` % epochs are above'; 'the threshold, do not contain breaks of 1'; 'minute or longer, and start and end with an '; 'epoch above the threshold.'};
            app.Metric6Description = uilabel(app.GridLayoutBoutsPanel);
            app.Metric6Description.Text = {'Metric 6'; 'Find segments of at least `10` minutes in'; 'which more than `80` % epochs are above'; 'the threshold, do not contain breaks of 1'; 'minute or longer, and start and end with an '; 'epoch above the threshold. And uses an '; 'optimized algorithm as compared to metric'; '4 (2021).'};
            % Show the figure after all components are created
            app.annotateActivityGGIRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotateActivityGGIR(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.annotateActivityGGIRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.annotateActivityGGIRUIFigure)
        end
    end
end