classdef annotateCounts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        annotateCountsUIFigure         matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CancelButton                   matlab.ui.control.Button
        GoButton                       matlab.ui.control.Button
        WaketresholdDropDownLabel      matlab.ui.control.Label
        WaketresholdDropDown           matlab.ui.control.DropDown
        CustomthresholdEditFieldLabel  matlab.ui.control.Label
        CustomthresholdEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            app.MainApp.Import.Data.wakeThreshold = 'medium';
            % ---------------------------------------------------------
            % Position the new app in the center of the Cicada app
            app_centerGUI(app.MainApp, app, app.annotateCountsUIFigure)
            % ---------------------------------------------------------
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: WaketresholdDropDown
        function WaketresholdDropDownValueChanged(app, event)
            switch app.WaketresholdDropDown.Value
                case 'custom'
                    app.MainApp.Import.Data.wakeThreshold = app.CustomthresholdEditField.Value;
                    app.CustomthresholdEditFieldLabel.Enable = 'on';
                    app.CustomthresholdEditField.Enable = 'on';
                otherwise
                    app.MainApp.Import.Data.wakeThreshold = app.WaketresholdDropDown.Value;
                    app.CustomthresholdEditFieldLabel.Enable = 'off';
                    app.CustomthresholdEditField.Enable = 'off';
                    switch app.WaketresholdDropDown.Value
                        case 'low'
                            app.CustomthresholdEditField.Value = 20;
                        case 'medium'
                            app.CustomthresholdEditField.Value = 40;
                        case 'high'
                            app.CustomthresholdEditField.Value = 80;
                    end
            end
        end

        % Value changed function: CustomthresholdEditField
        function CustomthresholdEditFieldValueChanged(app, event)
            app.MainApp.Import.Data.wakeThreshold = app.CustomthresholdEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.annotateCountsUIFigure = uifigure('Visible', 'off');
            app.annotateCountsUIFigure.Name = 'Annotate Activity Counts';
            app.GridLayout = uigridlayout(app.annotateCountsUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GoButton = uibutton(app.GridLayout, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.WaketresholdDropDownLabel = uilabel(app.GridLayout);
            app.WaketresholdDropDownLabel.Text = 'Wake treshold';
            app.WaketresholdDropDown = uidropdown(app.GridLayout);
            app.WaketresholdDropDown.Items = {'Low (20)', 'Medium (40)', 'High (80)', 'Custom'};
            app.WaketresholdDropDown.ItemsData = {'low', 'medium', 'high', 'custom'};
            app.WaketresholdDropDown.ValueChangedFcn = createCallbackFcn(app, @WaketresholdDropDownValueChanged, true);
            app.WaketresholdDropDown.Value = 'medium';
            app.CustomthresholdEditFieldLabel = uilabel(app.GridLayout);
            app.CustomthresholdEditFieldLabel.Enable = 'off';
            app.CustomthresholdEditFieldLabel.Text = 'Custom threshold';
            app.CustomthresholdEditField = uieditfield(app.GridLayout, 'numeric');
            app.CustomthresholdEditField.Limits = [1 1000];
            app.CustomthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @CustomthresholdEditFieldValueChanged, true);
            app.CustomthresholdEditField.Enable = 'off';
            app.CustomthresholdEditField.Value = 40;
            % Show the figure after all components are created
            app.annotateCountsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotateCounts(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.annotateCountsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.annotateCountsUIFigure)
        end
    end
end