classdef annotateLight < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        annotateLightUIFigure          matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CancelButton                   matlab.ui.control.Button
        GoButton                       matlab.ui.control.Button
        LightMeasurePanel              matlab.ui.container.Panel
        GridLayoutLightMeasurePanel    matlab.ui.container.GridLayout
        LightMeasureInput              matlab.ui.control.DropDown
        ThresholdLightPanel            matlab.ui.container.Panel
        GridLayoutThresholdLightPanel  matlab.ui.container.GridLayout
        ThresholdDimUp                 matlab.ui.control.NumericEditField
        ThresholdModDown               matlab.ui.control.NumericEditField
        ThresholdModUp                 matlab.ui.control.NumericEditField
        ThresholdBrightDown            matlab.ui.control.NumericEditField
        ThresholdDimLabel              matlab.ui.control.Label
        ThresholdModLabel              matlab.ui.control.Label
        ThresholdBrightLabel           matlab.ui.control.Label
        ThresholdDimLessLabel          matlab.ui.control.Label
        ThresholdModDashLabel          matlab.ui.control.Label
        ThresholdBrightOverLabel       matlab.ui.control.Label
        ThresholdDimUnitLabel          matlab.ui.control.Label
        ThresholdModUnitLabel          matlab.ui.control.Label
        ThresholdBrightUnitLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.annotateLightUIFigure.Position(3);
            w = app.annotateLightUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.annotateLightUIFigure.Position = [x y h w];
            app.annotateLightUIFigure.Resize = 'off';
            
            % Set the input
            Items = fieldnames(app.MainApp.ACT.data.light);
            app.LightMeasureInput.Items = Items;
            app.LightMeasureInput.Value = Items{1};
            
            % Set the default threshold
            app.MainApp.Import.Data.metric = Items{1};
            app.MainApp.Import.Data.threshold = [100, 1000];
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: ThresholdDimUp
        function ThresholdDimUpValueChanged(app, event)
            app.MainApp.Import.Data.threshold(1) = app.ThresholdDimUp.Value;
            app.ThresholdModDown.Value = app.ThresholdDimUp.Value;
        end

        % Value changed function: ThresholdModDown
        function ThresholdModDownValueChanged(app, event)
            app.MainApp.Import.Data.threshold(1) = app.ThresholdModDown.Value;
            app.ThresholdDimUp.Value = app.ThresholdModDown.Value;
        end

        % Value changed function: ThresholdModUp
        function ThresholdModUpValueChanged(app, event)
            app.MainApp.Import.Data.threshold(2) = app.ThresholdModUp.Value;
            app.ThresholdBrightDown.Value = app.ThresholdModUp.Value;
        end

        % Value changed function: ThresholdBrightDown
        function ThresholdBrightDownValueChanged(app, event)
            app.MainApp.Import.Data.threshold(2) = app.ThresholdBrightDown.Value;
            app.ThresholdModUp.Value = app.ThresholdBrightDown.Value;
        end

        % Value changed function: LightMeasureInput
        function LightMeasureInputValueChanged(app, event)
            app.MainApp.Import.Data.metric = app.LightMeasureInput.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.annotateLightUIFigure = uifigure('Visible', 'off');
            app.annotateLightUIFigure.Name = 'Annotate Light';
            app.GridLayout = uigridlayout(app.annotateLightUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GoButton = uibutton(app.GridLayout, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.LightMeasurePanel = uipanel(app.GridLayout);
            app.LightMeasurePanel.Title = 'Light Measure';
            app.GridLayoutLightMeasurePanel = uigridlayout(app.LightMeasurePanel);
            app.LightMeasureInput = uidropdown(app.GridLayoutLightMeasurePanel);
            app.LightMeasureInput.Items = {'Select'};
            app.LightMeasureInput.ValueChangedFcn = createCallbackFcn(app, @LightMeasureInputValueChanged, true);
            app.LightMeasureInput.Value = 'Select';
            app.ThresholdLightPanel = uipanel(app.GridLayout);
            app.ThresholdLightPanel.Title = 'Threshold Light';
            app.GridLayoutThresholdLightPanel = uigridlayout(app.ThresholdLightPanel);
            app.ThresholdDimUp = uieditfield(app.GridLayoutThresholdLightPanel, 'numeric');
            app.ThresholdDimUp.Limits = [0 Inf];
            app.ThresholdDimUp.ValueDisplayFormat = '%.0f';
            app.ThresholdDimUp.ValueChangedFcn = createCallbackFcn(app, @ThresholdDimUpValueChanged, true);
            app.ThresholdDimUp.Value = 100;
            app.ThresholdModDown = uieditfield(app.GridLayoutThresholdLightPanel, 'numeric');
            app.ThresholdModDown.Limits = [0 Inf];
            app.ThresholdModDown.ValueDisplayFormat = '%.0f';
            app.ThresholdModDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdModDownValueChanged, true);
            app.ThresholdModDown.Value = 100;
            app.ThresholdModUp = uieditfield(app.GridLayoutThresholdLightPanel, 'numeric');
            app.ThresholdModUp.Limits = [0 Inf];
            app.ThresholdModUp.ValueDisplayFormat = '%.0f';
            app.ThresholdModUp.ValueChangedFcn = createCallbackFcn(app, @ThresholdModUpValueChanged, true);
            app.ThresholdModUp.Value = 1000;
            app.ThresholdBrightDown = uieditfield(app.GridLayoutThresholdLightPanel, 'numeric');
            app.ThresholdBrightDown.Limits = [0 Inf];
            app.ThresholdBrightDown.ValueDisplayFormat = '%.0f';
            app.ThresholdBrightDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdBrightDownValueChanged, true);
            app.ThresholdBrightDown.Value = 1000;
            app.ThresholdDimLabel = uilabel(app.GridLayoutThresholdLightPanel);
            app.ThresholdDimLabel.Text = 'Dim';
            app.ThresholdModLabel = uilabel(app.GridLayoutThresholdLightPanel);
            app.ThresholdModLabel.Text = 'Moderate';
            app.ThresholdBrightLabel = uilabel(app.GridLayoutThresholdLightPanel);
            app.ThresholdBrightLabel.Text = 'Bright';
            app.ThresholdDimLessLabel = uilabel(app.GridLayoutThresholdLightPanel);
            app.ThresholdDimLessLabel.Text = '<';
            app.ThresholdModDashLabel = uilabel(app.GridLayoutThresholdLightPanel);
            app.ThresholdModDashLabel.Text = '-';
            app.ThresholdBrightOverLabel = uilabel(app.GridLayoutThresholdLightPanel);
            app.ThresholdBrightOverLabel.Text = '>=';
            app.ThresholdDimUnitLabel = uilabel(app.GridLayoutThresholdLightPanel);
            app.ThresholdDimUnitLabel.Text = 'lux';
            app.ThresholdModUnitLabel = uilabel(app.GridLayoutThresholdLightPanel);
            app.ThresholdModUnitLabel.Text = 'lux';
            app.ThresholdBrightUnitLabel = uilabel(app.GridLayoutThresholdLightPanel);
            app.ThresholdBrightUnitLabel.Text = 'lux';
            % Show the figure after all components are created
            app.annotateLightUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotateLight(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.annotateLightUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.annotateLightUIFigure)
        end
    end
end