classdef changeEpochLength < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChangeEpochLengthUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        CancelButton               matlab.ui.control.Button
        ChangeButton               matlab.ui.control.Button
        SelectNewEpochLengthPanel  matlab.ui.container.Panel
        GridLayoutSelectNewTimezonePanel  matlab.ui.container.GridLayout
        NewEpochLengthLabel        matlab.ui.control.Label
        NewEpochLengthInput        matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        MainApp;
        OriginalValue;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.ChangeEpochLengthUIFigure.Position(3);
            w = app.ChangeEpochLengthUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.ChangeEpochLengthUIFigure.Position = [x y h w];
            app.ChangeEpochLengthUIFigure.Resize = 'off';
            
            app.NewEpochLengthInput.Value = num2str(app.MainApp.ACT.epoch);
            app.OriginalValue = app.MainApp.ACT.epoch;

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: NewEpochLengthInput
        function NewEpochLengthInputValueChanged(app, event)
            app.MainApp.Import.Data.epoch = str2double(app.NewEpochLengthInput.Value);
            if app.MainApp.Import.Data.epoch == app.OriginalValue
                app.ChangeButton.Enable = 'off';
            else
                app.ChangeButton.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChangeEpochLengthUIFigure = uifigure('Visible', 'off');
            app.ChangeEpochLengthUIFigure.Name = 'Change Epoch Length';
            app.GridLayout = uigridlayout(app.ChangeEpochLengthUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ChangeButton = uibutton(app.GridLayout, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Enable = 'off';
            app.ChangeButton.Text = 'Change';
            app.SelectNewEpochLengthPanel = uipanel(app.GridLayout);
            app.SelectNewEpochLengthPanel.Title = 'Select New Epoch Length';
            app.GridLayoutSelectNewTimezonePanel = uigridlayout(app.SelectNewEpochLengthPanel);
            app.NewEpochLengthLabel = uilabel(app.GridLayoutSelectNewTimezonePanel);
            app.NewEpochLengthLabel.Text = 'New Epoch Length';
            app.NewEpochLengthInput = uilistbox(app.GridLayoutSelectNewTimezonePanel);
            app.NewEpochLengthInput.Items = {'5 seconds', '10 seconds', '15 seconds', '20 seconds', '30 seconds', '1 minute', '2 minutes'};
            app.NewEpochLengthInput.ItemsData = {'5', '10', '15', '20', '30', '60', '120'};
            app.NewEpochLengthInput.ValueChangedFcn = createCallbackFcn(app, @NewEpochLengthInputValueChanged, true);
            app.NewEpochLengthInput.Value = '5';
            % Show the figure after all components are created
            app.ChangeEpochLengthUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = changeEpochLength(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChangeEpochLengthUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChangeEpochLengthUIFigure)
        end
    end
end