classdef changeTimeZone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChangeTimeZoneUIFigure  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        CancelButton            matlab.ui.control.Button
        ChangeButton            matlab.ui.control.Button
        SelectNewTimeZonePanel  matlab.ui.container.Panel
        GridLayoutSelectNewTimezonePanel  matlab.ui.container.GridLayout
        NewTimeZoneLabel        matlab.ui.control.Label
        NewStartTimeLabel       matlab.ui.control.Label
        NewStartTimeValue       matlab.ui.control.Label
        NewTimeZoneInput        matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        MainApp;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.ChangeTimeZoneUIFigure.Position(3);
            w = app.ChangeTimeZoneUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.ChangeTimeZoneUIFigure.Position = [x y h w];
            app.ChangeTimeZoneUIFigure.Resize = 'off';
            
            if ~isfield(app.MainApp.ACT, 'timezone')
                app.MainApp.ACT.timezone = '+00:00';
            end
            if isempty(app.MainApp.ACT.timezone)
                app.MainApp.ACT.timezone = '+00:00';
            end
            
            app.NewTimeZoneInput.Value = app.MainApp.ACT.timezone;
            app.NewStartTimeValue.Text = datestr(app.MainApp.ACT.xmin, 'dd/mm/yyyy HH:MM');
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: NewTimeZoneInput
        function NewTimeZoneInputValueChanged(app, event)
            if ~isfield(app.MainApp.Import, 'Data')
                app.ChangeButton.Enable = 'on';
            end
            app.MainApp.Import.Data.timezone = app.NewTimeZoneInput.Value;
            t = datetime(app.MainApp.ACT.xmin, 'ConvertFrom', 'datenum', 'TimeZone', app.MainApp.ACT.timezone);
            t.TimeZone = app.MainApp.Import.Data.timezone;
            app.NewStartTimeValue.Text = datestr(t, 'dd/mm/yyyy HH:MM');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChangeTimeZoneUIFigure = uifigure('Visible', 'off');
            app.ChangeTimeZoneUIFigure.Name = 'Change Time Zone';
            app.GridLayout = uigridlayout(app.ChangeTimeZoneUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ChangeButton = uibutton(app.GridLayout, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Enable = 'off';
            app.ChangeButton.Text = 'Change';
            app.SelectNewTimeZonePanel = uipanel(app.GridLayout);
            app.SelectNewTimeZonePanel.Title = 'Select New Time Zone';
            app.GridLayoutSelectNewTimezonePanel = uigridlayout(app.SelectNewTimeZonePanel);
            app.NewTimeZoneLabel = uilabel(app.GridLayoutSelectNewTimezonePanel);
            app.NewTimeZoneLabel.Text = 'New Time Zone';
            app.NewStartTimeLabel = uilabel(app.GridLayoutSelectNewTimezonePanel);
            app.NewStartTimeLabel.Text = 'New Start Time';
            app.NewStartTimeValue = uilabel(app.GridLayoutSelectNewTimezonePanel);
            app.NewStartTimeValue.Text = '';
            app.NewTimeZoneInput = uilistbox(app.GridLayoutSelectNewTimezonePanel);
            app.NewTimeZoneInput.Items = {'+14:00', '+13:00', '+12:45', '+12:00', '+11:00', '+10:30', '+10:00', '+09:30', '+09:00', '+08:45', '+08:00', '+07:00', '+06:30', '+06:00', '+05:45', '+05:30', '+05:00', '+04:30', '+04:00', '+03:00', '+02:00', '+01:00', '+00:00', '-01:00', '-02:00', '-02:30', '-03:00', '-04:00', '-05:00', '-06:00', '-07:00', '-08:00', '-09:00', '-09:30', '-10:00', '-11:00', '-12:00'};
            app.NewTimeZoneInput.ValueChangedFcn = createCallbackFcn(app, @NewTimeZoneInputValueChanged, true);
            app.NewTimeZoneInput.Value = '+14:00';
            % Show the figure after all components are created
            app.ChangeTimeZoneUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = changeTimeZone(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChangeTimeZoneUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChangeTimeZoneUIFigure)
        end
    end
end