classdef createDailyEvent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateDailyEventUIFigure       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CancelButton                   matlab.ui.control.Button
        CreateButton                   matlab.ui.control.Button
        CreateNewEventPanel            matlab.ui.container.Panel
        GridLayoutCreateNewEventPanel  matlab.ui.container.GridLayout
        NewEventLabel                  matlab.ui.control.Label
        EventLabelInput                matlab.ui.control.EditField
        MonButton                      matlab.ui.control.StateButton
        TueButton                      matlab.ui.control.StateButton
        WedButton                      matlab.ui.control.StateButton
        ThuButton                      matlab.ui.control.StateButton
        FriButton                      matlab.ui.control.StateButton
        SatButton                      matlab.ui.control.StateButton
        SunButton                      matlab.ui.control.StateButton
        OnsetLabel                     matlab.ui.control.Label
        DelayPanel                     matlab.ui.container.Panel
        GridLayoutDelayPanel           matlab.ui.container.GridLayout
        OnsetHourUpButton              matlab.ui.control.Button
        OnsetMinuteUpButton            matlab.ui.control.Button
        OnsetHourDownButton            matlab.ui.control.Button
        OnsetMinuteDownButton          matlab.ui.control.Button
        OnsetDayHourSepLabel           matlab.ui.control.Label
        OnsetHourInput                 matlab.ui.control.NumericEditField
        OnsetMinuteInput               matlab.ui.control.NumericEditField
        HHMMLabel                      matlab.ui.control.Label
        DurationLabel                  matlab.ui.control.Label
        DurationPanel                  matlab.ui.container.Panel
        GridLayoutDurationPanel        matlab.ui.container.GridLayout
        DurationHourUpButton           matlab.ui.control.Button
        DurationMinuteUpButton         matlab.ui.control.Button
        DurationHourDownButton         matlab.ui.control.Button
        DurationMinuteDownButton       matlab.ui.control.Button
        DurationHourLabel              matlab.ui.control.Label
        DurationHourInput              matlab.ui.control.NumericEditField
        DurationMinuteInput            matlab.ui.control.NumericEditField
        DurationMinuteLabel            matlab.ui.control.Label
        ProprietaryPanel               matlab.ui.container.Panel
        GridLayoutProprietaryPanel     matlab.ui.container.GridLayout
        DismissProprietaryPanel        matlab.ui.control.Button
        ProprietaryLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
        function enableDisableButtons(app)
            if app.MainApp.Import.Data.onset + 60 >= 24*60
                app.OnsetHourUpButton.Enable = 'off';
            else
                app.OnsetHourUpButton.Enable = 'on';
            end
            
            if app.MainApp.Import.Data.onset + 1 >= 24*60
                app.OnsetMinuteUpButton.Enable = 'off';
            else
                app.OnsetMinuteUpButton.Enable = 'on';
            end
            
            if app.MainApp.Import.Data.onset - 60 < 0
                app.OnsetHourDownButton.Enable = 'off';
            else
                app.OnsetHourDownButton.Enable = 'on';
            end
            
            if app.MainApp.Import.Data.onset - 1 < 0
                app.OnsetMinuteDownButton.Enable = 'off';
            else
                app.OnsetMinuteDownButton.Enable = 'on';
            end
            
            if app.MainApp.Import.Data.duration - 60 < 1
                app.DurationHourDownButton.Enable = 'off';
            else
                app.DurationHourDownButton.Enable = 'on';
            end
            
            if app.MainApp.Import.Data.duration - 1 < 1
                app.DurationMinuteDownButton.Enable = 'off';
            else
                app.DurationMinuteDownButton.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.CreateDailyEventUIFigure.Position(3);
            w = app.CreateDailyEventUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.CreateDailyEventUIFigure.Position = [x y h w];
            app.CreateDailyEventUIFigure.Resize = 'off';
            
            % Set the UserData of the inputs
            app.OnsetHourInput.UserData.fname = 'onset';
            app.OnsetMinuteInput.UserData.fname = 'onset';
            app.DurationHourInput.UserData.fname = 'duration';
            app.DurationMinuteInput.UserData.fname = 'duration';
            
            % Set the UserData of the buttons
            app.OnsetHourUpButton.UserData.fname = 'onset';
            app.OnsetHourUpButton.UserData.step = 60;
            app.OnsetHourUpButton.UserData.direction = 1;
            app.OnsetMinuteUpButton.UserData.fname = 'onset';
            app.OnsetMinuteUpButton.UserData.step = 1;
            app.OnsetMinuteUpButton.UserData.direction = 1;
            
            app.OnsetHourDownButton.UserData.fname = 'onset';
            app.OnsetHourDownButton.UserData.step = 60;
            app.OnsetHourDownButton.UserData.direction = -1;
            app.OnsetMinuteDownButton.UserData.fname = 'onset';
            app.OnsetMinuteDownButton.UserData.step = 1;
            app.OnsetMinuteDownButton.UserData.direction = -1;
            
            app.DurationHourUpButton.UserData.fname = 'duration';
            app.DurationHourUpButton.UserData.step = 60;
            app.DurationHourUpButton.UserData.direction = 1;
            app.DurationMinuteUpButton.UserData.fname = 'duration';
            app.DurationMinuteUpButton.UserData.step = 1;
            app.DurationMinuteUpButton.UserData.direction = 1;
            
            app.DurationHourDownButton.UserData.fname = 'duration';
            app.DurationHourDownButton.UserData.step = 60;
            app.DurationHourDownButton.UserData.direction = -1;
            app.DurationMinuteDownButton.UserData.fname = 'duration';
            app.DurationMinuteDownButton.UserData.step = 1;
            app.DurationMinuteDownButton.UserData.direction = -1;
            
            app.MainApp.Import.Data.onset = 12*60;
            app.MainApp.Import.Data.duration = 60;
            app.MainApp.Import.Data.label = 'Custom Event';
            
            enableDisableButtons(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data.label = app.EventLabelInput.Value;
            app.MainApp.Import.Data.onset = app.MainApp.Import.Data.onset/1440; % onset in days
            app.MainApp.Import.Data.duration = app.MainApp.Import.Data.duration/60; % duration in hours
            app.MainApp.Import.Data.days = [ ...
                app.MonButton.Value, ...
                app.TueButton.Value, ...
                app.WedButton.Value, ...
                app.ThuButton.Value, ...
                app.FriButton.Value, ...
                app.SatButton.Value, ...
                app.SunButton.Value ...
                ];
            
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: DurationHourDownButton, 
        % DurationHourUpButton, DurationMinuteDownButton, 
        % DurationMinuteUpButton, OnsetHourDownButton, 
        % OnsetHourUpButton, OnsetMinuteDownButton, 
        % OnsetMinuteUpButton
        function ButtonPushed(app, event)
            % Dismiss the error panel
            app.ProprietaryPanel.Visible = 'off';
            
            fname = event.Source.UserData.fname;
            step = event.Source.UserData.step;
            dir = event.Source.UserData.direction;
            app.MainApp.Import.Data.(fname) = app.MainApp.Import.Data.(fname) + dir*step;
            HH = floor(app.MainApp.Import.Data.(fname)/60);
            MM = app.MainApp.Import.Data.(fname) - HH*60;
            % Update the input fields
            switch fname
                case 'onset'
                    app.OnsetHourInput.Value = HH;
                    app.OnsetMinuteInput.Value = MM;
                case 'duration'
                    app.DurationHourInput.Value = HH;
                    app.DurationMinuteInput.Value = MM;
            end
            
            enableDisableButtons(app);
        end

        % Value changed function: DurationHourInput, 
        % DurationMinuteInput, OnsetHourInput, OnsetMinuteInput
        function InputValueChanged(app, event)
            % Dismiss the error panel
            app.ProprietaryPanel.Visible = 'off';
            
            fname = event.Source.UserData.fname;
            switch fname
                case 'onset'
                    HH = app.OnsetHourInput.Value;
                    MM = app.OnsetMinuteInput.Value;
                case 'duration'
                    HH = app.DurationHourInput.Value;
                    MM = app.DurationMinuteInput.Value;
            end
            app.MainApp.Import.Data.(fname) = HH*60 + MM;
            enableDisableButtons(app);
        end

        % Value changed function: EventLabelInput
        function EventLabelInputValueChanged(app, event)
            % Check that the User did not specify a proprietary event label
            if checkProprietaryEventLabel(app.EventLabelInput.Value)
                app.ProprietaryPanel.Visible = 'on';
                app.EventLabelInput.Value = event.PreviousValue;
            else
                app.ProprietaryPanel.Visible = 'off';
                app.MainApp.Import.Data.label = event.Source.Value;
            end
        end

        % Value changed function: FriButton, MonButton, SatButton, 
        % SunButton, ThuButton, TueButton, WedButton
        function DayButtonValueChanged(app, event)
            % Dismiss the error panel
            app.ProprietaryPanel.Visible = 'off';
                
            if event.Source.Value == 1
                event.Source.BackgroundColor = [0.1765, 0.3725, 0.6745];
                event.Source.FontColor = [1, 1, 1];
            else
                event.Source.BackgroundColor = [0.42, 0.46, 0.49];
                event.Source.FontColor = [0.85, 0.85, 0.85];
            end
        end

        % Button pushed function: DismissProprietaryPanel
        function DismissProprietaryPanelButtonPushed(app, event)
            app.ProprietaryPanel.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateDailyEventUIFigure = uifigure('Visible', 'off');
            app.CreateDailyEventUIFigure.Name = 'Create Daily Event';
            app.GridLayout = uigridlayout(app.CreateDailyEventUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CreateButton = uibutton(app.GridLayout, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CreateNewEventPanel = uipanel(app.GridLayout);
            app.CreateNewEventPanel.Title = 'Create New Daily Event';
            app.GridLayoutCreateNewEventPanel = uigridlayout(app.CreateNewEventPanel);
            app.NewEventLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.NewEventLabel.Text = 'New Event Label';
            app.EventLabelInput = uieditfield(app.GridLayoutCreateNewEventPanel, 'text');
            app.EventLabelInput.ValueChangedFcn = createCallbackFcn(app, @EventLabelInputValueChanged, true);
            app.EventLabelInput.Value = 'Custom Event';
            app.MonButton = uibutton(app.GridLayoutCreateNewEventPanel, 'state');
            app.MonButton.ValueChangedFcn = createCallbackFcn(app, @DayButtonValueChanged, true);
            app.MonButton.Text = 'M';
            app.MonButton.Value = true;
            app.TueButton = uibutton(app.GridLayoutCreateNewEventPanel, 'state');
            app.TueButton.ValueChangedFcn = createCallbackFcn(app, @DayButtonValueChanged, true);
            app.TueButton.Text = 'T';
            app.TueButton.Value = true;
            app.WedButton = uibutton(app.GridLayoutCreateNewEventPanel, 'state');
            app.WedButton.ValueChangedFcn = createCallbackFcn(app, @DayButtonValueChanged, true);
            app.WedButton.Text = 'W';
            app.WedButton.Value = true;
            app.ThuButton = uibutton(app.GridLayoutCreateNewEventPanel, 'state');
            app.ThuButton.ValueChangedFcn = createCallbackFcn(app, @DayButtonValueChanged, true);
            app.ThuButton.Text = 'T';
            app.ThuButton.Value = true;
            app.FriButton = uibutton(app.GridLayoutCreateNewEventPanel, 'state');
            app.FriButton.ValueChangedFcn = createCallbackFcn(app, @DayButtonValueChanged, true);
            app.FriButton.Text = 'F';
            app.FriButton.Value = true;
            app.SatButton = uibutton(app.GridLayoutCreateNewEventPanel, 'state');
            app.SatButton.ValueChangedFcn = createCallbackFcn(app, @DayButtonValueChanged, true);
            app.SatButton.Text = 'S';
            app.SatButton.Value = true;
            app.SunButton = uibutton(app.GridLayoutCreateNewEventPanel, 'state');
            app.SunButton.ValueChangedFcn = createCallbackFcn(app, @DayButtonValueChanged, true);
            app.SunButton.Text = 'S';
            app.SunButton.Value = true;
            app.OnsetLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.OnsetLabel.Text = 'Onset';
            app.DelayPanel = uipanel(app.GridLayoutCreateNewEventPanel);
            app.GridLayoutDelayPanel = uigridlayout(app.DelayPanel);
            app.OnsetHourUpButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.OnsetHourUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.OnsetHourUpButton.Text = '^';
            app.OnsetMinuteUpButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.OnsetMinuteUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.OnsetMinuteUpButton.Text = '^';
            app.OnsetHourDownButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.OnsetHourDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.OnsetHourDownButton.Text = 'v';
            app.OnsetMinuteDownButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.OnsetMinuteDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.OnsetMinuteDownButton.Text = 'v';
            app.OnsetDayHourSepLabel = uilabel(app.GridLayoutDelayPanel);
            app.OnsetDayHourSepLabel.Text = ':';
            app.OnsetHourInput = uieditfield(app.GridLayoutDelayPanel, 'numeric');
            app.OnsetHourInput.Limits = [0 23];
            app.OnsetHourInput.RoundFractionalValues = 'on';
            app.OnsetHourInput.ValueDisplayFormat = '%i';
            app.OnsetHourInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.OnsetHourInput.Value = 12;
            app.OnsetMinuteInput = uieditfield(app.GridLayoutDelayPanel, 'numeric');
            app.OnsetMinuteInput.Limits = [0 59];
            app.OnsetMinuteInput.RoundFractionalValues = 'on';
            app.OnsetMinuteInput.ValueDisplayFormat = '%i';
            app.OnsetMinuteInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.HHMMLabel = uilabel(app.GridLayoutDelayPanel);
            app.HHMMLabel.Text = '(HH:MM)';
            app.DurationLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.DurationLabel.Text = 'Duration';
            app.DurationPanel = uipanel(app.GridLayoutCreateNewEventPanel);
            app.GridLayoutDurationPanel = uigridlayout(app.DurationPanel);
            app.DurationHourUpButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationHourUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationHourUpButton.Text = '^';
            app.DurationMinuteUpButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationMinuteUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationMinuteUpButton.Text = '^';
            app.DurationHourDownButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationHourDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationHourDownButton.Text = 'v';
            app.DurationMinuteDownButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationMinuteDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationMinuteDownButton.Text = 'v';
            app.DurationHourLabel = uilabel(app.GridLayoutDurationPanel);
            app.DurationHourLabel.Text = 'h';
            app.DurationHourInput = uieditfield(app.GridLayoutDurationPanel, 'numeric');
            app.DurationHourInput.Limits = [0 Inf];
            app.DurationHourInput.RoundFractionalValues = 'on';
            app.DurationHourInput.ValueDisplayFormat = '%i';
            app.DurationHourInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DurationHourInput.Value = 1;
            app.DurationMinuteInput = uieditfield(app.GridLayoutDurationPanel, 'numeric');
            app.DurationMinuteInput.Limits = [0 Inf];
            app.DurationMinuteInput.RoundFractionalValues = 'on';
            app.DurationMinuteInput.ValueDisplayFormat = '%i';
            app.DurationMinuteInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DurationMinuteLabel = uilabel(app.GridLayoutDurationPanel);
            app.DurationMinuteLabel.Text = 'm';
            app.ProprietaryPanel = uipanel(app.CreateDailyEventUIFigure);
            app.ProprietaryPanel.Visible = 'off';
            app.GridLayoutProprietaryPanel = uigridlayout(app.ProprietaryPanel);
            app.DismissProprietaryPanel = uibutton(app.GridLayoutProprietaryPanel, 'push');
            app.DismissProprietaryPanel.ButtonPushedFcn = createCallbackFcn(app, @DismissProprietaryPanelButtonPushed, true);
            app.DismissProprietaryPanel.Text = 'ok';
            app.ProprietaryLabel = uilabel(app.GridLayoutProprietaryPanel);
            app.ProprietaryLabel.Text = {'Can''t use Cicada''s'; 'proprietary labels'};
            % Show the figure after all components are created
            app.CreateDailyEventUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createDailyEvent(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateDailyEventUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateDailyEventUIFigure)
        end
    end
end