classdef createEvent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateEventUIFigure            matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CancelButton                   matlab.ui.control.Button
        CreateButton                   matlab.ui.control.Button
        CreateNewEventPanel            matlab.ui.container.Panel
        GridLayoutCreateNewEventPanel  matlab.ui.container.GridLayout
        EventLabelLabel                matlab.ui.control.Label
        EventLabelInput                matlab.ui.control.DropDown
        CustomEventLabelInput          matlab.ui.control.EditField
        DataPanel                      matlab.ui.container.Panel
    end

    
    properties (Access = public)
        MainApp;
        Import;
    end
    
    methods (Access = private)
        % add your functions here
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.CreateEventUIFigure.Position(3);
            w = app.CreateEventUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.CreateEventUIFigure.Position = [x y h w];
            app.CreateEventUIFigure.Resize = 'off';
            
            idx = strcmpi(app.MainApp.ACT.analysis.events.type, 'customEvent');
            eventLabels = unique(app.MainApp.ACT.analysis.events.label(idx));
            if iscolumn(eventLabels)
                eventLabels = eventLabels';
            end
            app.EventLabelInput.Items = [app.EventLabelInput.Items, eventLabels];
            app.EventLabelInput.ItemsData = [app.EventLabelInput.ItemsData, eventLabels];
            
            % Show data excerpt in panel
            app_constructDataExcerpt(app, app.DataPanel, app.MainApp.Interval.Position(1), app.MainApp.Interval.Position(2))
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            switch app.EventLabelInput.Value
                case 'custom'
                    app.MainApp.Import.Data.label = app.CustomEventLabelInput.Value;
                    app.MainApp.Import.Data.type = 'customEvent';
                case {'reject', 'sleepWindow'}
                    app.MainApp.Import.Data.label = app.EventLabelInput.Value;
                    app.MainApp.Import.Data.type = 'manual';
                otherwise
                    app.MainApp.Import.Data.label = app.EventLabelInput.Value;
                    app.MainApp.Import.Data.type = 'customEvent';
            end
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: EventLabelInput
        function EventLabelInputValueChanged(app, event)
            switch event.Source.Value
                case 'select'
                    app.CustomEventLabelInput.Visible = 'off';
                    app.CreateButton.Enable = 'off';
                case 'custom'
                    app.CustomEventLabelInput.Visible = 'on';
                    app.CreateButton.Enable = 'on';
                otherwise
                    app.CustomEventLabelInput.Visible = 'off';
                    app.CreateButton.Enable = 'on';
            end
        end

        % Value changed function: CustomEventLabelInput
        function CustomEventLabelInputValueChanged(app, event)
            % Check that the User did not specify a proprietary event label
            if checkProprietaryEventLabel(app.CustomEventLabelInput.Value)
                sel = uiconfirm(app.CreateEventUIFigure, 'Sorry, you can''t use an event label that is already used by Cicada.', 'Notification', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'info');
                app.CustomEventLabelInput.Value = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateEventUIFigure = uifigure('Visible', 'off');
            app.CreateEventUIFigure.Name = 'Create New Event';
            app.GridLayout = uigridlayout(app.CreateEventUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CreateButton = uibutton(app.GridLayout, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Enable = 'off';
            app.CreateButton.Text = 'Create';
            app.CreateNewEventPanel = uipanel(app.GridLayout);
            app.CreateNewEventPanel.Title = 'Create New Event';
            app.GridLayoutCreateNewEventPanel = uigridlayout(app.CreateNewEventPanel);
            app.EventLabelLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.EventLabelLabel.Text = 'Event Label';
            app.EventLabelInput = uidropdown(app.GridLayoutCreateNewEventPanel);
            app.EventLabelInput.Items = {'Select...', 'Custom Label', 'Reject', 'Sleep Window'};
            app.EventLabelInput.ItemsData = {'select', 'custom', 'reject', 'sleepWindow'};
            app.EventLabelInput.ValueChangedFcn = createCallbackFcn(app, @EventLabelInputValueChanged, true);
            app.EventLabelInput.Value = 'select';
            app.CustomEventLabelInput = uieditfield(app.GridLayoutCreateNewEventPanel, 'text');
            app.CustomEventLabelInput.ValueChangedFcn = createCallbackFcn(app, @CustomEventLabelInputValueChanged, true);
            app.CustomEventLabelInput.Visible = 'off';
            app.CustomEventLabelInput.Value = 'Custom Event';
            app.DataPanel = uipanel(app.GridLayout);
            app.DataPanel.Title = 'Mon 03/06/2019 HH:MM - Mon 03/06/2019 HH:MM';
            % Show the figure after all components are created
            app.CreateEventUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createEvent(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateEventUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateEventUIFigure)
        end
    end
end