classdef createRelativeEvent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CreateRelativeEventUIFigure    matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        CancelButton                   matlab.ui.control.Button
        CreateButton                   matlab.ui.control.Button
        CreateNewEventPanel            matlab.ui.container.Panel
        GridLayoutCreateNewEventPanel  matlab.ui.container.GridLayout
        RefEventLabel                  matlab.ui.control.Label
        RefEventTypeLabel              matlab.ui.control.Label
        RelativeToLabel                matlab.ui.control.Label
        NewEventLabel                  matlab.ui.control.Label
        DelayLabel                     matlab.ui.control.Label
        DurationLabel                  matlab.ui.control.Label
        ReferenceEventLabelInput       matlab.ui.control.DropDown
        ReferenceEventTypeInput        matlab.ui.control.DropDown
        RelativeButtonGroup            matlab.ui.container.ButtonGroup
        OnsetButton                    matlab.ui.control.RadioButton
        OffsetButton                   matlab.ui.control.RadioButton
        EventLabelInput                matlab.ui.control.EditField
        DelayPanel                     matlab.ui.container.Panel
        GridLayoutDelayPanel           matlab.ui.container.GridLayout
        DelayDayUpButton               matlab.ui.control.Button
        DelayHourUpButton              matlab.ui.control.Button
        DelayMinuteUpButton            matlab.ui.control.Button
        DelayDayDownButton             matlab.ui.control.Button
        DelayHourDownButton            matlab.ui.control.Button
        DelayMinuteDownButton          matlab.ui.control.Button
        DelayDayUnitLabel              matlab.ui.control.Label
        DelayDayHourSepLabel           matlab.ui.control.Label
        DelayDayInput                  matlab.ui.control.NumericEditField
        DelayHourInput                 matlab.ui.control.NumericEditField
        DelayMinuteInput               matlab.ui.control.NumericEditField
        DurationPanel                  matlab.ui.container.Panel
        GridLayoutDurationPanel        matlab.ui.container.GridLayout
        DurationDayUpButton            matlab.ui.control.Button
        DurationHourUpButton           matlab.ui.control.Button
        DurationMinuteUpButton         matlab.ui.control.Button
        DurationDayDownButton          matlab.ui.control.Button
        DurationHourDownButton         matlab.ui.control.Button
        DurationMinuteDownButton       matlab.ui.control.Button
        DurationDayUnitLabel           matlab.ui.control.Label
        DurationDayHourSepLabel        matlab.ui.control.Label
        DurationDayInput               matlab.ui.control.NumericEditField
        DurationHourInput              matlab.ui.control.NumericEditField
        DurationMinuteInput            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp;
    end
    
    methods (Access = private)
        % add your functions here
        function setDateInputs(~, obj, dateNumber, formatOut)
            obj.Value = datestr(dateNumber, formatOut);
        end
        function enableDisableButtons(app)
            
            if app.MainApp.Import.Data.duration - 1440 < 1
                app.DurationDayDownButton.Enable = 'off';
            else
                app.DurationDayDownButton.Enable = 'on';
            end
            
            if app.MainApp.Import.Data.duration - 60 < 1
                app.DurationHourDownButton.Enable = 'off';
            else
                app.DurationHourDownButton.Enable = 'on';
            end
            
            if app.MainApp.Import.Data.duration - 1 < 1
                app.DurationMinuteDownButton.Enable = 'off';
            else
                app.DurationMinuteDownButton.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.CreateRelativeEventUIFigure.Position(3);
            w = app.CreateRelativeEventUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.CreateRelativeEventUIFigure.Position = [x y h w];
            app.CreateRelativeEventUIFigure.Resize = 'off';
            
            % Populate the event label input
            labels = unique(app.MainApp.ACT.analysis.events.label);
            labels(strcmpi(labels, 'start')) = [];
            app.ReferenceEventLabelInput.Items = [{'Select...'}; labels];
            
            % Set the UserData of the inputs
            app.DelayDayInput.UserData.fname = 'delay';
            app.DelayHourInput.UserData.fname = 'delay';
            app.DelayMinuteInput.UserData.fname = 'delay';
            app.DurationDayInput.UserData.fname = 'duration';
            app.DurationHourInput.UserData.fname = 'duration';
            app.DurationMinuteInput.UserData.fname = 'duration';
            
            % Set the UserData of the buttons
            app.DelayDayUpButton.UserData.fname = 'delay';
            app.DelayDayUpButton.UserData.step = 1440;
            app.DelayDayUpButton.UserData.direction = 1;
            app.DelayHourUpButton.UserData.fname = 'delay';
            app.DelayHourUpButton.UserData.step = 60;
            app.DelayHourUpButton.UserData.direction = 1;
            app.DelayMinuteUpButton.UserData.fname = 'delay';
            app.DelayMinuteUpButton.UserData.step = 1;
            app.DelayMinuteUpButton.UserData.direction = 1;
            
            app.DelayDayDownButton.UserData.fname = 'delay';
            app.DelayDayDownButton.UserData.step = 1440;
            app.DelayDayDownButton.UserData.direction = -1;
            app.DelayHourDownButton.UserData.fname = 'delay';
            app.DelayHourDownButton.UserData.step = 60;
            app.DelayHourDownButton.UserData.direction = -1;
            app.DelayMinuteDownButton.UserData.fname = 'delay';
            app.DelayMinuteDownButton.UserData.step = 1;
            app.DelayMinuteDownButton.UserData.direction = -1;
            
            app.DurationDayUpButton.UserData.fname = 'duration';
            app.DurationDayUpButton.UserData.step = 1440;
            app.DurationDayUpButton.UserData.direction = 1;
            app.DurationHourUpButton.UserData.fname = 'duration';
            app.DurationHourUpButton.UserData.step = 60;
            app.DurationHourUpButton.UserData.direction = 1;
            app.DurationMinuteUpButton.UserData.fname = 'duration';
            app.DurationMinuteUpButton.UserData.step = 1;
            app.DurationMinuteUpButton.UserData.direction = 1;
            
            app.DurationDayDownButton.UserData.fname = 'duration';
            app.DurationDayDownButton.UserData.step = 1440;
            app.DurationDayDownButton.UserData.direction = -1;
            app.DurationHourDownButton.UserData.fname = 'duration';
            app.DurationHourDownButton.UserData.step = 60;
            app.DurationHourDownButton.UserData.direction = -1;
            app.DurationMinuteDownButton.UserData.fname = 'duration';
            app.DurationMinuteDownButton.UserData.step = 1;
            app.DurationMinuteDownButton.UserData.direction = -1;
            
            app.MainApp.Import.Data.delay = 0;
            app.MainApp.Import.Data.duration = 60;
            app.MainApp.Import.Data.label = 'Custom Event';
            
            enableDisableButtons(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data.ref = lower(app.RelativeButtonGroup.SelectedObject.Text);
            app.MainApp.Import.Data.refLabel = app.ReferenceEventLabelInput.Value;
            app.MainApp.Import.Data.refType  = app.ReferenceEventTypeInput.Value;
            app.MainApp.Import.Data.delay = app.MainApp.Import.Data.delay/60; % delay in hours
            app.MainApp.Import.Data.duration = app.MainApp.Import.Data.duration/60; % duration in hours
            app.MainApp.Import.Data.newLabel = app.EventLabelInput.Value;
            
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: ReferenceEventLabelInput
        function ReferenceEventInputValueChanged(app, event)
            eventLabel = event.Source.Value;
            % Populate the type input and set the value to 'Select...'
            idx = strcmpi(app.MainApp.ACT.analysis.events.label, eventLabel);
            types = unique(app.MainApp.ACT.analysis.events.type(idx));
            app.ReferenceEventTypeInput.Value = 'Select...';
            app.ReferenceEventTypeInput.Items = [{'Select...'}; types];
            app.CreateButton.Enable = 'off';
        end

        % Value changed function: ReferenceEventTypeInput
        function ReferenceEventTypeInputValueChanged(app, event)
            app.CreateButton.Enable = 'on';
        end

        % Button pushed function: DelayDayDownButton, 
        % DelayDayUpButton, DelayHourDownButton, DelayHourUpButton, 
        % DelayMinuteDownButton, DelayMinuteUpButton, 
        % DurationDayDownButton, DurationDayUpButton, 
        % DurationHourDownButton, DurationHourUpButton, 
        % DurationMinuteDownButton, DurationMinuteUpButton
        function ButtonPushed(app, event)
            fname = event.Source.UserData.fname;
            step = event.Source.UserData.step;
            dir = event.Source.UserData.direction;
            app.MainApp.Import.Data.(fname) = app.MainApp.Import.Data.(fname) + dir*step;
            if app.MainApp.Import.Data.(fname) >= 0
                dd = floor(app.MainApp.Import.Data.(fname)/1440);
                HH = floor((app.MainApp.Import.Data.(fname) - (dd*1440))/60);
                MM = app.MainApp.Import.Data.(fname) - dd*24*60 - HH*60;
            else
                dd = ceil(app.MainApp.Import.Data.(fname)/1440);
                HH = ceil((app.MainApp.Import.Data.(fname) - (dd*1440))/60);
                MM = app.MainApp.Import.Data.(fname) - dd*24*60 - HH*60;
            end
            % Update the input fields
            if strcmpi(fname, 'delay')
                app.DelayDayInput.Value = dd;
                app.DelayHourInput.Value = HH;
                app.DelayMinuteInput.Value = MM;
            else
                app.DurationDayInput.Value = dd;
                app.DurationHourInput.Value = HH;
                app.DurationMinuteInput.Value = MM;
            end
            enableDisableButtons(app)
        end

        % Value changed function: DelayDayInput, DelayHourInput, 
        % DelayMinuteInput, DurationDayInput, DurationHourInput, 
        % DurationMinuteInput
        function InputValueChanged(app, event)
            fname = event.Source.UserData.fname;
            switch fname
                case 'delay'
                    dd = app.DelayDayInput.Value;
                    HH = app.DelayHourInput.Value;
                    MM = app.DelayMinuteInput.Value;
                case 'duration'
                    dd = app.DurationDayInput.Value;
                    HH = app.DurationHourInput.Value;
                    MM = app.DurationMinuteInput.Value;
            end
            app.MainApp.Import.Data.(fname) = dd*1440 + HH*60 + MM;
            enableDisableButtons(app)
        end

        % Value changed function: EventLabelInput
        function EventLabelInputValueChanged(app, event)
            app.MainApp.Import.Data.newLabel = event.Source.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CreateRelativeEventUIFigure = uifigure('Visible', 'off');
            app.CreateRelativeEventUIFigure.Name = 'Create Relative Event';
            app.GridLayout = uigridlayout(app.CreateRelativeEventUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CreateButton = uibutton(app.GridLayout, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Enable = 'off';
            app.CreateButton.Text = 'Create';
            app.CreateNewEventPanel = uipanel(app.GridLayout);
            app.CreateNewEventPanel.Title = 'Create New Event Relative To Existing Event';
            app.GridLayoutCreateNewEventPanel = uigridlayout(app.CreateNewEventPanel);
            app.RefEventLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.RefEventLabel.Text = 'Ref Event Label';
            app.RefEventTypeLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.RefEventTypeLabel.Text = 'Ref Event Type';
            app.RelativeToLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.RelativeToLabel.Text = 'Relative To';
            app.NewEventLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.NewEventLabel.Text = 'New Event Label';
            app.DelayLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.DelayLabel.Text = 'Delay';
            app.DurationLabel = uilabel(app.GridLayoutCreateNewEventPanel);
            app.DurationLabel.Text = 'Duration';
            app.ReferenceEventLabelInput = uidropdown(app.GridLayoutCreateNewEventPanel);
            app.ReferenceEventLabelInput.Items = {'Select...'};
            app.ReferenceEventLabelInput.ValueChangedFcn = createCallbackFcn(app, @ReferenceEventInputValueChanged, true);
            app.ReferenceEventLabelInput.Value = 'Select...';
            app.ReferenceEventTypeInput = uidropdown(app.GridLayoutCreateNewEventPanel);
            app.ReferenceEventTypeInput.Items = {'Select...'};
            app.ReferenceEventTypeInput.ValueChangedFcn = createCallbackFcn(app, @ReferenceEventTypeInputValueChanged, true);
            app.ReferenceEventTypeInput.Value = 'Select...';
            app.RelativeButtonGroup = uibuttongroup(app.GridLayoutCreateNewEventPanel);
            app.OnsetButton = uiradiobutton(app.RelativeButtonGroup);
            app.OnsetButton.Text = 'Onset';
            app.OnsetButton.Value = true;
            app.OffsetButton = uiradiobutton(app.RelativeButtonGroup);
            app.OffsetButton.Text = 'Offset';
            app.EventLabelInput = uieditfield(app.GridLayoutCreateNewEventPanel, 'text');
            app.EventLabelInput.ValueChangedFcn = createCallbackFcn(app, @EventLabelInputValueChanged, true);
            app.EventLabelInput.Value = 'Custom Event';
            app.DelayPanel = uipanel(app.GridLayoutCreateNewEventPanel);
            app.GridLayoutDelayPanel = uigridlayout(app.DelayPanel);
            app.DelayDayUpButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.DelayDayUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DelayDayUpButton.Text = '^';
            app.DelayHourUpButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.DelayHourUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DelayHourUpButton.Text = '^';
            app.DelayMinuteUpButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.DelayMinuteUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DelayMinuteUpButton.Text = '^';
            app.DelayDayDownButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.DelayDayDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DelayDayDownButton.Text = 'v';
            app.DelayHourDownButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.DelayHourDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DelayHourDownButton.Text = 'v';
            app.DelayMinuteDownButton = uibutton(app.GridLayoutDelayPanel, 'push');
            app.DelayMinuteDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DelayMinuteDownButton.Text = 'v';
            app.DelayDayUnitLabel = uilabel(app.GridLayoutDelayPanel);
            app.DelayDayUnitLabel.Text = 'days';
            app.DelayDayHourSepLabel = uilabel(app.GridLayoutDelayPanel);
            app.DelayDayHourSepLabel.Text = ':';
            app.DelayDayInput = uieditfield(app.GridLayoutDelayPanel, 'numeric');
            app.DelayDayInput.RoundFractionalValues = 'on';
            app.DelayDayInput.ValueDisplayFormat = '%i';
            app.DelayDayInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DelayHourInput = uieditfield(app.GridLayoutDelayPanel, 'numeric');
            app.DelayHourInput.RoundFractionalValues = 'on';
            app.DelayHourInput.ValueDisplayFormat = '%i';
            app.DelayHourInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DelayMinuteInput = uieditfield(app.GridLayoutDelayPanel, 'numeric');
            app.DelayMinuteInput.RoundFractionalValues = 'on';
            app.DelayMinuteInput.ValueDisplayFormat = '%i';
            app.DelayMinuteInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DurationPanel = uipanel(app.GridLayoutCreateNewEventPanel);
            app.GridLayoutDurationPanel = uigridlayout(app.DurationPanel);
            app.DurationDayUpButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationDayUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationDayUpButton.Text = '^';
            app.DurationHourUpButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationHourUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationHourUpButton.Text = '^';
            app.DurationMinuteUpButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationMinuteUpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationMinuteUpButton.Text = '^';
            app.DurationDayDownButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationDayDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationDayDownButton.Text = 'v';
            app.DurationHourDownButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationHourDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationHourDownButton.Text = 'v';
            app.DurationMinuteDownButton = uibutton(app.GridLayoutDurationPanel, 'push');
            app.DurationMinuteDownButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DurationMinuteDownButton.Text = 'v';
            app.DurationDayUnitLabel = uilabel(app.GridLayoutDurationPanel);
            app.DurationDayUnitLabel.Text = 'days';
            app.DurationDayHourSepLabel = uilabel(app.GridLayoutDurationPanel);
            app.DurationDayHourSepLabel.Text = ':';
            app.DurationDayInput = uieditfield(app.GridLayoutDurationPanel, 'numeric');
            app.DurationDayInput.Limits = [0 Inf];
            app.DurationDayInput.RoundFractionalValues = 'on';
            app.DurationDayInput.ValueDisplayFormat = '%i';
            app.DurationDayInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DurationHourInput = uieditfield(app.GridLayoutDurationPanel, 'numeric');
            app.DurationHourInput.Limits = [0 Inf];
            app.DurationHourInput.RoundFractionalValues = 'on';
            app.DurationHourInput.ValueDisplayFormat = '%i';
            app.DurationHourInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DurationHourInput.Value = 1;
            app.DurationMinuteInput = uieditfield(app.GridLayoutDurationPanel, 'numeric');
            app.DurationMinuteInput.Limits = [0 Inf];
            app.DurationMinuteInput.RoundFractionalValues = 'on';
            app.DurationMinuteInput.ValueDisplayFormat = '%i';
            app.DurationMinuteInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            % Show the figure after all components are created
            app.CreateRelativeEventUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = createRelativeEvent(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CreateRelativeEventUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CreateRelativeEventUIFigure)
        end
    end
end