classdef editDatasetInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditDatasetInfoUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        CancelButton             matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        DatasetInformationPanel  matlab.ui.container.Panel
        GridLayoutDatasetInformationPanel  matlab.ui.container.GridLayout
        StudyLabel               matlab.ui.control.Label
        StudyInput               matlab.ui.control.EditField
        ResearcherLabel          matlab.ui.control.Label
        ResearcherInput          matlab.ui.control.EditField
        SubjectIDLabel           matlab.ui.control.Label
        SubjectIDInput           matlab.ui.control.EditField
        GroupLabel               matlab.ui.control.Label
        GroupInput               matlab.ui.control.EditField
        ConditionLabel           matlab.ui.control.Label
        ConditionInput           matlab.ui.control.EditField
        SessionLabel             matlab.ui.control.Label
        SessionInput             matlab.ui.control.EditField
        DateOfBirthLabel         matlab.ui.control.Label
        DateOfBirthInput         matlab.ui.control.DatePicker
        SexLabel                 matlab.ui.control.Label
        SexInput                 matlab.ui.control.DropDown
        HeightLabel              matlab.ui.control.Label
        HeightInput              matlab.ui.control.EditField
        WeightLabel              matlab.ui.control.Label
        WeightInput              matlab.ui.control.EditField
        cmLabel                  matlab.ui.control.Label
        kgLabel                  matlab.ui.control.Label
        HandednessLabel          matlab.ui.control.Label
        HandednessInput          matlab.ui.control.DropDown
        DeviceLocationLabel      matlab.ui.control.Label
        DeviceLocationInput      matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        MainApp;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.EditDatasetInfoUIFigure.Position(3);
            w = app.EditDatasetInfoUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.EditDatasetInfoUIFigure.Position = [x y h w];
            app.EditDatasetInfoUIFigure.Resize = 'off';
            
            % Add tags to the input fields
            app.StudyInput.Tag = 'study';
            app.ResearcherInput.Tag = 'researcher';
            app.SubjectIDInput.Tag = 'subject';
            app.GroupInput.Tag = 'group';
            app.ConditionInput.Tag = 'condition';
            app.SessionInput.Tag = 'session';
            app.DateOfBirthInput.Tag = 'dob';
            app.SexInput.Tag = 'sex';
            app.HeightInput.Tag = 'height';
            app.WeightInput.Tag = 'weight';
            app.HandednessInput.Tag = 'handedness';
            app.DeviceLocationInput.Tag = 'deviceLoc';
            
            % Set the values of the input fields
            app.StudyInput.Value = app.MainApp.ACT.info.study;
            app.ResearcherInput.Value = app.MainApp.ACT.info.researcher;
            app.SubjectIDInput.Value = app.MainApp.ACT.info.subject;
            app.GroupInput.Value = app.MainApp.ACT.info.group;
            app.ConditionInput.Value = app.MainApp.ACT.info.condition;
            app.SessionInput.Value = app.MainApp.ACT.info.session;
            
            app.DateOfBirthInput.Value = datetime(datenum(app.MainApp.ACT.info.dob, 'dd/mm/yyyy'), 'ConvertFrom', 'datenum');
            app.SexInput.Value = app.MainApp.ACT.info.sex;
            app.HeightInput.Value = num2str(app.MainApp.ACT.info.height);
            app.WeightInput.Value = num2str(app.MainApp.ACT.info.weight);
            app.HandednessInput.Value = app.MainApp.ACT.info.handedness;
            app.DeviceLocationInput.Value = app.MainApp.ACT.info.deviceLoc;
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: ConditionInput, DateOfBirthInput, 
        % DeviceLocationInput, GroupInput, HandednessInput, 
        % HeightInput, ResearcherInput, SessionInput, SexInput, 
        % StudyInput, SubjectIDInput, WeightInput
        function InputValueChanged(app, event)
            tag = event.Source.Tag;
            value = event.Source.Value;
            if ~isfield(app.MainApp.Import, 'Data')
                app.MainApp.Import.Data = app.MainApp.ACT.info;
            end
            switch tag
                case {'height', 'weight'}
                    value = str2double(value);
                case 'dob'
                    value = datestr(value, 'dd/mm/yyyy');
            end
            app.MainApp.Import.Data.(tag) = value;
            app.SaveButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditDatasetInfoUIFigure = uifigure('Visible', 'off');
            app.EditDatasetInfoUIFigure.Name = 'Edit Dataset Information';
            app.GridLayout = uigridlayout(app.EditDatasetInfoUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.DatasetInformationPanel = uipanel(app.GridLayout);
            app.DatasetInformationPanel.Title = 'Dataset Information';
            app.GridLayoutDatasetInformationPanel = uigridlayout(app.DatasetInformationPanel);
            app.StudyLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.StudyLabel.Text = 'Study';
            app.StudyInput = uieditfield(app.GridLayoutDatasetInformationPanel, 'text');
            app.StudyInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ResearcherLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.ResearcherLabel.Text = 'Researcher';
            app.ResearcherInput = uieditfield(app.GridLayoutDatasetInformationPanel, 'text');
            app.ResearcherInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.SubjectIDLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.SubjectIDLabel.Text = 'Subject ID';
            app.SubjectIDInput = uieditfield(app.GridLayoutDatasetInformationPanel, 'text');
            app.SubjectIDInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.GroupLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.GroupLabel.Text = 'Group';
            app.GroupInput = uieditfield(app.GridLayoutDatasetInformationPanel, 'text');
            app.GroupInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ConditionLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.ConditionLabel.Text = 'Condition';
            app.ConditionInput = uieditfield(app.GridLayoutDatasetInformationPanel, 'text');
            app.ConditionInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.SessionLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.SessionLabel.Text = 'Session';
            app.SessionInput = uieditfield(app.GridLayoutDatasetInformationPanel, 'text');
            app.SessionInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DateOfBirthLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.DateOfBirthLabel.Text = 'Date Of Birth';
            app.DateOfBirthInput = uidatepicker(app.GridLayoutDatasetInformationPanel);
            app.DateOfBirthInput.DisplayFormat = 'dd/MM/uuuu';
            app.DateOfBirthInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.SexLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.SexLabel.Text = 'Sex';
            app.SexInput = uidropdown(app.GridLayoutDatasetInformationPanel);
            app.SexInput.Items = {'male', 'female', 'other', 'not specified'};
            app.SexInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.SexInput.Value = 'male';
            app.HeightLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.HeightLabel.Text = 'Height';
            app.HeightInput = uieditfield(app.GridLayoutDatasetInformationPanel, 'text');
            app.HeightInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.WeightLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.WeightLabel.Text = 'Weight';
            app.WeightInput = uieditfield(app.GridLayoutDatasetInformationPanel, 'text');
            app.WeightInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.cmLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.cmLabel.Text = 'cm';
            app.kgLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.kgLabel.Text = 'kg';
            app.HandednessLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.HandednessLabel.Text = 'Handedness';
            app.HandednessInput = uidropdown(app.GridLayoutDatasetInformationPanel);
            app.HandednessInput.Items = {'right', 'left', 'ambidextrous', 'not specified'};
            app.HandednessInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.HandednessInput.Value = 'right';
            app.DeviceLocationLabel = uilabel(app.GridLayoutDatasetInformationPanel);
            app.DeviceLocationLabel.Text = 'Device Location';
            app.DeviceLocationInput = uidropdown(app.GridLayoutDatasetInformationPanel);
            app.DeviceLocationInput.Items = {'right wrist', 'left wrist', 'right upper arm', 'left upper arm', 'right ankle', 'left ankle', 'right upper leg', 'left upper leg', 'right hip', 'left hip', 'chest', 'other', 'not specified'};
            app.DeviceLocationInput.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.DeviceLocationInput.Value = 'right wrist';
            % Show the figure after all components are created
            app.EditDatasetInfoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = editDatasetInfo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EditDatasetInfoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditDatasetInfoUIFigure)
        end
    end
end