classdef editEvent < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditEventUIFigure              matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        EditEventLabelPanel            matlab.ui.container.Panel
        GridLayoutEditEventLabelPanel  matlab.ui.container.GridLayout
        EventLabelInput                matlab.ui.control.DropDown
        CustomEventLabelInput          matlab.ui.control.EditField
        EditEventOnsetPanel            matlab.ui.container.Panel
        GridLayoutEditEventOnsetPanel  matlab.ui.container.GridLayout
        OnsetDayLabel                  matlab.ui.control.Label
        OnsetMonthLabel                matlab.ui.control.Label
        OnsetYearLabel                 matlab.ui.control.Label
        OnsetHourLabel                 matlab.ui.control.Label
        OnsetMinuteLabel               matlab.ui.control.Label
        ButtonOnsetDayUp               matlab.ui.control.Button
        ButtonOnsetMonthUp             matlab.ui.control.Button
        ButtonOnsetYearUp              matlab.ui.control.Button
        ButtonOnsetHourUp              matlab.ui.control.Button
        ButtonOnsetMinuteUp            matlab.ui.control.Button
        InputOnsetDay                  matlab.ui.control.EditField
        InputOnsetMonth                matlab.ui.control.EditField
        InputOnsetYear                 matlab.ui.control.EditField
        InputOnsetHour                 matlab.ui.control.EditField
        InputOnsetMinute               matlab.ui.control.EditField
        ButtonOnsetDayDown             matlab.ui.control.Button
        ButtonOnsetMonthDown           matlab.ui.control.Button
        ButtonOnsetYearDown            matlab.ui.control.Button
        ButtonOnsetHourDown            matlab.ui.control.Button
        ButtonOnsetMinuteDown          matlab.ui.control.Button
        LabelOnsetDaySep               matlab.ui.control.Label
        LabelOnsetMonthSep             matlab.ui.control.Label
        LabelOnsetHourSep              matlab.ui.control.Label
        EditEventDurationPanel         matlab.ui.container.Panel
        GridLayoutEditEventDurationPanel  matlab.ui.container.GridLayout
        DurationDayLabel               matlab.ui.control.Label
        DurationHourLabel              matlab.ui.control.Label
        DurationMinuteLabel            matlab.ui.control.Label
        ButtonDurationDayUp            matlab.ui.control.Button
        ButtonDurationHourUp           matlab.ui.control.Button
        ButtonDurationMinuteUp         matlab.ui.control.Button
        InputDurationDay               matlab.ui.control.EditField
        InputDurationHour              matlab.ui.control.EditField
        InputDurationMinute            matlab.ui.control.EditField
        ButtonDurationDayDown          matlab.ui.control.Button
        ButtonDurationHourDown         matlab.ui.control.Button
        ButtonDurationMinuteDown       matlab.ui.control.Button
        LabelDurationHourSep           matlab.ui.control.Label
        NewEventOffsetLabel            matlab.ui.control.Label
        NewEventOffsetValue            matlab.ui.control.Label
        CurrentEventOffsetLabel        matlab.ui.control.Label
        CurrentEventOffsetValue        matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        DeleteEventButton              matlab.ui.control.Button
        EditButton                     matlab.ui.control.Button
        ProprietaryPanel               matlab.ui.container.Panel
        GridLayoutProprietaryPanel     matlab.ui.container.GridLayout
        DismissProprietaryPanel        matlab.ui.control.Button
        ProprietaryLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp;
        Import;
    end
    
    methods (Access = private)
        % add your functions here
        function setDateInputs(~, obj, dateNumber, formatOut)
            obj.Value = datestr(dateNumber, formatOut);
        end
        function setDurationInputs(app)
            dur = app.MainApp.Import.Data.duration;
            dd = floor(dur/(24*60));
            HH = floor((dur/(24*60)-dd)*24);
            MM = round((((dur/(24*60)-dd)*24)-HH)*60);
            if MM > 59 && MM < 61
                HH = HH + 1;
                MM = 0;
            end
            app.InputDurationDay.Value = num2str(dd);
            app.InputDurationHour.Value = num2str(HH);
            app.InputDurationMinute.Value = num2str(MM);
            if dur-24*60 < 0
                app.ButtonDurationDayDown.Enable = 'off';
            else
                app.ButtonDurationDayDown.Enable = 'on';
            end
            if dur-60 < 0
                app.ButtonDurationHourDown.Enable = 'off';
            else
                app.ButtonDurationHourDown.Enable = 'on';
            end
            if dur-1 < 0
                app.ButtonDurationMinuteDown.Enable = 'off';
            else
                app.ButtonDurationMinuteDown.Enable = 'on';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, eventID)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.EditEventUIFigure.Position(3);
            w = app.EditEventUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.EditEventUIFigure.Position = [x y h w];
            app.EditEventUIFigure.Resize = 'off';
            
            % Add any pre-existing custom event labels to the list
            idx = strcmpi(app.MainApp.ACT.analysis.events.type, 'customEvent');
            eventLabels = unique(app.MainApp.ACT.analysis.events.label(idx));
            if iscolumn(eventLabels)
                eventLabels = eventLabels';
            end
            app.EventLabelInput.Items = [app.EventLabelInput.Items, eventLabels];
            app.EventLabelInput.ItemsData = [app.EventLabelInput.ItemsData, eventLabels];
            
            % initialize the import structure
            idx = app.MainApp.ACT.analysis.events.id == eventID;
            app.MainApp.Import.Data.delete = false;
            app.MainApp.Import.Data.id = app.MainApp.ACT.analysis.events.id(idx);
            app.MainApp.Import.Data.onset = datenum(datestr(app.MainApp.ACT.analysis.events.onset(idx), 'dd/mm/yyyy HH:MM'), 'dd/mm/yyyy HH:MM');
            app.MainApp.Import.Data.duration = round(app.MainApp.ACT.analysis.events.duration(idx)*24*60);
            app.MainApp.Import.Data.label = app.MainApp.ACT.analysis.events.label{idx};
            app.MainApp.Import.Data.type = app.MainApp.ACT.analysis.events.type{idx};
            
            % Set the input fields
            app.EventLabelInput.Value = app.MainApp.Import.Data.label;
            % Onset
            setDateInputs(app, app.InputOnsetDay, app.MainApp.Import.Data.onset, 'dd')
            setDateInputs(app, app.InputOnsetMonth, app.MainApp.Import.Data.onset, 'mm')
            setDateInputs(app, app.InputOnsetYear, app.MainApp.Import.Data.onset, 'yyyy')
            setDateInputs(app, app.InputOnsetHour, app.MainApp.Import.Data.onset, 'HH')
            setDateInputs(app, app.InputOnsetMinute, app.MainApp.Import.Data.onset, 'MM')
            % Duration
            setDurationInputs(app)
            % Set new offset value
            app.CurrentEventOffsetValue.Text = datestr(app.MainApp.Import.Data.onset + app.MainApp.Import.Data.duration/(24*60), 'dd/mm/yyyy HH:MM');
            app.NewEventOffsetValue.Text = datestr(app.MainApp.Import.Data.onset + app.MainApp.Import.Data.duration/(24*60), 'dd/mm/yyyy HH:MM');
            
            % Set the UserData of the buttons
            app.ButtonOnsetDayUp.UserData.field = 'onset';
            app.ButtonOnsetDayUp.UserData.step = 'day';
            app.ButtonOnsetDayUp.UserData.direction = 1;
            app.ButtonOnsetMonthUp.UserData.field = 'onset';
            app.ButtonOnsetMonthUp.UserData.step = 'month';
            app.ButtonOnsetMonthUp.UserData.direction = 1;
            app.ButtonOnsetYearUp.UserData.field = 'onset';
            app.ButtonOnsetYearUp.UserData.step = 'year';
            app.ButtonOnsetYearUp.UserData.direction = 1;
            app.ButtonOnsetHourUp.UserData.field = 'onset';
            app.ButtonOnsetHourUp.UserData.step = 'hour';
            app.ButtonOnsetHourUp.UserData.direction = 1;
            app.ButtonOnsetMinuteUp.UserData.field = 'onset';
            app.ButtonOnsetMinuteUp.UserData.step = 'minute';
            app.ButtonOnsetMinuteUp.UserData.direction = 1;
            
            app.ButtonOnsetDayDown.UserData.field = 'onset';
            app.ButtonOnsetDayDown.UserData.step = 'day';
            app.ButtonOnsetDayDown.UserData.direction = -1;
            app.ButtonOnsetMonthDown.UserData.field = 'onset';
            app.ButtonOnsetMonthDown.UserData.step = 'month';
            app.ButtonOnsetMonthDown.UserData.direction = -1;
            app.ButtonOnsetYearDown.UserData.field = 'onset';
            app.ButtonOnsetYearDown.UserData.step = 'year';
            app.ButtonOnsetYearDown.UserData.direction = -1;
            app.ButtonOnsetHourDown.UserData.field = 'onset';
            app.ButtonOnsetHourDown.UserData.step = 'hour';
            app.ButtonOnsetHourDown.UserData.direction = -1;
            app.ButtonOnsetMinuteDown.UserData.field = 'onset';
            app.ButtonOnsetMinuteDown.UserData.step = 'minute';
            app.ButtonOnsetMinuteDown.UserData.direction = -1;
            
            app.ButtonDurationDayUp.UserData.field = 'duration';
            app.ButtonDurationDayUp.UserData.step = 24*60;
            app.ButtonDurationDayUp.UserData.direction = 1;
            app.ButtonDurationHourUp.UserData.field = 'duration';
            app.ButtonDurationHourUp.UserData.step = 60;
            app.ButtonDurationHourUp.UserData.direction = 1;
            app.ButtonDurationMinuteUp.UserData.field = 'duration';
            app.ButtonDurationMinuteUp.UserData.step = 1;
            app.ButtonDurationMinuteUp.UserData.direction = 1;
            
            app.ButtonDurationDayDown.UserData.field = 'duration';
            app.ButtonDurationDayDown.UserData.step = 24*60;
            app.ButtonDurationDayDown.UserData.direction = -1;
            app.ButtonDurationHourDown.UserData.field = 'duration';
            app.ButtonDurationHourDown.UserData.step = 60;
            app.ButtonDurationHourDown.UserData.direction = -1;
            app.ButtonDurationMinuteDown.UserData.field = 'duration';
            app.ButtonDurationMinuteDown.UserData.step = 1;
            app.ButtonDurationMinuteDown.UserData.direction = -1;
            
            app.InputOnsetDay.UserData.field = 'onset';
            app.InputOnsetDay.UserData.formatOut = 'dd';
            app.InputOnsetMonth.UserData.field = 'onset';
            app.InputOnsetMonth.UserData.formatOut = 'mm';
            app.InputOnsetYear.UserData.field = 'onset';
            app.InputOnsetYear.UserData.formatOut = 'yyyy';
            app.InputOnsetHour.UserData.field = 'onset';
            app.InputOnsetHour.UserData.formatOut = 'HH';
            app.InputOnsetMinute.UserData.field = 'onset';
            app.InputOnsetMinute.UserData.formatOut = 'MM';
            
            app.InputDurationDay.UserData.field = 'duration';
            app.InputDurationDay.UserData.formatOut = 'dd';
            app.InputDurationHour.UserData.field = 'duration';
            app.InputDurationHour.UserData.formatOut = 'HH';
            app.InputDurationMinute.UserData.field = 'duration';
            app.InputDurationMinute.UserData.formatOut = 'MM';
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            app.MainApp.Import.Data.duration = app.MainApp.Import.Data.duration/(24*60);
            app.MainApp.Import.Return = true;
            switch app.EventLabelInput.Value
                case 'custom'
                    app.MainApp.Import.Data.label = app.CustomEventLabelInput.Value;
                    app.MainApp.Import.Data.type = 'customEvent';
                case {'reject', 'sleepWindow'}
                    app.MainApp.Import.Data.label = app.EventLabelInput.Value;
                    app.MainApp.Import.Data.type = 'manual';
                otherwise
                    app.MainApp.Import.Data.label = app.EventLabelInput.Value;
                    app.MainApp.Import.Data.type = 'customEvent';
            end
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: DeleteEventButton
        function DeleteEventButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data.delete = true;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Value changed function: EventLabelInput
        function EventLabelInputValueChanged(app, event)
            app.ProprietaryPanel.Visible = 'off';
            switch event.Source.Value
                case 'select'
                    app.CustomEventLabelInput.Visible = 'off';
                    app.EditButton.Enable = 'off';
                case 'custom'
                    app.CustomEventLabelInput.Visible = 'on';
                    app.EditButton.Enable = 'on';
                case 'reject'
                    app.CustomEventLabelInput.Visible = 'off';
                    app.EditButton.Enable = 'on';
                case 'sleepWindow'
                    app.CustomEventLabelInput.Visible = 'off';
                    app.EditButton.Enable = 'on';
                otherwise
                    app.CustomEventLabelInput.Visible = 'off';
                    app.EditButton.Enable = 'on';
            end
        end

        % Button pushed function: ButtonDurationDayDown, 
        % ButtonDurationDayUp, ButtonDurationHourDown, 
        % ButtonDurationHourUp, ButtonDurationMinuteDown, 
        % ButtonDurationMinuteUp, ButtonOnsetDayDown, 
        % ButtonOnsetDayUp, ButtonOnsetHourDown, ButtonOnsetHourUp, 
        % ButtonOnsetMinuteDown, ButtonOnsetMinuteUp, 
        % ButtonOnsetMonthDown, ButtonOnsetMonthUp, 
        % ButtonOnsetYearDown, ButtonOnsetYearUp
        function ButtonPushed(app, event)
            % Dismiss the error panel 
            app.ProprietaryPanel.Visible = 'off';
            
            % Enable the edit button
            app.EditButton.Enable = 'on';
            
            field = event.Source.UserData.field;
            step = event.Source.UserData.step;
            dir = event.Source.UserData.direction;
            switch field
                case 'onset'
                    app.MainApp.Import.Data.onset = addtodate(app.MainApp.Import.Data.(field), dir, step);
                    setDateInputs(app, app.InputOnsetDay, app.MainApp.Import.Data.(field), 'dd')
                    setDateInputs(app, app.InputOnsetMonth, app.MainApp.Import.Data.(field), 'mm')
                    setDateInputs(app, app.InputOnsetYear, app.MainApp.Import.Data.(field), 'yyyy')
                    setDateInputs(app, app.InputOnsetHour, app.MainApp.Import.Data.(field), 'HH')
                    setDateInputs(app, app.InputOnsetMinute, app.MainApp.Import.Data.(field), 'MM')
                case 'duration'
                    app.MainApp.Import.Data.(field) = app.MainApp.Import.Data.(field) + step*dir;
                    setDurationInputs(app)
            end
            % Set new offset value
            app.NewEventOffsetValue.Text = datestr(app.MainApp.Import.Data.onset + app.MainApp.Import.Data.duration/(24*60), 'dd/mm/yyyy HH:MM');
            blink(app.NewEventOffsetValue, 0.15)
            pause(0.45)
            blink(app.NewEventOffsetValue)
        end

        % Value changed function: InputDurationDay, 
        % InputDurationHour, InputDurationMinute, InputOnsetDay, 
        % InputOnsetHour, InputOnsetMinute, InputOnsetMonth, 
        % InputOnsetYear
        function InputValueChanged(app, event)
            % Dismiss the error panel 
            app.ProprietaryPanel.Visible = 'off';
            
            % Enable the edit button
            app.EditButton.Enable = 'on';
            
            field = event.Source.UserData.field;
            switch field
                case 'onset'
                    app.MainApp.Import.Data.(field) = datenum([ ...
                        app.InputOnsetDay.Value, '/', ...
                        app.InputOnsetMonth.Value, '/', ...
                        app.InputOnsetYear.Value, ' ', ...
                        app.InputOnsetHour.Value, ':', ...
                        app.InputOnsetMinute.Value ...
                        ], 'dd/mm/yyyy HH:MM');
                    
                    formatOut = event.Source.UserData.formatOut;
                    setDateInputs(app, event.Source, app.MainApp.Import.Data.onset, formatOut)
                case 'duration'
                    app.MainApp.Import.Data.(field) = ...
                        str2double(app.InputDurationDay.Value)*24*60 + ...
                        str2double(app.InputDurationHour.Value)*60 + ...
                        str2double(app.InputDurationMinute.Value);
                    setDurationInputs(app)
            end
            % Set new offset value
            app.NewEventOffsetValue.Text = datestr(app.MainApp.Import.Data.onset + app.MainApp.Import.Data.duration/(24*60), 'dd/mm/yyyy HH:MM');
            blink(app.NewEventOffsetValue, 0.15)
            pause(0.45)
            blink(app.NewEventOffsetValue)
        end

        % Value changed function: CustomEventLabelInput
        function CustomEventLabelInputValueChanged(app, event)
            % Check that the User did not specify a proprietary event label
            if checkProprietaryEventLabel(app.CustomEventLabelInput.Value)
                app.ProprietaryPanel.Visible = 'on';
                app.CustomEventLabelInput.Value = event.PreviousValue;
            else
                % Dismiss the error panel 
                app.ProprietaryPanel.Visible = 'off';
            end
        end

        % Button pushed function: DismissProprietaryPanel
        function DismissProprietaryPanelButtonPushed(app, event)
            app.ProprietaryPanel.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditEventUIFigure = uifigure('Visible', 'off');
            app.EditEventUIFigure.Name = 'Edit Event';
            app.GridLayout = uigridlayout(app.EditEventUIFigure);
            app.EditEventLabelPanel = uipanel(app.GridLayout);
            app.EditEventLabelPanel.Title = 'Edit Event Label';
            app.GridLayoutEditEventLabelPanel = uigridlayout(app.EditEventLabelPanel);
            app.EventLabelInput = uidropdown(app.GridLayoutEditEventLabelPanel);
            app.EventLabelInput.Items = {'Select...', 'Custom Label', 'Reject', 'Sleep Window'};
            app.EventLabelInput.ItemsData = {'select', 'custom', 'reject', 'sleepWindow'};
            app.EventLabelInput.ValueChangedFcn = createCallbackFcn(app, @EventLabelInputValueChanged, true);
            app.EventLabelInput.Value = 'select';
            app.CustomEventLabelInput = uieditfield(app.GridLayoutEditEventLabelPanel, 'text');
            app.CustomEventLabelInput.ValueChangedFcn = createCallbackFcn(app, @CustomEventLabelInputValueChanged, true);
            app.CustomEventLabelInput.Visible = 'off';
            app.CustomEventLabelInput.Value = 'Custom Event';
            app.EditEventOnsetPanel = uipanel(app.GridLayout);
            app.EditEventOnsetPanel.Title = 'Edit Event Onset';
            app.GridLayoutEditEventOnsetPanel = uigridlayout(app.EditEventOnsetPanel);
            app.OnsetDayLabel = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.OnsetDayLabel.Text = 'Day';
            app.OnsetMonthLabel = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.OnsetMonthLabel.Text = 'Month';
            app.OnsetYearLabel = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.OnsetYearLabel.Text = 'Year';
            app.OnsetHourLabel = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.OnsetHourLabel.Text = 'Hour';
            app.OnsetMinuteLabel = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.OnsetMinuteLabel.Text = 'Minute';
            app.ButtonOnsetDayUp = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetDayUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetDayUp.Text = '^';
            app.ButtonOnsetMonthUp = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetMonthUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetMonthUp.Text = '^';
            app.ButtonOnsetYearUp = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetYearUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetYearUp.Text = '^';
            app.ButtonOnsetHourUp = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetHourUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetHourUp.Text = '^';
            app.ButtonOnsetMinuteUp = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetMinuteUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetMinuteUp.Text = '^';
            app.InputOnsetDay = uieditfield(app.GridLayoutEditEventOnsetPanel, 'text');
            app.InputOnsetDay.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputOnsetMonth = uieditfield(app.GridLayoutEditEventOnsetPanel, 'text');
            app.InputOnsetMonth.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputOnsetYear = uieditfield(app.GridLayoutEditEventOnsetPanel, 'text');
            app.InputOnsetYear.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputOnsetHour = uieditfield(app.GridLayoutEditEventOnsetPanel, 'text');
            app.InputOnsetHour.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputOnsetMinute = uieditfield(app.GridLayoutEditEventOnsetPanel, 'text');
            app.InputOnsetMinute.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ButtonOnsetDayDown = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetDayDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetDayDown.Text = 'v';
            app.ButtonOnsetMonthDown = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetMonthDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetMonthDown.Text = 'v';
            app.ButtonOnsetYearDown = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetYearDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetYearDown.Text = 'v';
            app.ButtonOnsetHourDown = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetHourDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetHourDown.Text = 'v';
            app.ButtonOnsetMinuteDown = uibutton(app.GridLayoutEditEventOnsetPanel, 'push');
            app.ButtonOnsetMinuteDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonOnsetMinuteDown.Text = 'v';
            app.LabelOnsetDaySep = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.LabelOnsetDaySep.Text = '/';
            app.LabelOnsetMonthSep = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.LabelOnsetMonthSep.Text = '/';
            app.LabelOnsetHourSep = uilabel(app.GridLayoutEditEventOnsetPanel);
            app.LabelOnsetHourSep.Text = ':';
            app.EditEventDurationPanel = uipanel(app.GridLayout);
            app.EditEventDurationPanel.Title = 'Edit Event Duration';
            app.GridLayoutEditEventDurationPanel = uigridlayout(app.EditEventDurationPanel);
            app.DurationDayLabel = uilabel(app.GridLayoutEditEventDurationPanel);
            app.DurationDayLabel.Text = 'Day';
            app.DurationHourLabel = uilabel(app.GridLayoutEditEventDurationPanel);
            app.DurationHourLabel.Text = 'Hour';
            app.DurationMinuteLabel = uilabel(app.GridLayoutEditEventDurationPanel);
            app.DurationMinuteLabel.Text = 'Minute';
            app.ButtonDurationDayUp = uibutton(app.GridLayoutEditEventDurationPanel, 'push');
            app.ButtonDurationDayUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonDurationDayUp.Text = '^';
            app.ButtonDurationHourUp = uibutton(app.GridLayoutEditEventDurationPanel, 'push');
            app.ButtonDurationHourUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonDurationHourUp.Text = '^';
            app.ButtonDurationMinuteUp = uibutton(app.GridLayoutEditEventDurationPanel, 'push');
            app.ButtonDurationMinuteUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonDurationMinuteUp.Text = '^';
            app.InputDurationDay = uieditfield(app.GridLayoutEditEventDurationPanel, 'text');
            app.InputDurationDay.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputDurationHour = uieditfield(app.GridLayoutEditEventDurationPanel, 'text');
            app.InputDurationHour.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputDurationMinute = uieditfield(app.GridLayoutEditEventDurationPanel, 'text');
            app.InputDurationMinute.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.ButtonDurationDayDown = uibutton(app.GridLayoutEditEventDurationPanel, 'push');
            app.ButtonDurationDayDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonDurationDayDown.Text = 'v';
            app.ButtonDurationHourDown = uibutton(app.GridLayoutEditEventDurationPanel, 'push');
            app.ButtonDurationHourDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonDurationHourDown.Text = 'v';
            app.ButtonDurationMinuteDown = uibutton(app.GridLayoutEditEventDurationPanel, 'push');
            app.ButtonDurationMinuteDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonDurationMinuteDown.Text = 'v';
            app.LabelDurationHourSep = uilabel(app.GridLayoutEditEventDurationPanel);
            app.LabelDurationHourSep.Text = ':';
            app.NewEventOffsetLabel = uilabel(app.GridLayoutEditEventDurationPanel);
            app.NewEventOffsetLabel.Text = 'New Event Offset';
            app.NewEventOffsetValue = uilabel(app.GridLayoutEditEventDurationPanel);
            app.NewEventOffsetValue.Text = '';
            app.CurrentEventOffsetLabel = uilabel(app.GridLayoutEditEventDurationPanel);
            app.CurrentEventOffsetLabel.Text = 'Current Event Offset';
            app.CurrentEventOffsetValue = uilabel(app.GridLayoutEditEventDurationPanel);
            app.CurrentEventOffsetValue.Text = '';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.DeleteEventButton = uibutton(app.GridLayout, 'push');
            app.DeleteEventButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteEventButtonPushed, true);
            app.DeleteEventButton.Text = 'Delete Event';
            app.EditButton = uibutton(app.GridLayout, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Enable = 'off';
            app.EditButton.Text = 'Edit';
            app.ProprietaryPanel = uipanel(app.EditEventUIFigure);
            app.ProprietaryPanel.Visible = 'off';
            app.GridLayoutProprietaryPanel = uigridlayout(app.ProprietaryPanel);
            app.DismissProprietaryPanel = uibutton(app.GridLayoutProprietaryPanel, 'push');
            app.DismissProprietaryPanel.ButtonPushedFcn = createCallbackFcn(app, @DismissProprietaryPanelButtonPushed, true);
            app.DismissProprietaryPanel.Text = 'ok';
            app.ProprietaryLabel = uilabel(app.GridLayoutProprietaryPanel);
            app.ProprietaryLabel.Text = 'Can''t use Cicada''s proprietary labels';
            % Show the figure after all components are created
            app.EditEventUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = editEvent(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EditEventUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditEventUIFigure)
        end
    end
end