classdef emptyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MyAppUIFigure  matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        CancelButton   matlab.ui.control.Button
        ImportButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.MyAppUIFigure.Position(3);
            w = app.MyAppUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.MyAppUIFigure.Position = [x y h w];
            app.MyAppUIFigure.Resize = 'off';
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MyAppUIFigure = uifigure('Visible', 'off');
            app.MyAppUIFigure.Name = 'Import Sleep Diary';
            app.GridLayout = uigridlayout(app.MyAppUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Enable = 'off';
            app.ImportButton.Text = 'Import';
            % Show the figure after all components are created
            app.MyAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = emptyApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MyAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MyAppUIFigure)
        end
    end
end