classdef importSleepDiary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportSleepDiaryUIFigure        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ImportSleepDiaryTab             matlab.ui.container.Tab
        GridLayoutImportSleepDiaryTab   matlab.ui.container.GridLayout
        SelectTabularDataFilePanel      matlab.ui.container.Panel
        GridLayoutSelectTabularDataFilePanel  matlab.ui.container.GridLayout
        BrowseButton                    matlab.ui.control.Button
        PathLabel                       matlab.ui.control.Label
        CheckRawDataPanel               matlab.ui.container.Panel
        GridLayoutCheckRawDataPanel     matlab.ui.container.GridLayout
        UITableRawData                  matlab.ui.control.Table
        SelectVariablesTab              matlab.ui.container.Tab
        GridLayoutSelectVariablesTab    matlab.ui.container.GridLayout
        SelectVariablesPanel            matlab.ui.container.Panel
        GridLayoutSelectVariablesPanel  matlab.ui.container.GridLayout
        VariableLabel                   matlab.ui.control.Label
        FormatLabel                     matlab.ui.control.Label
        Label_date                      matlab.ui.control.Label
        Label_lightsOut                 matlab.ui.control.Label
        Label_sleepLatency              matlab.ui.control.Label
        Label_awakenings                matlab.ui.control.Label
        Label_waso                      matlab.ui.control.Label
        Label_finAwake                  matlab.ui.control.Label
        Label_lightsOn                  matlab.ui.control.Label
        VarInput_date                   matlab.ui.control.DropDown
        VarInput_lightsOut              matlab.ui.control.DropDown
        VarInput_sleepLatency           matlab.ui.control.DropDown
        VarInput_awakenings             matlab.ui.control.DropDown
        VarInput_waso                   matlab.ui.control.DropDown
        VarInput_finAwake               matlab.ui.control.DropDown
        VarInput_lightsOn               matlab.ui.control.DropDown
        FormatInput_date                matlab.ui.control.EditField
        FormatInput_lightsOut           matlab.ui.control.EditField
        FormatInput_awakenings          matlab.ui.control.EditField
        FormatInput_finAwake            matlab.ui.control.EditField
        FormatInput_lightsOn            matlab.ui.control.EditField
        SaveSettingsButton              matlab.ui.control.Button
        LoadSettingsButton              matlab.ui.control.Button
        FormatInput_sleepLatency        matlab.ui.control.DropDown
        FormatInput_waso                matlab.ui.control.DropDown
        CheckImportDataPanel            matlab.ui.container.Panel
        GridLayoutCheckImportDataPanel  matlab.ui.container.GridLayout
        UITableImportData               matlab.ui.control.Table
        WarningPanel                    matlab.ui.container.Panel
        GridLayoutWarningPanel          matlab.ui.container.GridLayout
        WarningMessageLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp;
        HistorySize;
        InterruptDialog;
    end
    
    methods (Access = private)
        
        function mapStateToProps(app)
            % ---------------------------------------------------------
            % If the raw sleep diary has not been loaded yet, return
            if ~isfield(app.MainApp.ACT.etc, 'rawSleepDiary')
                return
            end
            % ---------------------------------------------------------
            % Keep track of whether there have been any issues
            err = false; % assume all is well
            % ---------------------------------------------------------
            % Set the dropdown menu's
            items = app.MainApp.ACT.etc.rawSleepDiary.Properties.VariableNames;
            fnames = fieldnames(app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx);
            for fi = 1:length(fnames)
                % Set the value of the dropdown menu's
                if isempty(app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.(fnames{fi}))
                    app.(['VarInput_', fnames{fi}]).Value = 'Select...';
                else
                    app.(['VarInput_', fnames{fi}]).Value = items{app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.(fnames{fi})};
                end
                % Check if all data have been parsed correctly
                if all(ismissing(app.MainApp.ACT.analysis.sleepDiary.(fnames{fi}))) % something went wrong
                    app.(['VarInput_', fnames{fi}]).BackgroundColor = [241, 229, 241]/255;
                    if ismember(fnames{fi}, {'date', 'lightsOut', 'lightsOn'})
                        err = true;
                    end
                else
                    app.(['VarInput_', fnames{fi}]).BackgroundColor = [0.96, 0.96, 0.96];
                end
            end
            if err
                app.ImportButton.Enable = 'off';
            else
                app.ImportButton.Enable = 'on';
            end
            % ---------------------------------------------------------
            % Highlight the cells with missing data
            app.UITableImportData.Data = app.MainApp.ACT.analysis.sleepDiary;
            [row, col] = find(ismissing(app.UITableImportData.Data));
            removeStyle(app.UITableImportData);
            addStyle(app.UITableImportData, ...
                uistyle('BackgroundColor', [241, 229, 241]/255), ...
                'cell', [row, col]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % ---------------------------------------------------------
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            % ---------------------------------------------------------
            % Position the new app in the center of the Cicada app
            app_centerGUI(app.MainApp, app, app.ImportSleepDiaryUIFigure)
            % ---------------------------------------------------------
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            % ---------------------------------------------------------
            % Check if the CicadaSettings.json still exists
            if exist([fileparts(which('cicada')), filesep, 'settings', filesep, 'CicadaSettings.json'], 'file') == 0
                sel = uiconfirm(app.ImportSleepDiaryUIFigure, 'Can''t find the ''CicadaSettings.json'' file anymore. Please restart Cicada.', 'Error', ...
                    'Options',{'Quit'},...
                    'DefaultOption', 'Quit', ...
                    'Icon', 'warning'); %#ok<NASGU>
                app.ImportSleepDiaryUIFigure.Visible = 'off';
                close(app.MainApp.CicadaUIFigure);
                return;
            end
            % ---------------------------------------------------------
            % Set the import settings
            if exist(app.MainApp.Settings.importSettings.sleepDiary, 'file') == 0
                % -----
                % If the import settings file cannot be found, ask the user to select the settings file
                sel = uiconfirm(app.ImportSleepDiaryUIFigure, {'Sleep diary import settings file could not be found.', 'Please create a new import settings file in the Cicada installation folder, or use the browse button to locate a sleep diary import settings file.'}, 'Oops... The Cicada is missing some parts.', ...
                    'Options',{'Cancel', 'Create New', 'Browse'},...
                    'DefaultOption', 'Browse', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'warning');
                if strcmpi(sel, 'Cancel')
                    % ---------------------------------------------------------
                    % If the user pressed cancel, delete the app and return to Cicada Main App
                    delete(app);
                    return
                    % ---------------------------------------------------------
                elseif strcmpi(sel, 'Create New')
                    % Create default import settings
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.date = 'dd/mm/yyyy';
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.lightsOut = 'HH:MM';
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.sleepLatency = 'minutes';
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.awakenings = 'integer';
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.waso = 'minutes';
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.finAwake = 'HH:MM';
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.lightsOn = 'HH:MM';
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.date = [];
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.lightsOut = [];
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.sleepLatency = [];
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.awakenings = [];
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.waso = [];
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.finAwake = [];
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.lightsOn = [];
                    errorMsg = 'Lets see if the file can be saved';
                    while ~isempty(errorMsg)
                        % Have the user select a new file to save the default import settings to
                        [selfile, selpath] = uiputfile('*.json', 'Save a new import settings .JSON file');
                        if selfile == 0
                            sel = uiconfirm(app.ImportSleepDiaryUIFigure, 'Sorry, you did not select a file.', 'Warning', ...
                                'Options', {'Close menu', 'Try again'},...
                                'DefaultOption', 'Try again', ...
                                'Icon', 'warning');
                            if strcmpi(sel, 'Close menu')
                                delete(app);
                                return;
                            else
                                continue;
                            end
                        end
                        % ---------------------------------------------------------
                        % Store the location of the import settings file in Cicada Settings
                        path2saveFile = strrep(fullfile(selpath, selfile), '\', '/');
                        % ---------------------------------------------------------
                        % Save the sleep diary import settings JSON file
                        errorMsg = struct2json(app.MainApp.ACT.analysis.settings.importSleepDiarySettings, path2saveFile);
                        if ~isempty(errorMsg)
                            app.MainApp.Settings.importSettings.sleepDiary = '';
                            sel = uiconfirm(app.ImportSleepDiaryUIFigure, 'Sorry, the file could not be saved here', 'Warning', ...
                                'Options', {'Close menu', 'Select another location'},...
                                'DefaultOption', 'Select another location', ...
                                'Icon', 'warning');
                            if strcmpi(sel, 'Close menu')
                                delete(app);
                                return;
                            end
                        else
                            app.MainApp.Settings.importSettings.sleepDiary = path2saveFile;
                        end
                    end
                    % ---------------------------------------------------------
                elseif strcmpi(sel, 'Browse')
                    [selfile, selpath] = uigetfile('*.json', 'Select the import settings .JSON file');
                    if selfile == 0
                        % If the user did not select a file, delete the app and return to Cicada Main App
                        sel = uiconfirm(app.ImportSleepDiaryUIFigure, 'Sorry, you did not select a file.', 'Warning', ...
                            'Options',{'Close menu'},...
                            'DefaultOption', 'Close menu', ...
                            'Icon', 'warning'); %#ok<NASGU> 
                        delete(app);
                        return;
                    end
                    % ---------------------------------------------------------
                    % Store the location of the import settings file in Cicada Settings
                    app.MainApp.Settings.importSettings.sleepDiary = strrep(fullfile(selpath, selfile), '\', '/');
                    if ~isfield(app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format, 'sleepLatency')
                        app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.sleepLatency = 'minutes';
                    end
                    if ~isfield(app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format, 'waso')
                        app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.waso = 'minutes';
                    end
                    if ~isfield(app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format, 'awakenings')
                        app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.waso = 'integer';
                    end
                end
                % -----
                % Save the Cicada settings JSON file
                errorMsg = struct2json(app.MainApp.Settings, [fileparts(which('cicada')), filesep, 'settings', filesep, 'CicadaSettings.json']);
                if ~isempty(errorMsg)
                    app.MainApp.Settings.importSettings.sleepDiary = '';
                     sel = uiconfirm(app.ImportSleepDiaryUIFigure, 'Crucial error, could not save the Cicada settings file.', 'Error', ...
                        'Options', {'Close menu'},...
                        'DefaultOption', 'Close menu', ...
                        'Icon', 'error'); %#ok<NASGU> 
                    delete(app);
                    return;
                end
                sel = uiconfirm(app.ImportSleepDiaryUIFigure, {'Cicada will use the following sleep diary import settings file from now on:', sprintf('%s', fullfile(selpath, selfile))}, 'All''s well that ends well', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'success'); %#ok<NASGU> 
            end
            % ---------------------------------------------------------
            % Write history
            % But before we write history, we'll save its size so we can
            % erase any written history in case user presses cancel
            app.HistorySize = size(app.MainApp.ACT.history, 1);
            app.MainApp.ACT.history = char(app.MainApp.ACT.history, '% ---------------------------------------------------------');
            app.MainApp.ACT.history = char(app.MainApp.ACT.history, '% Import sleep diary');
            % ---------------------------------------------------------
            % Load the import settings file
            [app.MainApp.ACT, ~, err, msg] = cic_importSleepDiarySettings(app.MainApp.ACT, app.MainApp.Settings.importSettings.sleepDiary);
            if err % show a warning that something is not right
                uiconfirm(app.ImportSleepDiaryUIFigure, msg, 'Warning', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'warning');
            end
            % ---------------------------------------------------------
            % Set the input values
            app.FormatInput_date.Value = app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.date;
            app.FormatInput_lightsOut.Value = app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.lightsOut;
            app.FormatInput_sleepLatency.Value = app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.sleepLatency;
            app.FormatInput_waso.Value = app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.waso;
            app.FormatInput_finAwake.Value = app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.finAwake;
            app.FormatInput_lightsOn.Value = app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.lightsOn;
            % ---------------------------------------------------------
            % Add tags to the Format inputs
            app.FormatInput_date.Tag = 'date';
            app.FormatInput_lightsOut.Tag = 'lightsOut';
            app.FormatInput_sleepLatency.Tag = 'sleepLatency';
            app.FormatInput_waso.Tag = 'waso';
            app.FormatInput_finAwake.Tag = 'finAwake';
            app.FormatInput_lightsOn.Tag = 'lightsOn';
            app.VarInput_date.Tag = 'date';
            app.VarInput_lightsOut.Tag = 'lightsOut';
            app.VarInput_sleepLatency.Tag = 'sleepLatency';
            app.VarInput_awakenings.Tag = 'awakenings';
            app.VarInput_waso.Tag = 'waso';
            app.VarInput_finAwake.Tag = 'finAwake';
            app.VarInput_lightsOn.Tag = 'lightsOn';
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % ---------------------------------------------------------
            % User selects a file to import
            % -----
            % Disable the browse button to prevent double clicks
            event.Source.Enable = 'off'; drawnow;
            % ---------------------------------------------------------
            % Ask the user to select the file
            [selfile, selpath] = uigetfile({'*.txt;*.csv;*.dat;*.tsv;*.xls;*.xlsx;*.ods'}, 'Select a tabular file');
            if selfile == 0
                % User pressed cancel, so return
                event.Source.Enable = 'on';
                return;
            end
            event.Source.Enable = 'on';
            % ---------------------------------------------------------
            % Try to load the data, set some values in the GUI and enable the import button
            try
                % -----
                % Read the raw sleep diary table
                app.MainApp.ACT = cic_importSleepDiary(app.MainApp.ACT, fullfile(selpath, selfile));
                % -----
                % Set the input to the full path of the file
                app.PathLabel.Text = fullfile(selpath, selfile);
                % -----
                % Set the UITable data
                app.UITableRawData.Data = app.MainApp.ACT.etc.rawSleepDiary;
                app.UITableRawData.ColumnName = app.UITableRawData.Data.Properties.VariableNames;
                % -----
                % Find missing data and indicate those with a pink background color
                [row, col] = find(ismissing(app.MainApp.ACT.etc.rawSleepDiary));
                removeStyle(app.UITableRawData);
                addStyle(app.UITableRawData, ...
                    uistyle('BackgroundColor', [241, 229, 241]/255), ...
                    'cell', [row, col]);
                % -----
                % Insert all raw variable names as items in the dropdown input
                items = [{'Select...'}; ascolumn(app.MainApp.ACT.etc.rawSleepDiary.Properties.VariableNames)];
                app.VarInput_date.Items = items;
                app.VarInput_lightsOut.Items = items;
                app.VarInput_sleepLatency.Items = items;
                app.VarInput_awakenings.Items = items;
                app.VarInput_waso.Items = items;
                app.VarInput_finAwake.Items = items;
                app.VarInput_lightsOn.Items = items;
                % ---------------------------------------------------------
                % Check that all column indices are within range, otherwise empty
                fnames = fieldnames(app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx);
                err = false;
                for fi = 1:length(fnames)
                    if app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.(fnames{fi}) > size(app.MainApp.ACT.etc.rawSleepDiary, 2)
                        app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.(fnames{fi}) = [];
                        err = true;
                    end
                end
                if err
                    sel = uiconfirm(app.ImportSleepDiaryUIFigure, 'At least one of the column numbers in the sleep diary import settings did not correspond to a valid column number in the raw datatable. These column numbers have been removed.', 'Warning', ...
                        'Options',{'Ok'},...
                        'DefaultOption', 'Ok', ...
                        'Icon', 'warning'); %#ok<NASGU> 
                end
                % ---------------------------------------------------------
                % Enable the import button
                app.ImportButton.Enable = 'on';
                
            catch ME
                % ---------------------------------------------------------
                % Something went wrong
                % ---------------------------------------------------------
                % Remove the raw sleep diary if it exists
                if isfield(app.MainApp.ACT.etc, 'rawSleepDiary')
                    app.MainApp.ACT.etc = rmfield(app.MainApp.ACT.etc, 'rawSleepDiary');
                end
                if isfield(app.MainApp.ACT.analysis, 'sleepDiary')
                    app.MainApp.ACT.analysis = rmfield(app.MainApp.ACT.analysis, 'sleepDiary');
                end
                % Clear the path label, dropdowns and UITables
                app.PathLabel.Text = '';
                removeStyle(app.UITableRawData);
                app.UITableRawData.Data = [];
                app.UITableRawData.ColumnName = '';
                fnames = fieldnames(app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx);
                for fi = 1:length(fnames)
                    app.(['VarInput_', fnames{fi}]).Items = {'Select...'};
                    app.(['VarInput_', fnames{fi}]).Value = 'Select...';
                    app.(['VarInput_', fnames{fi}]).BackgroundColor = [0.96, 0.96, 0.96];
                end
                removeStyle(app.UITableImportData);
                app.UITableImportData.Data = [];
                % ---------------------------------------------------------
                % Show error message that something has gone wrong
                uiconfirm(app.ImportSleepDiaryUIFigure, ME.message, 'Error', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'error');
                % ---------------------------------------------------------
                % Disable the import button
                app.ImportButton.Enable = 'off';
                return
            end
            % ---------------------------------------------------------
            % Try to parse the raw data with the current default settings
            [app.MainApp.ACT, err, msg] = cic_parseSleepDiary(app.MainApp.ACT, app.MainApp.ACT.etc.rawSleepDiary, app.MainApp.ACT.analysis.settings.importSleepDiarySettings);
            % Depricated, it was too annoying to have to click away
            %             if err % show a warning that something is not right
            %                 uiconfirm(app.ImportSleepDiaryUIFigure, msg, 'Warning', ...
            %                     'Options',{'Ok'},...
            %                     'DefaultOption', 'Ok', ...
            %                     'Icon', 'warning');
            %             end
            if err
                app.WarningPanel.Visible = 'on';
                app.WarningMessageLabel.Text = msg;
                app.WarningPanel.Layout.Row = 1;
                app.CheckImportDataPanel.Layout.Row = 2;
            else
                app.WarningPanel.Visible = 'off';
                app.WarningMessageLabel.Text = '';
                app.WarningPanel.Layout.Row = 1;
                app.CheckImportDataPanel.Layout.Row = [1, 2];
            end
            % Set the items and values of the dropdown menus and the UITable
            mapStateToProps(app);
        end

        % Value changed function: FormatInput_date, 
        % FormatInput_finAwake, FormatInput_lightsOn, 
        % FormatInput_lightsOut, FormatInput_sleepLatency, 
        % FormatInput_waso
        function FormatValueChanged(app, event)
            % ---------------------------------------------------------
            % Store the new value in import settings
            app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.(event.Source.Tag) = event.Source.Value;
            % ---------------------------------------------------------
            % Write this change to history
            app.MainApp.ACT.history = char(app.MainApp.ACT.history, '% -----');
            app.MainApp.ACT.history = char(app.MainApp.ACT.history, '% Manual change in import format');
            app.MainApp.ACT.history = char(app.MainApp.ACT.history, sprintf('ACT.analysis.settings.importSleepDiarySettings.format.%s = ''%s'';', event.Source.Tag, event.Source.Value));
            % ---------------------------------------------------------
            % Try to parse the raw data with the current default settings
            if isfield(app.MainApp.ACT.etc, 'rawSleepDiary')
                [app.MainApp.ACT, err, msg] = cic_parseSleepDiary(app.MainApp.ACT, app.MainApp.ACT.etc.rawSleepDiary, app.MainApp.ACT.analysis.settings.importSleepDiarySettings);
                % Depricated, it was too annoying to have to click away
                % warning messages. Instead show them in an alert box
%                 if err % show a warning that something is not right
%                     uiconfirm(app.ImportSleepDiaryUIFigure, msg, 'Warning', ...
%                         'Options',{'Ok'},...
%                         'DefaultOption', 'Ok', ...
%                         'Icon', 'warning');
%                 end
                if err
                    app.WarningPanel.Visible = 'on';
                    app.WarningMessageLabel.Text = msg;
                    app.WarningPanel.Layout.Row = 1;
                    app.CheckImportDataPanel.Layout.Row = 2;
                else
                    app.WarningPanel.Visible = 'off';
                    app.WarningMessageLabel.Text = '';
                    app.WarningPanel.Layout.Row = 1;
                    app.CheckImportDataPanel.Layout.Row = [1, 2];
                end
            end
            % ---------------------------------------------------------
            % Enable the save settings button
            app.SaveSettingsButton.Enable = 'on';
            % Set the items and values of the dropdown menus and the UITable
            mapStateToProps(app);
        end

        % Value changed function: VarInput_awakenings, 
        % VarInput_date, VarInput_finAwake, VarInput_lightsOn, 
        % VarInput_lightsOut, VarInput_sleepLatency, VarInput_waso
        function VarInputValueChanged(app, event)
            % ---------------------------------------------------------
            % Find the index of the current value in the list of items
            idx = strcmpi(event.Source.Value, app.UITableRawData.Data.Properties.VariableNames);
            if ~any(idx) % If the index is not found, i.e. the user clicked 'select', return empty
                idx = [];
            else % return the index
                idx = find(idx);
            end
            % Store the new index in import settings
            app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.(event.Source.Tag) = idx;
            % ---------------------------------------------------------
            % Write this change to history
            app.MainApp.ACT.history = char(app.MainApp.ACT.history, '% -----');
            app.MainApp.ACT.history = char(app.MainApp.ACT.history, '% Manual change in import column index');
            if isempty(idx)
                app.MainApp.ACT.history = char(app.MainApp.ACT.history, sprintf('ACT.analysis.settings.importSleepDiarySettings.idx.%s = [];', event.Source.Tag));
            else
                app.MainApp.ACT.history = char(app.MainApp.ACT.history, sprintf('ACT.analysis.settings.importSleepDiarySettings.idx.%s = %i;', event.Source.Tag, idx));
            end
            % ---------------------------------------------------------
            % Try to parse the raw data with the current default settings
            if isfield(app.MainApp.ACT.etc, 'rawSleepDiary')
                fprintf('heelow')
                [app.MainApp.ACT, err, msg] = cic_parseSleepDiary(app.MainApp.ACT, app.MainApp.ACT.etc.rawSleepDiary, app.MainApp.ACT.analysis.settings.importSleepDiarySettings);
                % Depricated, it was too annoying to have to click away
%                 if err % show a warning that something is not right
%                     uiconfirm(app.ImportSleepDiaryUIFigure, msg, 'Warning', ...
%                         'Options',{'Ok'},...
%                         'DefaultOption', 'Ok', ...
%                         'Icon', 'warning');
%                 end
                if err
                    app.WarningPanel.Visible = 'on';
                    app.WarningMessageLabel.Text = msg;
                    app.WarningPanel.Layout.Row = 1;
                    app.CheckImportDataPanel.Layout.Row = 2;
                else
                    app.WarningPanel.Visible = 'off';
                    app.WarningMessageLabel.Text = '';
                    app.WarningPanel.Layout.Row = 1;
                    app.CheckImportDataPanel.Layout.Row = [1, 2];
                end
            end
            % ---------------------------------------------------------
            % Enable the save settings button
            app.SaveSettingsButton.Enable = 'on';
            % Set the items and values of the dropdown menus and the UITable
            mapStateToProps(app);
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            % ---------------------------------------------------------
            % Save the sleep diary import settings JSON file
            currentDiaryImportSettingsFile = app.MainApp.Settings.importSettings.sleepDiary;
            errorMsg = 'Lets see if the file can be saved';
            while ~isempty(errorMsg)
                % Have the user select a new file to save the default import settings to
                [fOutName, fOutPath] = uiputfile(app.MainApp.Settings.importSettings.sleepDiary, 'Save JSON settings file to...');
                if fOutPath == 0; return; end % User pressed cancel, so return
                % ---------------------------------------------------------
                % Update the path to the sleep diary import settings JSON file
                path2saveFile = strrep(fullfile(fOutPath, fOutName), '\', '/');
                % ---------------------------------------------------------
                % Save the sleep diary import settings JSON file
                errorMsg = struct2json(app.MainApp.ACT.analysis.settings.importSleepDiarySettings, path2saveFile);
                if ~isempty(errorMsg)
                    sel = uiconfirm(app.ImportSleepDiaryUIFigure, 'Sorry, the file could not be saved here', 'Warning', ...
                        'Options', {'Cancel', 'Select another location'},...
                        'DefaultOption', 'Select another location', ...
                        'Icon', 'warning');
                    if strcmpi(sel, 'Cancel')
                        return;
                    end
                end
            end
            % Save the Cicada settings JSON file
            app.MainApp.Settings.importSettings.sleepDiary = path2saveFile;
            errorMsg = struct2json(app.MainApp.Settings, [fileparts(which('cicada')), filesep, 'settings', filesep, 'CicadaSettings.json']);
            if ~isempty(errorMsg)
                app.MainApp.Settings.importSettings.sleepDiary = currentDiaryImportSettingsFile;
                sel = uiconfirm(app.ImportSleepDiaryUIFigure, 'Crucial error, could not save the Cicada settings file.', 'Error', ...
                    'Options', {'Close menu'},...
                    'DefaultOption', 'Close menu', ...
                    'Icon', 'error'); %#ok<NASGU>
                delete(app);
                return;
            end
            % Confirm to the User that all is well
            sel = uiconfirm(app.ImportSleepDiaryUIFigure, {'Cicada will use the following sleep diary import settings file from now on:', sprintf('%s', fullfile(fOutPath, fOutName))}, 'All''s well that ends well', ...
                'Options',{'Ok'},...
                'DefaultOption', 'Ok', ...
                'Icon', 'success'); %#ok<NASGU>
            % ---------------------------------------------------------
            % Disable the save settings button
            app.SaveSettingsButton.Enable = 'off';
            % ---------------------------------------------------------
            % Write this change to history
            app.MainApp.ACT.history = char(app.MainApp.ACT.history, '% -----');
            app.MainApp.ACT.history = char(app.MainApp.ACT.history, '% Save import settings file');
            app.MainApp.ACT.history = char(app.MainApp.ACT.history, sprintf('settingsFile = ''%s''; % Paths must use forward slash ''/'', irrespective of operating system', app.MainApp.Settings.importSettings.sleepDiary));
            app.MainApp.ACT.history = char(app.MainApp.ACT.history, 'struct2json(ACT.analysis.settings.importSleepDiarySettings, settingsFile);');
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            % ---------------------------------------------------------
            % Check if raw data has been loaded yet, if not ask to do so first
            if ~isfield(app.MainApp.ACT.etc, 'rawSleepDiary')
                uiconfirm(app.ImportSleepDiaryUIFigure, 'Please import a tabular raw data file first.', 'Warning', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'warning');
                return
            end
            % ---------------------------------------------------------
            % Ask the user to select the file
            [selfile, selpath] = uigetfile({'*.json'}, 'Select a JSON settings file');
            if selfile == 0
                % User pressed cancel
                return;
            end
            % ---------------------------------------------------------
            % Set the import settings
            [app.MainApp.ACT, ~, err, msg] = cic_importSleepDiarySettings(app.MainApp.ACT, fullfile([selpath, selfile]));
            if err % show a warning that something is not right
                uiconfirm(app.ImportSleepDiaryUIFigure, msg, 'Warning', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'warning');
            end
            % -----
            % Set the input values
            app.FormatInput_date.Value = app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.date;
            app.FormatInput_lightsOut.Value = app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.lightsOut;
            app.FormatInput_finAwake.Value = app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.finAwake;
            app.FormatInput_lightsOn.Value = app.MainApp.ACT.analysis.settings.importSleepDiarySettings.format.lightsOn;
            % -----
            % Check that all column indices are within range, otherwise empty
            fnames = fieldnames(app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx);
            err = false;
            for fi = 1:length(fnames)
                if app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.(fnames{fi}) > length(app.VarInput_date.Items)
                    app.MainApp.ACT.analysis.settings.importSleepDiarySettings.idx.(fnames{fi}) = [];
                    err = true;
                end
            end
            if err
                sel = uiconfirm(app.ImportSleepDiaryUIFigure, 'At least one of the column numbers in the import settings file did not correspond to a valid column number in the raw datatable. These column numbers have been removed.', 'Warning', ...
                    'Options',{'Ok'},...
                    'DefaultOption', 'Ok', ...
                    'Icon', 'warning'); %#ok<NASGU>
            end
            % ---------------------------------------------------------
            % Update the path to the sleep diary import settings JSON file
            currentDiaryImportSettingsFile = app.MainApp.Settings.importSettings.sleepDiary;
            app.MainApp.Settings.importSettings.sleepDiary = strrep(fullfile(selpath, selfile), '\', '/');
            % Save the Cicada settings JSON file
            errorMsg = struct2json(app.MainApp.Settings, [fileparts(which('cicada')), filesep, 'settings', filesep, 'CicadaSettings.json']);
            if ~isempty(errorMsg)
                app.MainApp.Settings.importSettings.sleepDiary = currentDiaryImportSettingsFile;
                sel = uiconfirm(app.ImportSleepDiaryUIFigure, 'Crucial error, could not save the Cicada settings file.', 'Error', ...
                    'Options', {'Close menu'},...
                    'DefaultOption', 'Close menu', ...
                    'Icon', 'error'); %#ok<NASGU>
                delete(app);
                return;
            end
            % ---------------------------------------------------------
            % Try to parse the raw data with the current import settings
            [app.MainApp.ACT, err, msg] = cic_parseSleepDiary(app.MainApp.ACT, app.MainApp.ACT.etc.rawSleepDiary, app.MainApp.ACT.analysis.settings.importSleepDiarySettings);
            if err
                app.WarningPanel.Visible = 'on';
                app.WarningMessageLabel.Text = msg;
                app.WarningPanel.Layout.Row = 1;
                app.CheckImportDataPanel.Layout.Row = 2;
            else
                app.WarningPanel.Visible = 'off';
                app.WarningMessageLabel.Text = '';
                app.WarningPanel.Layout.Row = 1;
                app.CheckImportDataPanel.Layout.Row = [1, 2];
            end
            % Disable the save settings button
            app.SaveSettingsButton.Enable = 'off';
            % Set the items and values of the dropdown menus and the UITable
            mapStateToProps(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % ---------------------------------------------------------
            % Set return to false to indicate the user pressed cancel
            app.MainApp.Import.Return = false;
            % Remove raw and parsed sleep diaries if required
            if isfield(app.MainApp.ACT.etc, 'rawSleepDiary')
                app.MainApp.ACT.etc = rmfield(app.MainApp.ACT.etc, 'rawSleepDiary');
            end
            if isfield(app.MainApp.ACT.analysis, 'sleepDiary')
                app.MainApp.ACT.analysis = rmfield(app.MainApp.ACT.analysis, 'sleepDiary');
            end
            % Erase any written history
            app.MainApp.ACT.history = deblank(app.MainApp.ACT.history(1:app.HistorySize, :));
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % ---------------------------------------------------------
            % Set return to false to indicate the user pressed import
            app.MainApp.Import.Return = true;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportSleepDiaryUIFigure = uifigure('Visible', 'off');
            app.ImportSleepDiaryUIFigure.Name = 'Import Sleep Diary';
            app.GridLayout = uigridlayout(app.ImportSleepDiaryUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Enable = 'off';
            app.ImportButton.Text = 'Import';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ImportSleepDiaryTab = uitab(app.TabGroup);
            app.ImportSleepDiaryTab.Title = 'Import Sleep Diary';
            app.GridLayoutImportSleepDiaryTab = uigridlayout(app.ImportSleepDiaryTab);
            app.SelectTabularDataFilePanel = uipanel(app.GridLayoutImportSleepDiaryTab);
            app.SelectTabularDataFilePanel.Title = 'Select Tabular Data File';
            app.GridLayoutSelectTabularDataFilePanel = uigridlayout(app.SelectTabularDataFilePanel);
            app.BrowseButton = uibutton(app.GridLayoutSelectTabularDataFilePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.PathLabel = uilabel(app.GridLayoutSelectTabularDataFilePanel);
            app.PathLabel.Text = '';
            app.CheckRawDataPanel = uipanel(app.GridLayoutImportSleepDiaryTab);
            app.CheckRawDataPanel.Title = 'Check Raw Data';
            app.GridLayoutCheckRawDataPanel = uigridlayout(app.CheckRawDataPanel);
            app.UITableRawData = uitable(app.GridLayoutCheckRawDataPanel);
            app.UITableRawData.ColumnName = {''};
            app.UITableRawData.RowName = {};
            app.SelectVariablesTab = uitab(app.TabGroup);
            app.SelectVariablesTab.Title = 'Select Variables';
            app.GridLayoutSelectVariablesTab = uigridlayout(app.SelectVariablesTab);
            app.SelectVariablesPanel = uipanel(app.GridLayoutSelectVariablesTab);
            app.SelectVariablesPanel.Title = 'Select Variables';
            app.GridLayoutSelectVariablesPanel = uigridlayout(app.SelectVariablesPanel);
            app.VariableLabel = uilabel(app.GridLayoutSelectVariablesPanel);
            app.VariableLabel.Text = 'Variable';
            app.FormatLabel = uilabel(app.GridLayoutSelectVariablesPanel);
            app.FormatLabel.Text = 'Format';
            app.Label_date = uilabel(app.GridLayoutSelectVariablesPanel);
            app.Label_date.Text = 'Date*';
            app.Label_lightsOut = uilabel(app.GridLayoutSelectVariablesPanel);
            app.Label_lightsOut.Text = 'Lights Out*';
            app.Label_sleepLatency = uilabel(app.GridLayoutSelectVariablesPanel);
            app.Label_sleepLatency.Text = 'Sleep Latency';
            app.Label_awakenings = uilabel(app.GridLayoutSelectVariablesPanel);
            app.Label_awakenings.Text = '# Awakenings';
            app.Label_waso = uilabel(app.GridLayoutSelectVariablesPanel);
            app.Label_waso.Text = 'WASO';
            app.Label_finAwake = uilabel(app.GridLayoutSelectVariablesPanel);
            app.Label_finAwake.Text = {'Fin. Awake'; ''};
            app.Label_lightsOn = uilabel(app.GridLayoutSelectVariablesPanel);
            app.Label_lightsOn.Text = 'Lights On*';
            app.VarInput_date = uidropdown(app.GridLayoutSelectVariablesPanel);
            app.VarInput_date.Items = {'Select'};
            app.VarInput_date.ValueChangedFcn = createCallbackFcn(app, @VarInputValueChanged, true);
            app.VarInput_date.Value = 'Select';
            app.VarInput_lightsOut = uidropdown(app.GridLayoutSelectVariablesPanel);
            app.VarInput_lightsOut.Items = {'Select'};
            app.VarInput_lightsOut.ValueChangedFcn = createCallbackFcn(app, @VarInputValueChanged, true);
            app.VarInput_lightsOut.Value = 'Select';
            app.VarInput_sleepLatency = uidropdown(app.GridLayoutSelectVariablesPanel);
            app.VarInput_sleepLatency.Items = {'Select'};
            app.VarInput_sleepLatency.ValueChangedFcn = createCallbackFcn(app, @VarInputValueChanged, true);
            app.VarInput_sleepLatency.Value = 'Select';
            app.VarInput_awakenings = uidropdown(app.GridLayoutSelectVariablesPanel);
            app.VarInput_awakenings.Items = {'Select'};
            app.VarInput_awakenings.ValueChangedFcn = createCallbackFcn(app, @VarInputValueChanged, true);
            app.VarInput_awakenings.Value = 'Select';
            app.VarInput_waso = uidropdown(app.GridLayoutSelectVariablesPanel);
            app.VarInput_waso.Items = {'Select'};
            app.VarInput_waso.ValueChangedFcn = createCallbackFcn(app, @VarInputValueChanged, true);
            app.VarInput_waso.Value = 'Select';
            app.VarInput_finAwake = uidropdown(app.GridLayoutSelectVariablesPanel);
            app.VarInput_finAwake.Items = {'Select'};
            app.VarInput_finAwake.ValueChangedFcn = createCallbackFcn(app, @VarInputValueChanged, true);
            app.VarInput_finAwake.Value = 'Select';
            app.VarInput_lightsOn = uidropdown(app.GridLayoutSelectVariablesPanel);
            app.VarInput_lightsOn.Items = {'Select'};
            app.VarInput_lightsOn.ValueChangedFcn = createCallbackFcn(app, @VarInputValueChanged, true);
            app.VarInput_lightsOn.Value = 'Select';
            app.FormatInput_date = uieditfield(app.GridLayoutSelectVariablesPanel, 'text');
            app.FormatInput_date.ValueChangedFcn = createCallbackFcn(app, @FormatValueChanged, true);
            app.FormatInput_lightsOut = uieditfield(app.GridLayoutSelectVariablesPanel, 'text');
            app.FormatInput_lightsOut.ValueChangedFcn = createCallbackFcn(app, @FormatValueChanged, true);
            app.FormatInput_awakenings = uieditfield(app.GridLayoutSelectVariablesPanel, 'text');
            app.FormatInput_awakenings.Editable = 'off';
            app.FormatInput_awakenings.Value = 'integer';
            app.FormatInput_finAwake = uieditfield(app.GridLayoutSelectVariablesPanel, 'text');
            app.FormatInput_finAwake.ValueChangedFcn = createCallbackFcn(app, @FormatValueChanged, true);
            app.FormatInput_lightsOn = uieditfield(app.GridLayoutSelectVariablesPanel, 'text');
            app.FormatInput_lightsOn.ValueChangedFcn = createCallbackFcn(app, @FormatValueChanged, true);
            app.SaveSettingsButton = uibutton(app.GridLayoutSelectVariablesPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Enable = 'off';
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.GridLayoutSelectVariablesPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.FormatInput_sleepLatency = uidropdown(app.GridLayoutSelectVariablesPanel);
            app.FormatInput_sleepLatency.Items = {'minutes', 'HH:MM'};
            app.FormatInput_sleepLatency.ValueChangedFcn = createCallbackFcn(app, @FormatValueChanged, true);
            app.FormatInput_sleepLatency.Value = 'minutes';
            app.FormatInput_waso = uidropdown(app.GridLayoutSelectVariablesPanel);
            app.FormatInput_waso.Items = {'minutes', 'HH:MM'};
            app.FormatInput_waso.ValueChangedFcn = createCallbackFcn(app, @FormatValueChanged, true);
            app.FormatInput_waso.Value = 'minutes';
            app.CheckImportDataPanel = uipanel(app.GridLayoutSelectVariablesTab);
            app.CheckImportDataPanel.Title = 'Check Import Data';
            app.GridLayoutCheckImportDataPanel = uigridlayout(app.CheckImportDataPanel);
            app.UITableImportData = uitable(app.GridLayoutCheckImportDataPanel);
            app.UITableImportData.ColumnName = {'Date'; 'LightsOut'; 'SleepLatency'; 'Awakenings'; 'WASO'; 'FinAwake'; 'LightsOn'};
            app.UITableImportData.RowName = {};
            app.WarningPanel = uipanel(app.GridLayoutSelectVariablesTab);
            app.WarningPanel.Visible = 'off';
            app.GridLayoutWarningPanel = uigridlayout(app.WarningPanel);
            app.WarningMessageLabel = uilabel(app.GridLayoutWarningPanel);
            app.WarningMessageLabel.Text = 'Warning Message';
            % Show the figure after all components are created
            app.ImportSleepDiaryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = importSleepDiary(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportSleepDiaryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportSleepDiaryUIFigure)
        end
    end
end