classdef selectColor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectColorUIFigure  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        CancelButton         matlab.ui.control.Button
        SelectButton         matlab.ui.control.Button
        ColorPalettePanel    matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        HueLabel             matlab.ui.control.Label
        SaturationLabel      matlab.ui.control.Label
        ValueLabel           matlab.ui.control.Label
        SelectedColor        matlab.ui.control.Label
        H360                 matlab.ui.control.Button
        H324                 matlab.ui.control.Button
        H290                 matlab.ui.control.Button
        H247                 matlab.ui.control.Button
        H217                 matlab.ui.control.Button
        H182                 matlab.ui.control.Button
        H143                 matlab.ui.control.Button
        H75                  matlab.ui.control.Button
        H37                  matlab.ui.control.Button
        S10                  matlab.ui.control.Button
        S20                  matlab.ui.control.Button
        S30                  matlab.ui.control.Button
        S40                  matlab.ui.control.Button
        S50                  matlab.ui.control.Button
        S60                  matlab.ui.control.Button
        S70                  matlab.ui.control.Button
        S80                  matlab.ui.control.Button
        S90                  matlab.ui.control.Button
        V10                  matlab.ui.control.Button
        V20                  matlab.ui.control.Button
        V30                  matlab.ui.control.Button
        V40                  matlab.ui.control.Button
        V50                  matlab.ui.control.Button
        V60                  matlab.ui.control.Button
        V70                  matlab.ui.control.Button
        V80                  matlab.ui.control.Button
        V90                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, clr)
            % ---------------------------------------------------------
            % Store main app object
            app.MainApp = mainapp;
            % ---------------------------------------------------------
            % Clear the import structure
            app.MainApp.Import = struct();
            % ---------------------------------------------------------
            % Position the new app in the center of the Cicada app
            app_centerGUI(app.MainApp, app, app.SelectColorUIFigure)
            % ---------------------------------------------------------
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            % ---------------------------------------------------------
            % Initialize the buttons
            app.SelectedColor.BackgroundColor = clr;
            clr = rgb2hsv(app.SelectedColor.BackgroundColor);
            for si = 10:10:90
                app.(['S', num2str(si)]).UserData = si;
                app.(['S', num2str(si)]).BackgroundColor = hsv2rgb([clr(1), si/100, clr(3)]);
            end
            for vi = 10:10:90
                app.(['V', num2str(vi)]).UserData = vi;
                app.(['V', num2str(vi)]).BackgroundColor = hsv2rgb([clr(1), clr(2), vi/100]);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            app.MainApp.Import.Data = app.SelectedColor.BackgroundColor;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: H143, H182, H217, H247, H290, 
        % H324, H360, H37, H75
        function HueButtonPushed(app, event)
            app.SelectedColor.BackgroundColor = event.Source.BackgroundColor;
            clr = rgb2hsv(app.SelectedColor.BackgroundColor);
            for si = 10:10:90
                app.(['S', num2str(si)]).BackgroundColor = hsv2rgb([clr(1), si/100, clr(3)]);
            end
            for vi = 10:10:90
                app.(['V', num2str(vi)]).BackgroundColor = hsv2rgb([clr(1), clr(2), vi/100]);
            end
            app.SelectButton.Enable = 'on';
        end

        % Button pushed function: S10, S20, S30, S40, S50, S60, 
        % S70, S80, S90
        function SaturationButtonPushed(app, event)
            clr = rgb2hsv(app.SelectedColor.BackgroundColor);
            clr(2) = event.Source.UserData/100;
            app.SelectedColor.BackgroundColor = hsv2rgb(clr);
            for vi = 10:10:90
                app.(['V', num2str(vi)]).BackgroundColor = hsv2rgb([clr(1), clr(2), vi/100]);
            end
            app.SelectButton.Enable = 'on';
        end

        % Button pushed function: V10, V20, V30, V40, V50, V60, 
        % V70, V80, V90
        function ValueButtonPushed(app, event)
            clr = rgb2hsv(app.SelectedColor.BackgroundColor);
            clr(3) = event.Source.UserData/100;
            app.SelectedColor.BackgroundColor = hsv2rgb(clr);
            for si = 10:10:90
                app.(['S', num2str(si)]).BackgroundColor = hsv2rgb([clr(1), si/100, clr(3)]);
            end
            app.SelectButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectColorUIFigure = uifigure('Visible', 'off');
            app.SelectColorUIFigure.Name = 'Select Color';
            app.GridLayout = uigridlayout(app.SelectColorUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Enable = 'off';
            app.SelectButton.Text = 'Select';
            app.ColorPalettePanel = uipanel(app.GridLayout);
            app.ColorPalettePanel.Title = 'Color Palette';
            app.GridLayout2 = uigridlayout(app.ColorPalettePanel);
            app.HueLabel = uilabel(app.GridLayout2);
            app.HueLabel.Text = 'Hue';
            app.SaturationLabel = uilabel(app.GridLayout2);
            app.SaturationLabel.Text = 'Saturation';
            app.ValueLabel = uilabel(app.GridLayout2);
            app.ValueLabel.Text = 'Value';
            app.SelectedColor = uilabel(app.GridLayout2);
            app.SelectedColor.Text = '';
            app.H360 = uibutton(app.GridLayout2, 'push');
            app.H360.ButtonPushedFcn = createCallbackFcn(app, @HueButtonPushed, true);
            app.H360.Text = '';
            app.H324 = uibutton(app.GridLayout2, 'push');
            app.H324.ButtonPushedFcn = createCallbackFcn(app, @HueButtonPushed, true);
            app.H324.Text = '';
            app.H290 = uibutton(app.GridLayout2, 'push');
            app.H290.ButtonPushedFcn = createCallbackFcn(app, @HueButtonPushed, true);
            app.H290.Text = '';
            app.H247 = uibutton(app.GridLayout2, 'push');
            app.H247.ButtonPushedFcn = createCallbackFcn(app, @HueButtonPushed, true);
            app.H247.Text = '';
            app.H217 = uibutton(app.GridLayout2, 'push');
            app.H217.ButtonPushedFcn = createCallbackFcn(app, @HueButtonPushed, true);
            app.H217.Text = '';
            app.H182 = uibutton(app.GridLayout2, 'push');
            app.H182.ButtonPushedFcn = createCallbackFcn(app, @HueButtonPushed, true);
            app.H182.Text = '';
            app.H143 = uibutton(app.GridLayout2, 'push');
            app.H143.ButtonPushedFcn = createCallbackFcn(app, @HueButtonPushed, true);
            app.H143.Text = '';
            app.H75 = uibutton(app.GridLayout2, 'push');
            app.H75.ButtonPushedFcn = createCallbackFcn(app, @HueButtonPushed, true);
            app.H75.Text = '';
            app.H37 = uibutton(app.GridLayout2, 'push');
            app.H37.ButtonPushedFcn = createCallbackFcn(app, @HueButtonPushed, true);
            app.H37.Text = '';
            app.S10 = uibutton(app.GridLayout2, 'push');
            app.S10.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.S10.Text = '';
            app.S20 = uibutton(app.GridLayout2, 'push');
            app.S20.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.S20.Text = '';
            app.S30 = uibutton(app.GridLayout2, 'push');
            app.S30.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.S30.Text = '';
            app.S40 = uibutton(app.GridLayout2, 'push');
            app.S40.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.S40.Text = '';
            app.S50 = uibutton(app.GridLayout2, 'push');
            app.S50.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.S50.Text = '';
            app.S60 = uibutton(app.GridLayout2, 'push');
            app.S60.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.S60.Text = '';
            app.S70 = uibutton(app.GridLayout2, 'push');
            app.S70.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.S70.Text = '';
            app.S80 = uibutton(app.GridLayout2, 'push');
            app.S80.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.S80.Text = '';
            app.S90 = uibutton(app.GridLayout2, 'push');
            app.S90.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.S90.Text = '';
            app.V10 = uibutton(app.GridLayout2, 'push');
            app.V10.ButtonPushedFcn = createCallbackFcn(app, @ValueButtonPushed, true);
            app.V10.Text = '';
            app.V20 = uibutton(app.GridLayout2, 'push');
            app.V20.ButtonPushedFcn = createCallbackFcn(app, @ValueButtonPushed, true);
            app.V20.Text = '';
            app.V30 = uibutton(app.GridLayout2, 'push');
            app.V30.ButtonPushedFcn = createCallbackFcn(app, @ValueButtonPushed, true);
            app.V30.Text = '';
            app.V40 = uibutton(app.GridLayout2, 'push');
            app.V40.ButtonPushedFcn = createCallbackFcn(app, @ValueButtonPushed, true);
            app.V40.Text = '';
            app.V50 = uibutton(app.GridLayout2, 'push');
            app.V50.ButtonPushedFcn = createCallbackFcn(app, @ValueButtonPushed, true);
            app.V50.Text = '';
            app.V60 = uibutton(app.GridLayout2, 'push');
            app.V60.ButtonPushedFcn = createCallbackFcn(app, @ValueButtonPushed, true);
            app.V60.Text = '';
            app.V70 = uibutton(app.GridLayout2, 'push');
            app.V70.ButtonPushedFcn = createCallbackFcn(app, @ValueButtonPushed, true);
            app.V70.Text = '';
            app.V80 = uibutton(app.GridLayout2, 'push');
            app.V80.ButtonPushedFcn = createCallbackFcn(app, @ValueButtonPushed, true);
            app.V80.Text = '';
            app.V90 = uibutton(app.GridLayout2, 'push');
            app.V90.ButtonPushedFcn = createCallbackFcn(app, @ValueButtonPushed, true);
            app.V90.Text = '';
            % Show the figure after all components are created
            app.SelectColorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectColor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectColorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectColorUIFigure)
        end
    end
end