classdef selectData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectDataUIFigure        matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        CancelButton              matlab.ui.control.Button
        SelectButton              matlab.ui.control.Button
        StartDatePanel            matlab.ui.container.Panel
        GridLayoutStartDatePanel  matlab.ui.container.GridLayout
        ButtonStartDayUp          matlab.ui.control.Button
        ButtonStartMonthUp        matlab.ui.control.Button
        ButtonStartYearUp         matlab.ui.control.Button
        ButtonStartHourUp         matlab.ui.control.Button
        ButtonStartMinuteUp       matlab.ui.control.Button
        ButtonStartDayDown        matlab.ui.control.Button
        ButtonStartMonthDown      matlab.ui.control.Button
        ButtonStartYearDown       matlab.ui.control.Button
        ButtonStartHourDown       matlab.ui.control.Button
        ButtonStartMinuteDown     matlab.ui.control.Button
        LabelStartDaySep          matlab.ui.control.Label
        LabelStartMonthSep        matlab.ui.control.Label
        LabelStartHourSep         matlab.ui.control.Label
        InputStartDay             matlab.ui.control.EditField
        InputStartMonth           matlab.ui.control.EditField
        InputStartYear            matlab.ui.control.EditField
        InputStartHour            matlab.ui.control.EditField
        InputStartMinute          matlab.ui.control.EditField
        EndDatePanel              matlab.ui.container.Panel
        GridLayoutEndDatePanel    matlab.ui.container.GridLayout
        ButtonEndDayUp            matlab.ui.control.Button
        ButtonEndMonthUp          matlab.ui.control.Button
        ButtonEndYearUp           matlab.ui.control.Button
        ButtonEndHourUp           matlab.ui.control.Button
        ButtonEndMinuteUp         matlab.ui.control.Button
        ButtonEndDayDown          matlab.ui.control.Button
        ButtonEndMonthDown        matlab.ui.control.Button
        ButtonEndYearDown         matlab.ui.control.Button
        ButtonEndHourDown         matlab.ui.control.Button
        ButtonEndMinuteDown       matlab.ui.control.Button
        LabelEndDaySep            matlab.ui.control.Label
        LabelEndMonthSep          matlab.ui.control.Label
        LabelEndHourSep           matlab.ui.control.Label
        InputEndDay               matlab.ui.control.EditField
        InputEndMonth             matlab.ui.control.EditField
        InputEndYear              matlab.ui.control.EditField
        InputEndHour              matlab.ui.control.EditField
        InputEndMinute            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        MainApp;
        InterruptDialog;
    end
    
    methods (Access = private)
        % add your functions here
        function setDateInputs(~, obj, dateNumber, formatOut)
            obj.Value = datestr(dateNumber, formatOut);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app object
            app.MainApp = mainapp;
            % Clear the import structure
            app.MainApp.Import = struct();
            % Show waitbar to indicate another gui is open
            app.InterruptDialog = uiprogressdlg(app.MainApp.CicadaUIFigure);
            
            pos = app.MainApp.CicadaUIFigure.Position;
            h = app.SelectDataUIFigure.Position(3);
            w = app.SelectDataUIFigure.Position(4);
            x = (pos(3)-h)/2+pos(1);
            y = (pos(4)-w)/2+pos(2);
            app.SelectDataUIFigure.Position = [x y h w];
            app.SelectDataUIFigure.Resize = 'off';
            
            setDateInputs(app, app.InputStartDay, app.MainApp.ACT.xmin, 'dd')
            setDateInputs(app, app.InputStartMonth, app.MainApp.ACT.xmin, 'mm')
            setDateInputs(app, app.InputStartYear, app.MainApp.ACT.xmin, 'yyyy')
            setDateInputs(app, app.InputStartHour, app.MainApp.ACT.xmin, 'HH')
            setDateInputs(app, app.InputStartMinute, app.MainApp.ACT.xmin, 'MM')
            
            setDateInputs(app, app.InputEndDay, app.MainApp.ACT.xmax, 'dd')
            setDateInputs(app, app.InputEndMonth, app.MainApp.ACT.xmax, 'mm')
            setDateInputs(app, app.InputEndYear, app.MainApp.ACT.xmax, 'yyyy')
            setDateInputs(app, app.InputEndHour, app.MainApp.ACT.xmax, 'HH')
            setDateInputs(app, app.InputEndMinute, app.MainApp.ACT.xmax, 'MM')
            
            % Set the UserData of the buttons
            app.ButtonStartDayUp.UserData.idx = 1;
            app.ButtonStartDayUp.UserData.step = 'day';
            app.ButtonStartDayUp.UserData.direction = 1;
            app.ButtonStartMonthUp.UserData.idx = 1;
            app.ButtonStartMonthUp.UserData.step = 'month';
            app.ButtonStartMonthUp.UserData.direction = 1;
            app.ButtonStartYearUp.UserData.idx = 1;
            app.ButtonStartYearUp.UserData.step = 'year';
            app.ButtonStartYearUp.UserData.direction = 1;
            app.ButtonStartHourUp.UserData.idx = 1;
            app.ButtonStartHourUp.UserData.step = 'hour';
            app.ButtonStartHourUp.UserData.direction = 1;
            app.ButtonStartMinuteUp.UserData.idx = 1;
            app.ButtonStartMinuteUp.UserData.step = 'minute';
            app.ButtonStartMinuteUp.UserData.direction = 1;
            
            app.ButtonStartDayDown.UserData.idx = 1;
            app.ButtonStartDayDown.UserData.step = 'day';
            app.ButtonStartDayDown.UserData.direction = -1;
            app.ButtonStartMonthDown.UserData.idx = 1;
            app.ButtonStartMonthDown.UserData.step = 'month';
            app.ButtonStartMonthDown.UserData.direction = -1;
            app.ButtonStartYearDown.UserData.idx = 1;
            app.ButtonStartYearDown.UserData.step = 'year';
            app.ButtonStartYearDown.UserData.direction = -1;
            app.ButtonStartHourDown.UserData.idx = 1;
            app.ButtonStartHourDown.UserData.step = 'hour';
            app.ButtonStartHourDown.UserData.direction = -1;
            app.ButtonStartMinuteDown.UserData.idx = 1;
            app.ButtonStartMinuteDown.UserData.step = 'minute';
            app.ButtonStartMinuteDown.UserData.direction = -1;
            
            app.ButtonEndDayUp.UserData.idx = 2;
            app.ButtonEndDayUp.UserData.step = 'day';
            app.ButtonEndDayUp.UserData.direction = 1;
            app.ButtonEndMonthUp.UserData.idx = 2;
            app.ButtonEndMonthUp.UserData.step = 'month';
            app.ButtonEndMonthUp.UserData.direction = 1;
            app.ButtonEndYearUp.UserData.idx = 2;
            app.ButtonEndYearUp.UserData.step = 'year';
            app.ButtonEndYearUp.UserData.direction = 1;
            app.ButtonEndHourUp.UserData.idx = 2;
            app.ButtonEndHourUp.UserData.step = 'hour';
            app.ButtonEndHourUp.UserData.direction = 1;
            app.ButtonEndMinuteUp.UserData.idx = 2;
            app.ButtonEndMinuteUp.UserData.step = 'minute';
            app.ButtonEndMinuteUp.UserData.direction = 1;
            
            app.ButtonEndDayDown.UserData.idx = 2;
            app.ButtonEndDayDown.UserData.step = 'day';
            app.ButtonEndDayDown.UserData.direction = -1;
            app.ButtonEndMonthDown.UserData.idx = 2;
            app.ButtonEndMonthDown.UserData.step = 'month';
            app.ButtonEndMonthDown.UserData.direction = -1;
            app.ButtonEndYearDown.UserData.idx = 2;
            app.ButtonEndYearDown.UserData.step = 'year';
            app.ButtonEndYearDown.UserData.direction = -1;
            app.ButtonEndHourDown.UserData.idx = 2;
            app.ButtonEndHourDown.UserData.step = 'hour';
            app.ButtonEndHourDown.UserData.direction = -1;
            app.ButtonEndMinuteDown.UserData.idx = 2;
            app.ButtonEndMinuteDown.UserData.step = 'minute';
            app.ButtonEndMinuteDown.UserData.direction = -1;
            
            app.InputStartDay.UserData.idx = 1;
            app.InputStartDay.UserData.formatOut = 'dd';
            app.InputStartMonth.UserData.idx = 1;
            app.InputStartMonth.UserData.formatOut = 'mm';
            app.InputStartYear.UserData.idx = 1;
            app.InputStartYear.UserData.formatOut = 'yyyy';
            app.InputStartHour.UserData.idx = 1;
            app.InputStartHour.UserData.formatOut = 'HH';
            app.InputStartMinute.UserData.idx = 1;
            app.InputStartMinute.UserData.formatOut = 'MM';
            
            app.InputEndDay.UserData.idx = 2;
            app.InputEndDay.UserData.formatOut = 'dd';
            app.InputEndMonth.UserData.idx = 2;
            app.InputEndMonth.UserData.formatOut = 'mm';
            app.InputEndYear.UserData.idx = 2;
            app.InputEndYear.UserData.formatOut = 'yyyy';
            app.InputEndHour.UserData.idx = 2;
            app.InputEndHour.UserData.formatOut = 'HH';
            app.InputEndMinute.UserData.idx = 2;
            app.InputEndMinute.UserData.formatOut = 'MM';
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.MainApp.Import.Return = false;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.MainApp.Import.Return = true;
            % Delete the dialog box
            if isvalid(app)
                delete(app)
            end
        end

        % Button pushed function: ButtonEndDayDown, ButtonEndDayUp, 
        % ButtonEndHourDown, ButtonEndHourUp, ButtonEndMinuteDown, 
        % ButtonEndMinuteUp, ButtonEndMonthDown, ButtonEndMonthUp, 
        % ButtonEndYearDown, ButtonEndYearUp, ButtonStartDayDown, 
        % ButtonStartDayUp, ButtonStartHourDown, ButtonStartHourUp, 
        % ButtonStartMinuteDown, ButtonStartMinuteUp, 
        % ButtonStartMonthDown, ButtonStartMonthUp, 
        % ButtonStartYearDown, ButtonStartYearUp
        function ButtonPushed(app, event)
            if ~isfield(app.MainApp.Import, 'Data')
                app.MainApp.Import.Data = [app.MainApp.ACT.xmin, app.MainApp.ACT.xmax];
                app.SelectButton.Enable = 'on';
            end
            idx = event.Source.UserData.idx;
            step = event.Source.UserData.step;
            dir = event.Source.UserData.direction;
            app.MainApp.Import.Data(idx) = addtodate(app.MainApp.Import.Data(idx), dir, step);
            
            % Update the input fields
            if idx == 1
                setDateInputs(app, app.InputStartDay, app.MainApp.Import.Data(idx), 'dd')
                setDateInputs(app, app.InputStartMonth, app.MainApp.Import.Data(idx), 'mm')
                setDateInputs(app, app.InputStartYear, app.MainApp.Import.Data(idx), 'yyyy')
                setDateInputs(app, app.InputStartHour, app.MainApp.Import.Data(idx), 'HH')
                setDateInputs(app, app.InputStartMinute, app.MainApp.Import.Data(idx), 'MM')
            else
                setDateInputs(app, app.InputEndDay, app.MainApp.Import.Data(idx), 'dd')
                setDateInputs(app, app.InputEndMonth, app.MainApp.Import.Data(idx), 'mm')
                setDateInputs(app, app.InputEndYear, app.MainApp.Import.Data(idx), 'yyyy')
                setDateInputs(app, app.InputEndHour, app.MainApp.Import.Data(idx), 'HH')
                setDateInputs(app, app.InputEndMinute, app.MainApp.Import.Data(idx), 'MM')
            end
            
        end

        % Value changed function: InputEndDay, InputEndHour, 
        % InputEndMinute, InputEndMonth, InputEndYear, 
        % InputStartDay, InputStartHour, InputStartMinute, 
        % InputStartMonth, InputStartYear
        function InputValueChanged(app, event)
            if ~isfield(app.MainApp.Import, 'Data')
                app.MainApp.Import.Data = [app.MainApp.ACT.xmin, app.MainApp.ACT.xmax];
                app.SelectButton.Enable = 'on';
            end
            
            app.MainApp.Import.Data(1) = datenum([ ...
                app.InputStartDay.Value, '/', ...
                app.InputStartMonth.Value, '/', ...
                app.InputStartYear.Value, ' ', ...
                app.InputStartHour.Value, ':', ...
                app.InputStartMinute.Value ...
                ], 'dd/mm/yyyy HH:MM');
            app.MainApp.Import.Data(2) = datenum([ ...
                app.InputEndDay.Value, '/', ...
                app.InputEndMonth.Value, '/', ...
                app.InputEndYear.Value, ' ', ...
                app.InputEndHour.Value, ':', ...
                app.InputEndMinute.Value ...
                ], 'dd/mm/yyyy HH:MM');
            
            idx = event.Source.UserData.idx;
            formatOut = event.Source.UserData.formatOut;
            setDateInputs(app, event.Source, app.MainApp.Import.Data(idx), formatOut)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectDataUIFigure = uifigure('Visible', 'off');
            app.SelectDataUIFigure.Name = 'Select Data';
            app.GridLayout = uigridlayout(app.SelectDataUIFigure);
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Enable = 'off';
            app.SelectButton.Text = 'Select';
            app.StartDatePanel = uipanel(app.GridLayout);
            app.StartDatePanel.Title = 'Start Date';
            app.GridLayoutStartDatePanel = uigridlayout(app.StartDatePanel);
            app.ButtonStartDayUp = uibutton(app.GridLayoutStartDatePanel, 'push');
            app.ButtonStartDayUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonStartDayUp.Text = '^';
            app.ButtonStartMonthUp = uibutton(app.GridLayoutStartDatePanel, 'push');
            app.ButtonStartMonthUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonStartMonthUp.Text = '^';
            app.ButtonStartYearUp = uibutton(app.GridLayoutStartDatePanel, 'push');
            app.ButtonStartYearUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonStartYearUp.Text = '^';
            app.ButtonStartHourUp = uibutton(app.GridLayoutStartDatePanel, 'push');
            app.ButtonStartHourUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonStartHourUp.Text = '^';
            app.ButtonStartMinuteUp = uibutton(app.GridLayoutStartDatePanel, 'push');
            app.ButtonStartMinuteUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonStartMinuteUp.Text = '^';
            app.ButtonStartDayDown = uibutton(app.GridLayoutStartDatePanel, 'push');
            app.ButtonStartDayDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonStartDayDown.Text = 'v';
            app.ButtonStartMonthDown = uibutton(app.GridLayoutStartDatePanel, 'push');
            app.ButtonStartMonthDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonStartMonthDown.Text = 'v';
            app.ButtonStartYearDown = uibutton(app.GridLayoutStartDatePanel, 'push');
            app.ButtonStartYearDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonStartYearDown.Text = 'v';
            app.ButtonStartHourDown = uibutton(app.GridLayoutStartDatePanel, 'push');
            app.ButtonStartHourDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonStartHourDown.Text = 'v';
            app.ButtonStartMinuteDown = uibutton(app.GridLayoutStartDatePanel, 'push');
            app.ButtonStartMinuteDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonStartMinuteDown.Text = 'v';
            app.LabelStartDaySep = uilabel(app.GridLayoutStartDatePanel);
            app.LabelStartDaySep.Text = '/';
            app.LabelStartMonthSep = uilabel(app.GridLayoutStartDatePanel);
            app.LabelStartMonthSep.Text = '/';
            app.LabelStartHourSep = uilabel(app.GridLayoutStartDatePanel);
            app.LabelStartHourSep.Text = ':';
            app.InputStartDay = uieditfield(app.GridLayoutStartDatePanel, 'text');
            app.InputStartDay.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputStartMonth = uieditfield(app.GridLayoutStartDatePanel, 'text');
            app.InputStartMonth.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputStartYear = uieditfield(app.GridLayoutStartDatePanel, 'text');
            app.InputStartYear.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputStartHour = uieditfield(app.GridLayoutStartDatePanel, 'text');
            app.InputStartHour.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputStartMinute = uieditfield(app.GridLayoutStartDatePanel, 'text');
            app.InputStartMinute.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.EndDatePanel = uipanel(app.GridLayout);
            app.EndDatePanel.Title = 'End Date';
            app.GridLayoutEndDatePanel = uigridlayout(app.EndDatePanel);
            app.ButtonEndDayUp = uibutton(app.GridLayoutEndDatePanel, 'push');
            app.ButtonEndDayUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonEndDayUp.Text = '^';
            app.ButtonEndMonthUp = uibutton(app.GridLayoutEndDatePanel, 'push');
            app.ButtonEndMonthUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonEndMonthUp.Text = '^';
            app.ButtonEndYearUp = uibutton(app.GridLayoutEndDatePanel, 'push');
            app.ButtonEndYearUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonEndYearUp.Text = '^';
            app.ButtonEndHourUp = uibutton(app.GridLayoutEndDatePanel, 'push');
            app.ButtonEndHourUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonEndHourUp.Text = '^';
            app.ButtonEndMinuteUp = uibutton(app.GridLayoutEndDatePanel, 'push');
            app.ButtonEndMinuteUp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonEndMinuteUp.Text = '^';
            app.ButtonEndDayDown = uibutton(app.GridLayoutEndDatePanel, 'push');
            app.ButtonEndDayDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonEndDayDown.Text = 'v';
            app.ButtonEndMonthDown = uibutton(app.GridLayoutEndDatePanel, 'push');
            app.ButtonEndMonthDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonEndMonthDown.Text = 'v';
            app.ButtonEndYearDown = uibutton(app.GridLayoutEndDatePanel, 'push');
            app.ButtonEndYearDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonEndYearDown.Text = 'v';
            app.ButtonEndHourDown = uibutton(app.GridLayoutEndDatePanel, 'push');
            app.ButtonEndHourDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonEndHourDown.Text = 'v';
            app.ButtonEndMinuteDown = uibutton(app.GridLayoutEndDatePanel, 'push');
            app.ButtonEndMinuteDown.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.ButtonEndMinuteDown.Text = 'v';
            app.LabelEndDaySep = uilabel(app.GridLayoutEndDatePanel);
            app.LabelEndDaySep.Text = '/';
            app.LabelEndMonthSep = uilabel(app.GridLayoutEndDatePanel);
            app.LabelEndMonthSep.Text = '/';
            app.LabelEndHourSep = uilabel(app.GridLayoutEndDatePanel);
            app.LabelEndHourSep.Text = ':';
            app.InputEndDay = uieditfield(app.GridLayoutEndDatePanel, 'text');
            app.InputEndDay.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputEndMonth = uieditfield(app.GridLayoutEndDatePanel, 'text');
            app.InputEndMonth.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputEndYear = uieditfield(app.GridLayoutEndDatePanel, 'text');
            app.InputEndYear.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputEndHour = uieditfield(app.GridLayoutEndDatePanel, 'text');
            app.InputEndHour.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            app.InputEndMinute = uieditfield(app.GridLayoutEndDatePanel, 'text');
            app.InputEndMinute.ValueChangedFcn = createCallbackFcn(app, @InputValueChanged, true);
            % Show the figure after all components are created
            app.SelectDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectDataUIFigure)
        end
    end
end